classdef HVloop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HVadjustmentLabel             matlab.ui.control.Label
        Switch                        matlab.ui.control.ToggleSwitch
        Lamp                          matlab.ui.control.Lamp
        Button                        matlab.ui.control.Button
        AdjustmentperiodsecLabel      matlab.ui.control.Label
        AdjustmentperiodsecEditField  matlab.ui.control.NumericEditField
        MaxresidualmVEditFieldLabel   matlab.ui.control.Label
        MaxresidualmVEditField        matlab.ui.control.NumericEditField
        TemperatureBoardTMP125CLabel  matlab.ui.control.Label
        Q0Label_2                     matlab.ui.control.Label
        Q1Label                       matlab.ui.control.Label
        Q2Label                       matlab.ui.control.Label
        Q3Label                       matlab.ui.control.Label
        Q0Label                       matlab.ui.control.Label
        Q1Label_2                     matlab.ui.control.Label
        Q2Label_2                     matlab.ui.control.Label
        Q3Label_2                     matlab.ui.control.Label
        OptimalHVVLabel               matlab.ui.control.Label
        ActualHVVLabel                matlab.ui.control.Label
        Lamp_2                        matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        waitloopsec % Description
        resQ
    end
    

    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            if strmatch(value,'On')
                app.Lamp.Color='g'
            else
                app.Lamp.Color='r'
            end
            
                   % uialert(app.UIFigure,'Ok1','About','Icon','info')

            
            quaboconfig=importquaboconfig([getuserdir '\panoseti\defaultconfig\quabo_config.txt']);
            
             %  uialert(app.UIFigure,'Ok2','About','Icon','info')
            init=0
            if init==1
                % init the board?
                %disp('Init Marocs...')
                %sendconfig2Maroc(quaboconfig)
                %disp('Waiting 3s...')
                %pause(3)
                disp('Init Acq...')
                sentacqparams2board(quaboconfig)
                disp('Waiting 3s...')
                pause(3)
            end
            %disp('Init HV...')
            %sendHV(quaboconfig)
            %disp('Waiting 3s...')
            %pause(3)
            Temp125=[]; timehk=[];
            
            
            while strmatch(value,'On')
                % close all
               try
                        hk = getlastHK;
                    catch
                        try
                            wait(0.1)
                            hk = getlastHK;
                        catch
                            try
                                wait(0.1)
                            hk = getlastHK;
                            catch
                                try
                                    wait(0.1)
                                  hk = getlastHK;
                                catch
                                    
                                      uialert(app.UIFigure,'It seems HK is having issues...please restart apps.','About','Icon','info')
                                end
                            end
                        end
               end
                
                Tnow= hk.temp1
                
                  % uialert(app.UIFigure,'Ok3','About','Icon','info')
                if numel(timehk>2) && (hk.timecomp == timehk(end))
                     uialert(app.UIFigure,'It seems HK is not running...Please (re)-start PANO HK','About','Icon','info')
                end
                timehk=[timehk hk.timecomp];
                     
                     Temp125=[Temp125 Tnow];
                
                meth=0
                optimal25HV0=54.195;optimal25HV1=54.196;optimal25HV2=54.194;optimal25HV3=54.204;
                
                optimalHV0=optimal25HV0+(0.054*(Tnow-25));optimalHV1=optimal25HV1+(0.054*(Tnow-25));optimalHV2=optimal25HV2+(0.054*(Tnow-25));optimalHV3=optimal25HV3+(0.054*(Tnow-25));
                maxhv=65.1;
                resQ=1e-3*app.MaxresidualmVEditField.Value;%0.002;%resQ=5;
                costQ0=10*resQ;costQ1=10*resQ;costQ2=10*resQ;costQ3=10*resQ;
                optimalcnt=10;%for meth=1
                HVinc=0.0012073;
                IntensmeanQ0=[];IntensmeanQ1=[];IntensmeanQ2=[];IntensmeanQ3=[];
                realhvQ0=[];realhvQ1=[];realhvQ2=[];realhvQ3=[];
                realcurQ0=[];realcurQ1=[];realcurQ2=[];realcurQ3=[];
                app.Lamp_2.Color='g'
                app.Q0Label.Text=['Q0: ' num2str(hk.hvmon0)];
                app.Q1Label.Text=['Q1: ' num2str(hk.hvmon1)];
                app.Q2Label.Text=['Q2: ' num2str(hk.hvmon2)];
                app.Q3Label.Text=['Q3: ' num2str(hk.hvmon3)];
                app.Q0Label_2.Text=['Q0: ' num2str(optimalHV0)];
                app.Q1Label_2.Text=['Q1: ' num2str(optimalHV1)];
                app.Q2Label_2.Text=['Q2: ' num2str(optimalHV2)];
                app.Q3Label_2.Text=['Q3: ' num2str(optimalHV3)];
                app.TemperatureBoardTMP125CLabel.Text=['Temperature Board (TMP125) [C]:' num2str(Temp125(end))]
                pause(0.3)
                app.Lamp_2.Color=[1.00 0.96 0.93]
                
                %                 try
                %                     hk = getlastHK;
                %                 catch
                %                     try
                %                         hk = getlastHK;
                %                     catch
                %                         hk = getlastHK;
                %                     end
                %                 end
                
                %  app.Lamp.Color='r'
                %figure
                while ((abs(costQ0)>resQ) || (abs(costQ1)>resQ) || (abs(costQ2)>resQ) || (abs(costQ3)>resQ)) && (strmatch(value,'On'))
                    resQ=1e-3*app.MaxresidualmVEditField.Value;
                    % pause(6)
                    %
                    %     images=grabimages(10,1,1);
                    %
                    %     meanimage=mean(images(:,:,:),[3])';
                    %
                    %     %imagesc(meanimage)
                    %     %colorbar
                    %     timenow=now;
                    %     import matlab.io.*
                    %     utcshift=7/24;
                    %     filename = ['b_d0w0m0q0_' datestr(timenow+utcshift,'yyyymmdd_HHMMSS') ];
                    %     fullfilename=[ getuserdir filename];
                    %     fptr = fits.createFile([fullfilename '.fits']);
                    %
                    %     fits.createImg(fptr,'int32',[16 16]);
                    %     %fitsmode='overwrite';
                    %     img=int32(meanimage);
                    %     fits.writeImg(fptr,img)
                    %     fits.closeFile(fptr);
                    %
                    %     % IntensmaxQ2(nima)=max(ima(1:8,1:8),[],'all');
                    %     IntensmeanQ1=[IntensmeanQ1 mean(meanimage(1:8,1:8),[1 2])];
                    %     %   IntensmaxQ3(nima)=max(ima(1:8,9:16),[],'all');
                    %     IntensmeanQ2=[IntensmeanQ2 mean(meanimage(1:8,9:16),[1 2])];
                    %     %  IntensmaxQ1(nima)=max(ima(9:16,1:8),[],'all');
                    %     IntensmeanQ0=[IntensmeanQ0 mean(meanimage(9:16,1:8),[1 2])];
                    %     %   IntensmaxQ4(nima)=max(ima(9:16,9:16),[],'all');
                    %     IntensmeanQ3=[IntensmeanQ3 mean(meanimage(9:16,9:16),[1 2])];
                    %   uialert(app.UIFigure,'Ok4','About','Icon','info')
                    Vref=1250.;
                    [ia,index]=ismember('HV_0 ',quaboconfig);
                    HV_0=str2num(quaboconfig(index,2)) ;
                    HVrV_0= (( ( 0.0011453 * HV_0 - 1.073)    ));
                    
                    % HVrV_0=  (Vref*(10/(65536*158)))*HV_0;
                    
                    [ia,index]=ismember('HV_1 ',quaboconfig);
                    HV_1=str2num(quaboconfig(index,2)) ;
                    HVrV_1= (( ( 0.0011453 * HV_1 - 1.073)    ));
                    %HVrV_1= (floor( ( HV_1 +1.073)/0.0011453    ));
                    
                    [ia,index]=ismember('HV_2 ',quaboconfig);
                    HV_2=str2num(quaboconfig(index,2)) ;
                    HVrV_2= (( ( 0.0011453 * HV_2 - 1.073)    ));
                    %HVrV_2= (floor( ( HV_2 +1.073)/0.0011453    ));
                    
                    [ia,index]=ismember('HV_3 ',quaboconfig);
                    HV_3=str2num(quaboconfig(index,2)) ;
                    HVrV_3= (( ( 0.0011453 * HV_3 - 1.073)    ));
                    %HVrV_3= (floor( ( HV_3 +1.073)/0.0011453    ));
                    
                    tabnames={'HV_0 ','HV_1 ','HV_2 ',...
                        'HV_3 '};
                    
                    try
                        hk = getlastHK;
                    catch
                        try
                            wait(0.1)
                            hk = getlastHK;
                        catch
                            try
                                wait(0.1)
                            hk = getlastHK;
                            catch
                                try
                                    wait(0.1)
                                  hk = getlastHK;
                                catch
                                    
                                      uialert(app.UIFigure,'It seems HK is having issues...please restart apps.','About','Icon','info')
                                end
                            end
                        end
                    end
                    realhvQ0=[realhvQ0 hk.hvmon0];
                    realhvQ1=[realhvQ1 hk.hvmon1];
                    realhvQ2=[realhvQ2 hk.hvmon2];
                    realhvQ3=[realhvQ3 hk.hvmon3];
                    realcurQ0=[realcurQ0 hk.ihvmon0];
                    realcurQ1=[realcurQ1 hk.ihvmon1];
                    realcurQ2=[realcurQ2 hk.ihvmon2];
                    realcurQ3=[realcurQ3 hk.ihvmon3];
                    
                    app.Lamp_2.Color='g'
                    app.Q0Label.Text=['Q0: ' num2str(hk.hvmon0)];
                    app.Q1Label.Text=['Q1: ' num2str(hk.hvmon1)];
                    app.Q2Label.Text=['Q2: ' num2str(hk.hvmon2)];
                    app.Q3Label.Text=['Q3: ' num2str(hk.hvmon3)];
                    app.Q0Label_2.Text=['Q0: ' num2str(optimalHV0)];
                    app.Q1Label_2.Text=['Q1: ' num2str(optimalHV1)];
                    app.Q2Label_2.Text=['Q2: ' num2str(optimalHV2)];
                    app.Q3Label_2.Text=['Q3: ' num2str(optimalHV3)];
                    app.TemperatureBoardTMP125CLabel.Text=['Temperature Board (TMP125) [C]:' num2str(Temp125(end))]
                    pause(0.3)
                    app.Lamp_2.Color=[1.00 0.96 0.93]
                     value = app.Switch.Value;
                 %      uialert(app.UIFigure,'Ok5','About','Icon','info')
                    if meth==0
                        
                        costQ0=(realhvQ0(end)-optimalHV0);
                        costQ1=(realhvQ1(end)-optimalHV1);
                        costQ2=(realhvQ2(end)-optimalHV2);
                        costQ3=(realhvQ3(end)-optimalHV3);
                    elseif meth==1
                        %         costQ0=(IntensmeanQ0(end)-optimalcnt);
                        %         costQ1=(IntensmeanQ1(end)-optimalcnt);
                        %         costQ2=(IntensmeanQ2(end)-optimalcnt);
                        %         costQ3=(IntensmeanQ3(end)-optimalcnt);
                    end
                    
                    if (hk.hvmon0< maxhv) || (hk.hvmon1< maxhv) || (hk.hvmon2< maxhv) || (hk.hvmon3< maxhv)


                        changeHV0=0;
                        if (abs(costQ0)>resQ)
                            newHV0=HVrV_0-sign(costQ0)*abs(costQ0);%*HVinc
                            changeHV0=1;
                        else
                            %newHV0=HVrV_0;
                        end
                        changeHV1=0;
                        if (abs(costQ1)>resQ)
                            newHV1=HVrV_1-sign(costQ1)*abs(costQ1);%*HVinc;
                            changeHV1=1;
                        else
                            %newHV1=HVrV_1;
                        end
                        changeHV2=0;
                        if (abs(costQ2)>resQ)
                            newHV2=HVrV_2-sign(costQ2)*abs(costQ2);%*HVinc;
                            changeHV2=1;
                        else
                            %newHV2=HVrV_2;
                        end
                        changeHV3=0;
                        if (abs(costQ3)>resQ)
                            newHV3=HVrV_3-sign(costQ3)*abs(costQ3);%*HVinc;
                            changeHV3=1;
                        else
                            %newHV3=HVrV_3;
                        end
                        
                        disp(['Req. HV0:' num2str(HVrV_0) '(' num2str(HV_0) ')' ...
                            ' HV1:' num2str(HVrV_1) '(' num2str(HV_1) ')' ...
                            ' HV2:' num2str(HVrV_2) '(' num2str(HV_2) ')' ...
                            ' HV3:' num2str(HVrV_3) '(' num2str(HV_3) ')'])
                        disp(['Real HV0:' num2str(hk.hvmon0) ' HV1:' num2str(hk.hvmon1) ' HV2:' num2str(hk.hvmon2) ' HV3:' num2str(hk.hvmon3)])
                        %         disp(['Cnts Q0:' num2str(IntensmeanQ0(end))...
                        %             ' Q1:' num2str(IntensmeanQ1(end))  ' Q2:' num2str(IntensmeanQ2(end))  ' Q3:' num2str(IntensmeanQ3(end))])
                        %
                        %oldvalue=quaboconfig(index,2);
%                         if  isnumeric(newHV0) && (newHV0>0) && (newHV0<maxhv) && ...
%                                 isnumeric(newHV1) && (newHV1>0) && (newHV1<maxhv) && ...
%                                 isnumeric(newHV2) && (newHV2>0) && (newHV2<maxhv) && ...
%                                 isnumeric(newHV3) && (newHV3>0) && (newHV3<maxhv)
                            % (1/(Vref*(10/(65536*158))))*
                            if exist('newHV0','var') &&  isnumeric(newHV0) && (newHV0>0) && (newHV0<maxhv)
                            [ia,index]=ismember('HV_0 ',quaboconfig);
                            %quaboconfig(index,2)=string(floor(    (1/(Vref*(10/(65536*158))))*newHV0     ));
                            %quaboconfig(index,2)=string(floor( ( optimalHV0 +1.073)/0.0011453    ));
                            quaboconfig(index,2)=string(floor( ( newHV0 +1.073)/0.0011453    ));
                            end
                            if exist('newHV1','var') &&  isnumeric(newHV1) && (newHV1>0) && (newHV1<maxhv)
                            [ia,index]=ismember('HV_1 ',quaboconfig);
                            %quaboconfig(index,2)=string(floor(    (1/(Vref*(10/(65536*158))))*newHV1     ));
                            %quaboconfig(index,2)=string(floor( ( optimalHV1 +1.073)/0.0011453    ));
                            quaboconfig(index,2)=string(floor( ( newHV1 +1.073)/0.0011453    ));
                            end
                            if exist('newHV2','var') &&  isnumeric(newHV2) && (newHV2>0) && (newHV2<maxhv)
                            [ia,index]=ismember('HV_2 ',quaboconfig);
                            %quaboconfig(index,2)=string(floor(    (1/(Vref*(10/(65536*158))))*newHV2     ));
                            % quaboconfig(index,2)=string(floor( ( optimalHV2 +1.073)/0.0011453    ));
                            quaboconfig(index,2)=string(floor( ( newHV2 +1.073)/0.0011453    ));
                            end
                            if exist('newHV3','var') &&  isnumeric(newHV3) && (newHV3>0) && (newHV3<maxhv)
                            [ia,index]=ismember('HV_3 ',quaboconfig);
                            % quaboconfig(index,2)=string(floor(    (1/(Vref*(10/(65536*158))))*newHV3     ));
                            %quaboconfig(index,2)=string(floor( ( optimalHV3 +1.073)/0.0011453    ));
                            quaboconfig(index,2)=string(floor( ( newHV3 +1.073)/0.0011453    ));
                            %quaboconfig(index,2)=string(floor(    (65536/75)*newData     ));
                            end
                            
                            disp(['Sign Q0:' num2str(-changeHV0*sign(costQ0)) ...
                                'Sign Q1:'  num2str(-changeHV1*sign(costQ1)) ...
                                'Sign Q2:'  num2str(-changeHV2*sign(costQ2)) ...
                                'Sign Q3:'  num2str(-changeHV3*sign(costQ3))])
                            %             disp(['Send new HV0:' num2str(newHV0) ' (' num2str(floor(    (1/(Vref*(10/(65536*158))))*newHV0     )) ')' ...
                            %                 ' new HV1:' num2str(newHV1) ' (' num2str(floor(    (1/(Vref*(10/(65536*158))))*newHV1     )) ')' ...
                            %                 ' new HV2:' num2str(newHV2) ' (' num2str(floor(    (1/(Vref*(10/(65536*158))))*newHV2     )) ')' ...
                            %                 ' new HV3:' num2str(newHV3) ' (' num2str(floor(    (1/(Vref*(10/(65536*158))))*newHV3     )) ')' ...
                            %                 ])
                            
                            
                            if changeHV0
                                app.Q0Label.BackgroundColor=[0.91 0.41 0.17];
                            else
                                app.Q0Label.BackgroundColor='g';
                            end
                            if changeHV1
                                app.Q1Label.BackgroundColor=[0.91 0.41 0.17];
                            else
                                app.Q1Label.BackgroundColor='g';
                            end
                            if changeHV2
                                app.Q2Label.BackgroundColor=[0.91 0.41 0.17];
                            else
                                app.Q2Label.BackgroundColor='g';
                            end
                            if changeHV3
                                app.Q3Label.BackgroundColor=[0.91 0.41 0.17];
                            else
                                app.Q3Label.BackgroundColor='g';
                            end
                            
                            if (changeHV0+changeHV1+changeHV2+changeHV3>0)
                                
                                
                                disp(['Temp125[\circC] Now: ' num2str(Temp125(end)) ' previous iter was:' num2str(Temp125(end-1>0))])
                                disp(['Requested HV Q0[V]:' num2str(optimalHV0) ' Q1[V]:' num2str(optimalHV1) ...
                                    ' Q2[V]:' num2str(optimalHV2) ' Q3[V]:' num2str(optimalHV3)])
                                
                                sendHV(quaboconfig)
                            else
                                
                                
                            end
                            disp(['Real HV Q0[V]:' num2str(hk.hvmon0) ' Q1[V]:' num2str(hk.hvmon1) ...
                                ' Q2[V]:' num2str(hk.hvmon2) ' Q3[V]:' num2str(hk.hvmon3)])
                            disp(['Optimal HV Q0[V]:' num2str(optimalHV0) ' Q1[V]:' num2str(optimalHV1) ...
                                ' Q2[V]:' num2str(optimalHV2) ' Q3[V]:' num2str(optimalHV3)])
                        %end
                    end
                    
                    pause(5)
                end
                tpause=app.AdjustmentperiodsecEditField.Value
                pause(tpause)
                if exist('app','var')
                value = app.Switch.Value;
                end
                %plot HVs, current, cnts
                % cpsfac=400;
                % figure
                % set(gcf, 'Color','w')
                % subplot(1,3,1)
                % hold on
                % plot(cpsfac*IntensmeanQ0,'b')
                % plot(cpsfac*IntensmeanQ1,'r')
                % plot(cpsfac*IntensmeanQ2,'g')
                % plot(cpsfac*IntensmeanQ3,'m')
                % hold off
                % xlabel('iter#')
                % ylabel('Mean intensity [cps]')
                % legend('Q0','Q1','Q2','Q3')
                %
                % subplot(1,3,2)
                % hold on
                % plot(realhvQ0,'b')
                % plot(realhvQ1,'r')
                % plot(realhvQ2,'g')
                % plot(realhvQ3,'m')
                % hold off
                % xlabel('iter#')
                % ylabel('HV [V]')
                % legend('Q0','Q1','Q2','Q3')
                %
                % subplot(1,3,3)
                % hold on
                % plot(realcurQ0,'b')
                % plot(realcurQ1,'r')
                % plot(realcurQ2,'g')
                % plot(realcurQ3,'m')
                % hold off
                % xlabel('iter#')
                % ylabel('I [\muA]')
                % legend('Q0','Q1','Q2','Q3')
                %
                % datenow=datestr(now,'yymmdd_HHMMSS');
                %    saveas(gcf,['HVinitVop' datenow '.png'])
                %    saveas(gcf,['HVinitVop' datenow '.fig'])
                %Real HV0:54.9956 HV1:54.7687 HV2:54.9582 HV3:54.59
                
                
            end

        end

        % Value changed function: AdjustmentperiodsecEditField
        function AdjustmentperiodsecEditFieldValueChanged(app, event)
            value = app.AdjustmentperiodsecEditField.Value;
            app.waitloopsec=value;
        end

        % Value changed function: MaxresidualmVEditField
        function MaxresidualmVEditFieldValueChanged(app, event)
            value = app.MaxresidualmVEditField.Value;
             app.resQ=value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             uialert(app.UIFigure,'PANO HVloop v1.0 (jmaire@ucsd.edu)','About','Icon','info')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.HVadjustmentLabel = uilabel(app.UIFigure);
            app.HVadjustmentLabel.Text = 'HV adjustment';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'PANOlogo0.png';
            app.Button.Text = '';
            app.AdjustmentperiodsecLabel = uilabel(app.UIFigure);
            app.AdjustmentperiodsecLabel.Text = 'Adjustment period [sec]:';
            app.AdjustmentperiodsecEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdjustmentperiodsecEditField.Limits = [0.001 Inf];
            app.AdjustmentperiodsecEditField.ValueChangedFcn = createCallbackFcn(app, @AdjustmentperiodsecEditFieldValueChanged, true);
            app.AdjustmentperiodsecEditField.Value = 10;
            app.MaxresidualmVEditFieldLabel = uilabel(app.UIFigure);
            app.MaxresidualmVEditFieldLabel.Text = 'Max residual [mV] :';
            app.MaxresidualmVEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxresidualmVEditField.Limits = [2 Inf];
            app.MaxresidualmVEditField.ValueChangedFcn = createCallbackFcn(app, @MaxresidualmVEditFieldValueChanged, true);
            app.MaxresidualmVEditField.Value = 2;
            app.TemperatureBoardTMP125CLabel = uilabel(app.UIFigure);
            app.TemperatureBoardTMP125CLabel.Text = 'Temperature Board (TMP125) [C]:';
            app.Q0Label_2 = uilabel(app.UIFigure);
            app.Q0Label_2.Text = 'Q0:        ';
            app.Q1Label = uilabel(app.UIFigure);
            app.Q1Label.Text = 'Q1:';
            app.Q2Label = uilabel(app.UIFigure);
            app.Q2Label.Text = 'Q2:';
            app.Q3Label = uilabel(app.UIFigure);
            app.Q3Label.Text = 'Q3:';
            app.Q0Label = uilabel(app.UIFigure);
            app.Q0Label.Text = 'Q0:';
            app.Q1Label_2 = uilabel(app.UIFigure);
            app.Q1Label_2.Text = 'Q1:';
            app.Q2Label_2 = uilabel(app.UIFigure);
            app.Q2Label_2.Text = 'Q2:';
            app.Q3Label_2 = uilabel(app.UIFigure);
            app.Q3Label_2.Text = 'Q3:';
            app.OptimalHVVLabel = uilabel(app.UIFigure);
            app.OptimalHVVLabel.Text = 'Optimal HV [V] :';
            app.ActualHVVLabel = uilabel(app.UIFigure);
            app.ActualHVVLabel.Text = 'Actual HV [V] :';
            app.Lamp_2 = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = HVloop

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end