classdef HVloop14 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HVadjustmentLabel             matlab.ui.control.Label
        Switch                        matlab.ui.control.ToggleSwitch
        Lamp                          matlab.ui.control.Lamp
        Button                        matlab.ui.control.Button
        AdjustmentperiodsecLabel      matlab.ui.control.Label
        AdjustmentperiodsecEditField  matlab.ui.control.NumericEditField
        MaxresidualmVEditFieldLabel   matlab.ui.control.Label
        MaxresidualmVEditField        matlab.ui.control.NumericEditField
        TemperatureBoardTMP125CLabel  matlab.ui.control.Label
        Q0Label_2                     matlab.ui.control.Label
        Q1Label                       matlab.ui.control.Label
        Q2Label                       matlab.ui.control.Label
        Q3Label                       matlab.ui.control.Label
        Q0Label                       matlab.ui.control.Label
        Q1Label_2                     matlab.ui.control.Label
        Q2Label_2                     matlab.ui.control.Label
        Q3Label_2                     matlab.ui.control.Label
        OptimalHVVLabel               matlab.ui.control.Label
        ActualHVVLabel                matlab.ui.control.Label
        Lamp_2                        matlab.ui.control.Lamp
        QuaboDropDownLabel            matlab.ui.control.Label
        QuaboDropDown                 matlab.ui.control.DropDown
        OptimalHVTEMP25CVLabel        matlab.ui.control.Label
        Q0Label_3                     matlab.ui.control.Label
        Q1Label_3                     matlab.ui.control.Label
        Q2Label_3                     matlab.ui.control.Label
        Q3Label_3                     matlab.ui.control.Label
        SelectIPLabel                 matlab.ui.control.Label
        SelectIPDropDown              matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        waitloopsec % Description
        resQ
        quabo
        HV1
        HV2
        HV3
        HV4
        IP
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %vargin eg '192.168.1.10','SN009'
            if length(varargin)>1
                %find which board according to varargin(2) supposed to be string 'SN002' e.g.
                items=app.QuaboDropDown.Items
                matchcnt=0
                matchind=0
                while matchind==0
%                     uiwait(msgbox(['cell2mat(items(matchcnt+1)):' cell2mat(items(matchcnt+1))],'Success','modal'))
%                     uiwait(msgbox(['cell2mat(varargin(1)):' cell2mat(varargin(1))],'Success','modal'))
%                     uiwait(msgbox(['cell2mat(varargin(2)):' cell2mat(varargin(2))],'Success','modal'))
                  
                    if strncmp(cell2mat(items(matchcnt+1)), cell2mat(varargin(2)),5)
                         matchind=matchcnt+1
                    end
                matchcnt=matchcnt+1
                end
                app.QuaboDropDown.Value=items(matchcnt)
                app.Switch.Value='On'
                app.IP=varargin(1)
                QuaboDropDownValueChanged(app, [])
                SwitchValueChanged(app, [])
            else
                app.IP=app.SelectIPDropDown.Value
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
           % disp(~strmatch(app.QuaboDropDown.Value, 'Select ID'))
            if ~strncmp(app.QuaboDropDown.Value, 'Select ID',5)
            if strmatch(value,'On')
                app.Lamp.Color='g'
            else
                app.Lamp.Color='r'
            end
            
                   % uialert(app.UIFigure,'Ok1','About','Icon','info')

            
            quaboconfig=importquaboconfig([getuserdir '\panoseti\defaultconfig\quabo_config.txt']);
            
            szq=size(quaboconfig,1);
            quaboconfig(szq+1,1)={'IP'};quaboconfig(szq+1,3)={app.IP};
            
             %  uialert(app.UIFigure,'Ok2','About','Icon','info')
            init=0
            if init==1
                % init the board?
                %disp('Init Marocs...')
                %sendconfig2Maroc(quaboconfig)
                %disp('Waiting 3s...')
                %pause(3)
                disp('Init Acq...')
                sentacqparams2board(quaboconfig)
                disp('Waiting 3s...')
                pause(3)
            end
            %disp('Init HV...')
            %sendHV(quaboconfig)
            %disp('Waiting 3s...')
            %pause(3)
            Temp125=[]; timehk=[];
            
            
            while strmatch(value,'On')
                % close all
               try
                        hk = getlastHKmultiIP(0,4660);
                    catch
                        try
                            wait(0.1)
                            hk = getlastHKmultiIP(0,4660);
                        catch
                            try
                                wait(0.1)
                            hk = getlastHKmultiIP(0,4660);
                            catch
                                try
                                    wait(0.1)
                                  hk = getlastHKmultiIP(0,4660);
                                catch
                                    
                                     % uialert(app.UIFigure,'It seems HK is having issues...please restart apps.','About','Icon','info')
                                end
                            end
                        end
               end
                
                Tnow= hk.temp1
                
                  % uialert(app.UIFigure,'Ok3','About','Icon','info')
                if numel(timehk)>3 && (hk.timecomp == timehk(end)) && (hk.timecomp == timehk(end-1))
                    
                     %uialert(app.UIFigure,'It seems HK is not running...Please (re)-start PANO HK','About','Icon','info')
                end
                timehk=[timehk hk.timecomp];
                     
                     Temp125=[Temp125 Tnow];
                
                meth=0

                optimal25HV0=app.HV1;optimal25HV1=app.HV2;optimal25HV2=app.HV3;optimal25HV3=app.HV4;
                
                optimalHV0=optimal25HV0+(0.054*(Tnow-25));optimalHV1=optimal25HV1+(0.054*(Tnow-25));optimalHV2=optimal25HV2+(0.054*(Tnow-25));optimalHV3=optimal25HV3+(0.054*(Tnow-25));
                maxhv=65.1;
                resQ=1e-3*app.MaxresidualmVEditField.Value;%0.002;%resQ=5;
                costQ0=10*resQ;costQ1=10*resQ;costQ2=10*resQ;costQ3=10*resQ;
                optimalcnt=10;%for meth=1
                HVinc=0.0012073;
                IntensmeanQ0=[];IntensmeanQ1=[];IntensmeanQ2=[];IntensmeanQ3=[];
                realhvQ0=[];realhvQ1=[];realhvQ2=[];realhvQ3=[];
                realcurQ0=[];realcurQ1=[];realcurQ2=[];realcurQ3=[];
                app.Lamp_2.Color='g'
                app.Q0Label.Text=['Q0: ' num2str(hk.hvmon0)];
                app.Q1Label.Text=['Q1: ' num2str(hk.hvmon1)];
                app.Q2Label.Text=['Q2: ' num2str(hk.hvmon2)];
                app.Q3Label.Text=['Q3: ' num2str(hk.hvmon3)];
                app.Q0Label_2.Text=['Q0: ' num2str(optimalHV0)];
                app.Q1Label_2.Text=['Q1: ' num2str(optimalHV1)];
                app.Q2Label_2.Text=['Q2: ' num2str(optimalHV2)];
                app.Q3Label_2.Text=['Q3: ' num2str(optimalHV3)];
                app.TemperatureBoardTMP125CLabel.Text=['Temperature Board (TMP125) [C]:' num2str(Temp125(end))]
                pause(0.3)
                app.Lamp_2.Color=[1.00 0.96 0.93]
                
                %                 try
                %                     hk = getlastHK;
                %                 catch
                %                     try
                %                         hk = getlastHK;
                %                     catch
                %                         hk = getlastHK;
                %                     end
                %                 end
                
                %  app.Lamp.Color='r'
                %figure
                while ((abs(costQ0)>resQ) || (abs(costQ1)>resQ) || (abs(costQ2)>resQ) || (abs(costQ3)>resQ)) && (strmatch(value,'On'))
                    resQ=1e-3*app.MaxresidualmVEditField.Value;
                    % pause(6)
                    %
                    %     images=grabimages(10,1,1);
                    %
                    %     meanimage=mean(images(:,:,:),[3])';
                    %
                    %     %imagesc(meanimage)
                    %     %colorbar
                    %     timenow=now;
                    %     import matlab.io.*
                    %     utcshift=7/24;
                    %     filename = ['b_d0w0m0q0_' datestr(timenow+utcshift,'yyyymmdd_HHMMSS') ];
                    %     fullfilename=[ getuserdir filename];
                    %     fptr = fits.createFile([fullfilename '.fits']);
                    %
                    %     fits.createImg(fptr,'int32',[16 16]);
                    %     %fitsmode='overwrite';
                    %     img=int32(meanimage);
                    %     fits.writeImg(fptr,img)
                    %     fits.closeFile(fptr);
                    %
                    %     % IntensmaxQ2(nima)=max(ima(1:8,1:8),[],'all');
                    %     IntensmeanQ1=[IntensmeanQ1 mean(meanimage(1:8,1:8),[1 2])];
                    %     %   IntensmaxQ3(nima)=max(ima(1:8,9:16),[],'all');
                    %     IntensmeanQ2=[IntensmeanQ2 mean(meanimage(1:8,9:16),[1 2])];
                    %     %  IntensmaxQ1(nima)=max(ima(9:16,1:8),[],'all');
                    %     IntensmeanQ0=[IntensmeanQ0 mean(meanimage(9:16,1:8),[1 2])];
                    %     %   IntensmaxQ4(nima)=max(ima(9:16,9:16),[],'all');
                    %     IntensmeanQ3=[IntensmeanQ3 mean(meanimage(9:16,9:16),[1 2])];
                    %   uialert(app.UIFigure,'Ok4','About','Icon','info')
                    Vref=1250.;
                    [ia,index]=ismember('HV_0 ',quaboconfig);
                    HV_0=str2num(quaboconfig(index,2)) ;
                    HVrV_0= (( ( 0.0011453 * HV_0 - 1.073)    ));
                    
                    % HVrV_0=  (Vref*(10/(65536*158)))*HV_0;
                    
                    [ia,index]=ismember('HV_1 ',quaboconfig);
                    HV_1=str2num(quaboconfig(index,2)) ;
                    HVrV_1= (( ( 0.0011453 * HV_1 - 1.073)    ));
                    %HVrV_1= (floor( ( HV_1 +1.073)/0.0011453    ));
                    
                    [ia,index]=ismember('HV_2 ',quaboconfig);
                    HV_2=str2num(quaboconfig(index,2)) ;
                    HVrV_2= (( ( 0.0011453 * HV_2 - 1.073)    ));
                    %HVrV_2= (floor( ( HV_2 +1.073)/0.0011453    ));
                    
                    [ia,index]=ismember('HV_3 ',quaboconfig);
                    HV_3=str2num(quaboconfig(index,2)) ;
                    HVrV_3= (( ( 0.0011453 * HV_3 - 1.073)    ));
                    %HVrV_3= (floor( ( HV_3 +1.073)/0.0011453    ));
                    
                    tabnames={'HV_0 ','HV_1 ','HV_2 ',...
                        'HV_3 '};
                    
                    try
                        hk = getlastHKmultiIP(0,4660);
                    catch
                        try
                            wait(0.1)
                            hk = getlastHKmultiIP(0,4660);
                        catch
                            try
                                wait(0.1)
                            hk = getlastHKmultiIP(0,4660);
                            catch
                                try
                                    wait(0.1)
                                  hk = getlastHKmultiIP(0,4660);
                                catch
                                    
                                   %   uialert(app.UIFigure,'It seems HK is having issues...please restart apps.','About','Icon','info')
                                end
                            end
                        end
                    end
                    realhvQ0=[realhvQ0 hk.hvmon0];
                    realhvQ1=[realhvQ1 hk.hvmon1];
                    realhvQ2=[realhvQ2 hk.hvmon2];
                    realhvQ3=[realhvQ3 hk.hvmon3];
                    realcurQ0=[realcurQ0 hk.ihvmon0];
                    realcurQ1=[realcurQ1 hk.ihvmon1];
                    realcurQ2=[realcurQ2 hk.ihvmon2];
                    realcurQ3=[realcurQ3 hk.ihvmon3];
                    
                    app.Lamp_2.Color='g'
                    app.Q0Label.Text=['Q0: ' num2str(hk.hvmon0)];
                    app.Q1Label.Text=['Q1: ' num2str(hk.hvmon1)];
                    app.Q2Label.Text=['Q2: ' num2str(hk.hvmon2)];
                    app.Q3Label.Text=['Q3: ' num2str(hk.hvmon3)];
                    app.Q0Label_2.Text=['Q0: ' num2str(optimalHV0)];
                    app.Q1Label_2.Text=['Q1: ' num2str(optimalHV1)];
                    app.Q2Label_2.Text=['Q2: ' num2str(optimalHV2)];
                    app.Q3Label_2.Text=['Q3: ' num2str(optimalHV3)];
                    app.TemperatureBoardTMP125CLabel.Text=['Temperature Board (TMP125) [C]:' num2str(Temp125(end))]
                    pause(0.3)
                    app.Lamp_2.Color=[1.00 0.96 0.93]
                     value = app.Switch.Value;
                 %      uialert(app.UIFigure,'Ok5','About','Icon','info')
                    if meth==0
                        
                        costQ0=(realhvQ0(end)-optimalHV0);
                        costQ1=(realhvQ1(end)-optimalHV1);
                        costQ2=(realhvQ2(end)-optimalHV2);
                        costQ3=(realhvQ3(end)-optimalHV3);
                    elseif meth==1
                        %         costQ0=(IntensmeanQ0(end)-optimalcnt);
                        %         costQ1=(IntensmeanQ1(end)-optimalcnt);
                        %         costQ2=(IntensmeanQ2(end)-optimalcnt);
                        %         costQ3=(IntensmeanQ3(end)-optimalcnt);
                    end
                    
                    if (hk.hvmon0< maxhv) || (hk.hvmon1< maxhv) || (hk.hvmon2< maxhv) || (hk.hvmon3< maxhv)


                        changeHV0=0;
                        if (abs(costQ0)>resQ)
                            newHV0=HVrV_0-sign(costQ0)*abs(costQ0);%*HVinc
                            changeHV0=1;
                        else
                            %newHV0=HVrV_0;
                        end
                        changeHV1=0;
                        if (abs(costQ1)>resQ)
                            newHV1=HVrV_1-sign(costQ1)*abs(costQ1);%*HVinc;
                            changeHV1=1;
                        else
                            %newHV1=HVrV_1;
                        end
                        changeHV2=0;
                        if (abs(costQ2)>resQ)
                            newHV2=HVrV_2-sign(costQ2)*abs(costQ2);%*HVinc;
                            changeHV2=1;
                        else
                            %newHV2=HVrV_2;
                        end
                        changeHV3=0;
                        if (abs(costQ3)>resQ)
                            newHV3=HVrV_3-sign(costQ3)*abs(costQ3);%*HVinc;
                            changeHV3=1;
                        else
                            %newHV3=HVrV_3;
                        end
                        
                        disp(['Req. HV0:' num2str(HVrV_0) '(' num2str(HV_0) ')' ...
                            ' HV1:' num2str(HVrV_1) '(' num2str(HV_1) ')' ...
                            ' HV2:' num2str(HVrV_2) '(' num2str(HV_2) ')' ...
                            ' HV3:' num2str(HVrV_3) '(' num2str(HV_3) ')'])
                        disp(['Real HV0:' num2str(hk.hvmon0) ' HV1:' num2str(hk.hvmon1) ' HV2:' num2str(hk.hvmon2) ' HV3:' num2str(hk.hvmon3)])
                        %         disp(['Cnts Q0:' num2str(IntensmeanQ0(end))...
                        %             ' Q1:' num2str(IntensmeanQ1(end))  ' Q2:' num2str(IntensmeanQ2(end))  ' Q3:' num2str(IntensmeanQ3(end))])
                        %
                        %oldvalue=quaboconfig(index,2);
%                         if  isnumeric(newHV0) && (newHV0>0) && (newHV0<maxhv) && ...
%                                 isnumeric(newHV1) && (newHV1>0) && (newHV1<maxhv) && ...
%                                 isnumeric(newHV2) && (newHV2>0) && (newHV2<maxhv) && ...
%                                 isnumeric(newHV3) && (newHV3>0) && (newHV3<maxhv)
                            % (1/(Vref*(10/(65536*158))))*
                            if exist('newHV0','var') &&  isnumeric(newHV0) && (newHV0>0) && (newHV0<maxhv)
                            [ia,index]=ismember('HV_0 ',quaboconfig);
                            %quaboconfig(index,2)=string(floor(    (1/(Vref*(10/(65536*158))))*newHV0     ));
                            %quaboconfig(index,2)=string(floor( ( optimalHV0 +1.073)/0.0011453    ));
                            quaboconfig(index,2)=string(floor( ( newHV0 +1.073)/0.0011453    ));
                            end
                            if exist('newHV1','var') &&  isnumeric(newHV1) && (newHV1>0) && (newHV1<maxhv)
                            [ia,index]=ismember('HV_1 ',quaboconfig);
                            %quaboconfig(index,2)=string(floor(    (1/(Vref*(10/(65536*158))))*newHV1     ));
                            %quaboconfig(index,2)=string(floor( ( optimalHV1 +1.073)/0.0011453    ));
                            quaboconfig(index,2)=string(floor( ( newHV1 +1.073)/0.0011453    ));
                            end
                            if exist('newHV2','var') &&  isnumeric(newHV2) && (newHV2>0) && (newHV2<maxhv)
                            [ia,index]=ismember('HV_2 ',quaboconfig);
                            %quaboconfig(index,2)=string(floor(    (1/(Vref*(10/(65536*158))))*newHV2     ));
                            % quaboconfig(index,2)=string(floor( ( optimalHV2 +1.073)/0.0011453    ));
                            quaboconfig(index,2)=string(floor( ( newHV2 +1.073)/0.0011453    ));
                            end
                            if exist('newHV3','var') &&  isnumeric(newHV3) && (newHV3>0) && (newHV3<maxhv)
                            [ia,index]=ismember('HV_3 ',quaboconfig);
                            % quaboconfig(index,2)=string(floor(    (1/(Vref*(10/(65536*158))))*newHV3     ));
                            %quaboconfig(index,2)=string(floor( ( optimalHV3 +1.073)/0.0011453    ));
                            quaboconfig(index,2)=string(floor( ( newHV3 +1.073)/0.0011453    ));
                            %quaboconfig(index,2)=string(floor(    (65536/75)*newData     ));
                            end
                            
                            disp(['Sign Q0:' num2str(-changeHV0*sign(costQ0)) ...
                                'Sign Q1:'  num2str(-changeHV1*sign(costQ1)) ...
                                'Sign Q2:'  num2str(-changeHV2*sign(costQ2)) ...
                                'Sign Q3:'  num2str(-changeHV3*sign(costQ3))])
                            %             disp(['Send new HV0:' num2str(newHV0) ' (' num2str(floor(    (1/(Vref*(10/(65536*158))))*newHV0     )) ')' ...
                            %                 ' new HV1:' num2str(newHV1) ' (' num2str(floor(    (1/(Vref*(10/(65536*158))))*newHV1     )) ')' ...
                            %                 ' new HV2:' num2str(newHV2) ' (' num2str(floor(    (1/(Vref*(10/(65536*158))))*newHV2     )) ')' ...
                            %                 ' new HV3:' num2str(newHV3) ' (' num2str(floor(    (1/(Vref*(10/(65536*158))))*newHV3     )) ')' ...
                            %                 ])
                            
                            
                            if changeHV0
                                app.Q0Label.BackgroundColor=[0.91 0.41 0.17];
                            else
                                app.Q0Label.BackgroundColor='g';
                            end
                            if changeHV1
                                app.Q1Label.BackgroundColor=[0.91 0.41 0.17];
                            else
                                app.Q1Label.BackgroundColor='g';
                            end
                            if changeHV2
                                app.Q2Label.BackgroundColor=[0.91 0.41 0.17];
                            else
                                app.Q2Label.BackgroundColor='g';
                            end
                            if changeHV3
                                app.Q3Label.BackgroundColor=[0.91 0.41 0.17];
                            else
                                app.Q3Label.BackgroundColor='g';
                            end
                            
                            if (changeHV0+changeHV1+changeHV2+changeHV3>0)
                                
                                
                                disp(['Temp125[\circC] Now: ' num2str(Temp125(end)) ' previous iter was:' num2str(Temp125(end-1>0))])
                                disp(['Requested HV Q0[V]:' num2str(optimalHV0) ' Q1[V]:' num2str(optimalHV1) ...
                                    ' Q2[V]:' num2str(optimalHV2) ' Q3[V]:' num2str(optimalHV3)])
                                try
                                sendHV(quaboconfig)
                                catch
                                    pause(1)
                                    sendHV(quaboconfig)
                                end
                                    
                            else
                                
                                
                            end
                            disp(['Real HV Q0[V]:' num2str(hk.hvmon0) ' Q1[V]:' num2str(hk.hvmon1) ...
                                ' Q2[V]:' num2str(hk.hvmon2) ' Q3[V]:' num2str(hk.hvmon3)])
                            disp(['Optimal HV Q0[V]:' num2str(optimalHV0) ' Q1[V]:' num2str(optimalHV1) ...
                                ' Q2[V]:' num2str(optimalHV2) ' Q3[V]:' num2str(optimalHV3)])
                        %end
                    end
                    
                    pause(5)
                end
                tpause=app.AdjustmentperiodsecEditField.Value
                pause(tpause)
                if exist('app','var')
                value = app.Switch.Value;
                end
                %plot HVs, current, cnts
                % cpsfac=400;
                % figure
                % set(gcf, 'Color','w')
                % subplot(1,3,1)
                % hold on
                % plot(cpsfac*IntensmeanQ0,'b')
                % plot(cpsfac*IntensmeanQ1,'r')
                % plot(cpsfac*IntensmeanQ2,'g')
                % plot(cpsfac*IntensmeanQ3,'m')
                % hold off
                % xlabel('iter#')
                % ylabel('Mean intensity [cps]')
                % legend('Q0','Q1','Q2','Q3')
                %
                % subplot(1,3,2)
                % hold on
                % plot(realhvQ0,'b')
                % plot(realhvQ1,'r')
                % plot(realhvQ2,'g')
                % plot(realhvQ3,'m')
                % hold off
                % xlabel('iter#')
                % ylabel('HV [V]')
                % legend('Q0','Q1','Q2','Q3')
                %
                % subplot(1,3,3)
                % hold on
                % plot(realcurQ0,'b')
                % plot(realcurQ1,'r')
                % plot(realcurQ2,'g')
                % plot(realcurQ3,'m')
                % hold off
                % xlabel('iter#')
                % ylabel('I [\muA]')
                % legend('Q0','Q1','Q2','Q3')
                %
                % datenow=datestr(now,'yymmdd_HHMMSS');
                %    saveas(gcf,['HVinitVop' datenow '.png'])
                %    saveas(gcf,['HVinitVop' datenow '.fig'])
                %Real HV0:54.9956 HV1:54.7687 HV2:54.9582 HV3:54.59
                
                
            end
            else
                uialert(app.UIFigure,'Please Select a Quabo','About','Icon','info')
                app.Switch.Value='Off'
            end
        end

        % Value changed function: AdjustmentperiodsecEditField
        function AdjustmentperiodsecEditFieldValueChanged(app, event)
            value = app.AdjustmentperiodsecEditField.Value;
            app.waitloopsec=value;
        end

        % Value changed function: MaxresidualmVEditField
        function MaxresidualmVEditFieldValueChanged(app, event)
            value = app.MaxresidualmVEditField.Value;
             app.resQ=value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             uialert(app.UIFigure,'PANO HVloop v1.4 (jmaire@ucsd.edu)','About','Icon','info')
        end

        % Value changed function: QuaboDropDown
        function QuaboDropDownValueChanged(app, event)
            quabo = app.QuaboDropDown.Value;
            app.quabo = quabo
            det_494= [54.54 54.56 54.55 54.53 54.53 54.48 54.55 54.56 54.55 54.53 54.50 54.51 54.54 54.50 54.56 54.49 54.54 54.55 54.52 54.51 54.54 54.51 54.54 54.52 54.55 54.57 54.53 54.51 54.51 54.51 54.52 54.56 54.58 54.51 54.53 54.52 54.53 54.59 54.50 54.58 54.58 54.52 54.51 54.52 54.51 54.54 54.54 54.56 54.60 54.54 54.58 54.55 54.55 54.51 54.57 54.54 54.61 54.61 54.59 54.55 54.59 54.56 54.58 54.53 ]
            det_495= [54.46 54.45 54.43 54.38 54.41 54.47 54.45 54.48 54.44 54.43 54.43 54.38 54.37 54.45 54.45 54.47 54.41 54.37 54.44 54.42 54.39 54.38 54.45 54.44 54.39 54.38 54.46 54.44 54.43 54.40 54.41 54.40 54.40 54.40 54.41 54.44 54.47 54.42 54.39 54.38 54.42 54.40 54.46 54.47 54.45 54.44 54.42 54.42 54.46 54.47 54.45 54.44 54.49 54.46 54.42 54.45 54.50 54.50 54.49 54.46 54.45 54.48 54.45 54.45 ]
            det_496= [54.76 54.82 54.78 54.83 54.81 54.77 54.78 54.82 54.78 54.81 54.80 54.81 54.80 54.80 54.83 54.75 54.77 54.83 54.80 54.80 54.76 54.78 54.84 54.78 54.77 54.80 54.82 54.82 54.79 54.81 54.83 54.85 54.80 54.80 54.85 54.76 54.79 54.86 54.84 54.80 54.81 54.81 54.83 54.76 54.79 54.83 54.82 54.83 54.80 54.81 54.84 54.76 54.82 54.82 54.78 54.87 54.79 54.77 54.87 54.80 54.87 54.81 54.79 54.84 ]
            det_497= [54.35 54.28 54.33 54.36 54.35 54.37 54.36 54.30 54.35 54.24 54.34 54.36 54.35 54.37 54.35 54.32 54.33 54.26 54.31 54.36 54.37 54.36 54.37 54.31 54.33 54.29 54.31 54.34 54.36 54.37 54.34 54.37 54.36 54.29 54.35 54.35 54.35 54.38 54.38 54.33 54.36 54.30 54.34 54.32 54.35 54.36 54.34 54.35 54.30 54.36 54.37 54.30 54.38 54.34 54.38 54.36 54.28 54.37 54.36 54.31 54.39 54.36 54.39 54.38 ]
            det_498= [54.43 54.43 54.42 54.41 54.41 54.42 54.42 54.46 54.42 54.39 54.49 54.43 54.37 54.47 54.39 54.48 54.44 54.44 54.47 54.48 54.43 54.48 54.43 54.46 54.45 54.43 54.39 54.46 54.48 54.49 54.49 54.41 54.47 54.42 54.40 54.38 54.49 54.47 54.48 54.41 54.45 54.41 54.41 54.39 54.48 54.39 54.46 54.45 54.42 54.42 54.43 54.42 54.43 54.43 54.44 54.50 54.39 54.40 54.49 54.46 54.43 54.50 54.45 54.48 ]
            det_499= [54.43 54.40 54.36 54.46 54.39 54.37 54.41 54.49 54.46 54.44 54.41 54.44 54.39 54.40 54.37 54.42 54.47 54.38 54.41 54.45 54.44 54.38 54.39 54.38 54.46 54.39 54.47 54.46 54.47 54.39 54.40 54.46 54.43 54.38 54.46 54.46 54.46 54.41 54.41 54.46 54.41 54.44 54.39 54.44 54.43 54.41 54.44 54.39 54.45 54.47 54.40 54.41 54.46 54.42 54.42 54.43 54.47 54.45 54.41 54.41 54.47 54.42 54.43 54.45 ]
            det_500= [54.45 54.46 54.40 54.43 54.43 54.38 54.42 54.39 54.44 54.45 54.46 54.42 54.44 54.40 54.43 54.40 54.46 54.44 54.45 54.44 54.46 54.38 54.45 54.42 54.44 54.42 54.41 54.43 54.46 54.39 54.43 54.39 54.43 54.41 54.40 54.43 54.44 54.42 54.40 54.42 54.45 54.43 54.38 54.38 54.40 54.43 54.41 54.42 54.47 54.46 54.41 54.40 54.40 54.37 54.38 54.43 54.45 54.46 54.47 54.43 54.43 54.43 54.40 54.43 ]
            det_501= [54.41 54.41 54.42 54.41 54.46 54.47 54.40 54.48 54.40 54.41 54.43 54.39 54.44 54.49 54.39 54.42 54.40 54.41 54.44 54.41 54.37 54.45 54.45 54.39 54.38 54.41 54.44 54.41 54.39 54.46 54.44 54.42 54.39 54.41 54.44 54.42 54.38 54.46 54.45 54.43 54.43 54.40 54.39 54.42 54.40 54.44 54.44 54.48 54.44 54.40 54.37 54.43 54.41 54.44 54.44 54.48 54.43 54.44 54.40 54.44 54.44 54.50 54.49 54.47 ]
            det_502= [54.22 54.35 54.36 54.32 54.37 54.33 54.35 54.29 54.25 54.34 54.36 54.33 54.35 54.30 54.36 54.27 54.26 54.35 54.37 54.38 54.35 54.27 54.37 54.28 54.22 54.38 54.35 54.37 54.39 54.27 54.37 54.28 54.23 54.36 54.35 54.36 54.32 54.29 54.37 54.29 54.25 54.37 54.32 54.37 54.27 54.27 54.39 54.37 54.25 54.27 54.34 54.33 54.31 54.28 54.35 54.36 54.27 54.28 54.37 54.36 54.35 54.37 54.36 54.33 ]
            det_503= [54.31 54.29 54.36 54.38 54.49 54.43 54.41 54.45 54.30 54.36 54.37 54.37 54.37 54.43 54.42 54.47 54.30 54.32 54.33 54.37 54.37 54.42 54.49 54.44 54.29 54.29 54.36 54.34 54.38 54.45 54.45 54.45 54.37 54.37 54.39 54.35 54.35 54.47 54.38 54.45 54.38 54.36 54.35 54.37 54.35 54.47 54.40 54.39 54.33 54.38 54.37 54.39 54.36 54.48 54.40 54.40 54.32 54.37 54.36 54.39 54.38 54.52 54.46 54.43 ]
            det_504= [54.81 54.80 54.80 54.74 54.82 54.73 54.78 54.81 54.80 54.79 54.79 54.78 54.79 54.78 54.80 54.82 54.80 54.78 54.78 54.83 54.80 54.80 54.77 54.80 54.78 54.83 54.81 54.78 54.83 54.75 54.75 54.79 54.81 54.83 54.79 54.79 54.85 54.77 54.84 54.78 54.81 54.82 54.76 54.77 54.82 54.78 54.84 54.82 54.80 54.83 54.79 54.76 54.84 54.82 54.77 54.78 54.75 54.85 54.85 54.80 54.83 54.86 54.74 54.75 ]
            det_506= [54.46 54.40 54.45 54.41 54.42 54.45 54.46 54.48 54.44 54.41 54.46 54.36 54.41 54.47 54.47 54.48 54.44 54.44 54.47 54.38 54.42 54.44 54.47 54.48 54.37 54.46 54.45 54.45 54.46 54.42 54.43 54.49 54.45 54.47 54.42 54.46 54.46 54.40 54.46 54.47 54.41 54.48 54.36 54.48 54.46 54.39 54.48 54.46 54.37 54.49 54.44 54.47 54.48 54.47 54.48 54.48 54.41 54.50 54.47 54.49 54.47 54.49 54.47 54.49 ]
            det_507= [54.77 54.73 54.71 54.83 54.74 54.71 54.78 54.79 54.83 54.78 54.72 54.81 54.73 54.75 54.81 54.83 54.80 54.72 54.72 54.74 54.78 54.80 54.75 54.75 54.78 54.74 54.82 54.79 54.78 54.74 54.72 54.79 54.82 54.80 54.77 54.80 54.73 54.78 54.76 54.74 54.82 54.76 54.77 54.73 54.77 54.74 54.83 54.73 54.78 54.80 54.81 54.75 54.76 54.79 54.83 54.75 54.77 54.82 54.76 54.79 54.77 54.85 54.82 54.82 ]
            det_508= [54.85 54.86 54.85 54.88 54.92 54.85 54.95 54.90 54.86 54.85 54.87 54.92 54.92 54.85 54.88 54.87 54.84 54.85 54.89 54.95 54.88 54.89 54.86 54.93 54.84 54.87 54.93 54.89 54.88 54.94 54.89 54.93 54.84 54.86 54.96 54.87 54.87 54.89 54.96 54.90 54.88 54.86 54.94 54.88 54.85 54.86 54.96 54.86 54.86 54.87 54.91 54.87 54.85 54.87 54.88 54.89 54.83 54.87 54.91 54.88 54.88 54.89 54.88 54.93 ]
            det_509= [54.84 54.91 54.87 54.85 54.88 54.89 54.91 54.94 54.91 54.94 54.91 54.83 54.85 54.88 54.93 54.91 54.96 54.87 54.94 54.86 54.86 54.86 54.93 54.89 54.88 54.87 54.92 54.89 54.90 54.88 54.88 54.88 54.90 54.86 54.90 54.96 54.93 54.89 54.86 54.88 54.95 54.92 54.85 54.93 54.95 54.91 54.88 54.95 54.89 54.96 54.85 54.87 54.92 54.94 54.89 54.95 54.86 54.87 54.90 54.88 54.88 54.93 54.95 54.87 ]
            det_510= [54.76 54.73 54.74 54.80 54.74 54.75 54.73 54.77 54.81 54.72 54.76 54.83 54.74 54.77 54.77 54.76 54.82 54.72 54.75 54.84 54.81 54.81 54.77 54.77 54.78 54.75 54.77 54.81 54.84 54.77 54.75 54.78 54.72 54.75 54.80 54.80 54.78 54.74 54.78 54.73 54.76 54.77 54.79 54.79 54.77 54.74 54.76 54.76 54.80 54.76 54.78 54.75 54.74 54.79 54.76 54.85 54.78 54.75 54.75 54.75 54.78 54.79 54.83 54.75 ]
            det_1068=[54.14 54.15 54.18 54.19 54.21 54.19 54.17 54.18 54.16 54.21 54.21 54.16 54.21 54.20 54.16 54.18 54.17 54.23 54.25 54.19 54.20 54.18 54.15 54.19 54.18 54.25 54.25 54.24 54.19 54.22 54.17 54.19 54.24 54.24 54.23 54.17 54.18 54.25 54.25 54.20 54.20 54.23 54.25 54.16 54.18 54.24 54.23 54.20 54.18 54.21 54.17 54.21 54.22 54.23 54.20 54.24 54.16 54.18 54.18 54.24 54.26 54.25 54.21 54.26]
            det_1070=[54.18 54.14 54.15 54.15 54.15 54.15 54.25 54.18 54.18 54.16 54.16 54.21 54.18 54.15 54.25 54.20 54.22 54.24 54.17 54.14 54.22 54.17 54.22 54.18 54.24 54.24 54.25 54.15 54.16 54.18 54.18 54.20 54.24 54.22 54.18 54.23 54.15 54.23 54.22 54.20 54.23 54.18 54.21 54.14 54.17 54.21 54.18 54.21 54.20 54.18 54.19 54.23 54.18 54.16 54.23 54.23 54.24 54.22 54.19 54.19 54.19 54.19 54.19 54.25]
            det_1071=[54.22 54.21 54.16 54.17 54.18 54.16 54.14 54.14 54.21 54.23 54.15 54.14 54.18 54.19 54.17 54.15 54.22 54.24 54.24 54.19 54.16 54.19 54.16 54.19 54.23 54.24 54.16 54.22 54.15 54.16 54.21 54.20 54.23 54.19 54.15 54.23 54.18 54.15 54.22 54.18 54.18 54.19 54.20 54.22 54.22 54.16 54.19 54.16 54.23 54.19 54.24 54.22 54.23 54.22 54.18 54.21 54.21 54.25 54.26 54.22 54.24 54.22 54.20 54.19]
            det_1072=[54.14 54.22 54.23 54.14 54.19 54.22 54.23 54.22 54.19 54.22 54.18 54.19 54.21 54.21 54.21 54.24 54.24 54.18 54.13 54.22 54.18 54.17 54.17 54.26 54.15 54.21 54.17 54.21 54.15 54.16 54.17 54.24 54.19 54.23 54.23 54.19 54.13 54.15 54.15 54.24 54.16 54.22 54.19 54.14 54.15 54.16 54.16 54.24 54.14 54.19 54.23 54.16 54.15 54.15 54.21 54.23 54.19 54.25 54.24 54.22 54.17 54.19 54.27 54.25]
            det_1073=[54.18 54.18 54.20 54.20 54.21 54.20 54.17 54.16 54.21 54.20 54.24 54.20 54.18 54.22 54.18 54.18 54.22 54.20 54.25 54.22 54.19 54.17 54.24 54.23 54.25 54.20 54.21 54.24 54.17 54.18 54.22 54.19 54.22 54.22 54.20 54.20 54.17 54.18 54.25 54.14 54.19 54.26 54.18 54.19 54.21 54.23 54.19 54.16 54.18 54.25 54.24 54.25 54.25 54.21 54.16 54.17 54.18 54.21 54.23 54.21 54.21 54.22 54.19 54.21]
            det_1074=[54.22 54.23 54.21 54.18 54.20 54.22 54.15 54.23 54.21 54.21 54.21 54.22 54.20 54.22 54.21 54.25 54.15 54.23 54.20 54.23 54.19 54.22 54.20 54.25 54.19 54.17 54.24 54.23 54.24 54.16 54.19 54.19 54.25 54.23 54.23 54.21 54.23 54.17 54.23 54.16 54.16 54.25 54.24 54.24 54.25 54.18 54.20 54.18 54.16 54.22 54.25 54.23 54.25 54.23 54.20 54.20 54.26 54.25 54.25 54.22 54.20 54.19 54.27 54.17]
            det_1075=[54.05 54.05 54.09 54.09 54.05 54.03 54.03 54.04 54.09 54.05 54.06 54.09 54.06 54.04 54.05 54.06 54.08 54.04 54.04 54.11 54.07 54.08 54.10 54.07 54.08 54.06 54.05 54.10 54.10 54.10 54.13 54.11 54.07 54.11 54.03 54.04 54.12 54.07 54.14 54.11 54.04 54.09 54.03 54.03 54.13 54.08 54.15 54.11 54.07 54.05 54.07 54.05 54.10 54.12 54.16 54.06 54.15 54.07 54.10 54.08 54.15 54.10 54.10 54.08] 
            det_1076=[54.12 54.12 54.12 54.13 54.11 54.09 54.05 54.13 54.07 54.12 54.13 54.14 54.13 54.10 54.08 54.14 54.07 54.13 54.13 54.13 54.13 54.11 54.06 54.14 54.07 54.12 54.10 54.13 54.11 54.09 54.07 54.12 54.07 54.13 54.10 54.14 54.12 54.10 54.13 54.10 54.04 54.13 54.10 54.14 54.13 54.14 54.13 54.14 54.04 54.13 54.14 54.14 54.13 54.12 54.12 54.14 54.09 54.13 54.14 54.14 54.16 54.13 54.11 54.09]
            det_1077=[54.11 54.11 54.11 54.10 54.03 54.10 54.07 54.06 54.13 54.09 54.09 54.09 54.05 54.11 54.10 54.08 54.11 54.11 54.10 54.06 54.06 54.13 54.08 54.06 54.12 54.12 54.10 54.07 54.08 54.11 54.07 54.06 54.09 54.12 54.09 54.09 54.06 54.10 54.06 54.07 54.08 54.12 54.08 54.10 54.10 54.10 54.04 54.08 54.11 54.10 54.10 54.13 54.12 54.10 54.07 54.11 54.13 54.12 54.13 54.14 54.12 54.08 54.13 54.16]
            det_1078=[54.07 54.01 54.12 54.03 54.12 54.07 54.10 54.03 54.10 54.05 54.09 54.04 54.10 54.07 54.13 54.06 54.08 54.04 54.06 54.04 54.09 54.06 54.13 54.12 54.07 54.05 54.08 54.05 54.06 54.09 54.14 54.13 54.08 54.06 54.05 54.05 54.05 54.07 54.14 54.11 54.06 54.06 54.04 54.12 54.05 54.09 54.14 54.08 54.07 54.12 54.08 54.13 54.08 54.07 54.14 54.05 54.12 54.14 54.09 54.15 54.09 54.12 54.15 54.11]
            det_1079=[54.18 54.21 54.21 54.22 54.17 54.23 54.16 54.17 54.24 54.25 54.19 54.18 54.17 54.25 54.16 54.19 54.22 54.24 54.18 54.18 54.21 54.24 54.21 54.20 54.22 54.14 54.17 54.18 54.18 54.20 54.20 54.18 54.25 54.21 54.19 54.21 54.16 54.19 54.20 54.18 54.20 54.19 54.25 54.18 54.18 54.16 54.19 54.18 54.16 54.17 54.21 54.16 54.19 54.17 54.17 54.17 54.20 54.24 54.22 54.19 54.26 54.21 54.19 54.18]
            det_1080=[54.13 54.14 54.20 54.22 54.21 54.16 54.25 54.24 54.18 54.15 54.17 54.22 54.21 54.14 54.26 54.20 54.21 54.24 54.14 54.21 54.24 54.17 54.22 54.20 54.22 54.23 54.19 54.25 54.23 54.15 54.18 54.19 54.24 54.19 54.24 54.20 54.23 54.16 54.16 54.18 54.24 54.23 54.19 54.18 54.23 54.24 54.17 54.23 54.21 54.25 54.20 54.22 54.24 54.24 54.21 54.25 54.23 54.24 54.24 54.26 54.24 54.22 54.23 54.28]
            det_1081=[54.21 54.13 54.19 54.13 54.24 54.19 54.19 54.18 54.23 54.13 54.22 54.18 54.25 54.16 54.24 54.19 54.22 54.23 54.19 54.21 54.21 54.19 54.23 54.16 54.24 54.19 54.18 54.22 54.20 54.20 54.23 54.15 54.24 54.21 54.16 54.24 54.18 54.17 54.22 54.16 54.24 54.25 54.18 54.19 54.18 54.18 54.22 54.17 54.22 54.23 54.17 54.18 54.17 54.19 54.22 54.16 54.18 54.23 54.16 54.20 54.18 54.27 54.26 54.18]
            det_1082=[54.23 54.21 54.17 54.23 54.19 54.22 54.21 54.15 54.22 54.17 54.14 54.16 54.22 54.19 54.21 54.17 54.15 54.14 54.18 54.23 54.25 54.14 54.14 54.24 54.16 54.18 54.23 54.15 54.21 54.16 54.14 54.20 54.16 54.23 54.22 54.23 54.20 54.23 54.16 54.16 54.19 54.25 54.17 54.20 54.25 54.18 54.15 54.20 54.24 54.24 54.19 54.21 54.21 54.18 54.16 54.19 54.25 54.23 54.21 54.23 54.22 54.16 54.19 54.18]
            det_1083=[54.07 54.09 54.07 54.11 54.10 54.02 53.98 54.12 54.04 54.03 54.02 54.12 54.10 54.07 54.03 54.13 54.10 54.05 54.08 54.07 54.08 54.08 54.05 54.14 54.14 54.04 54.14 54.07 54.09 54.10 54.07 54.13 54.13 54.04 54.05 54.13 54.11 54.13 54.10 54.11 54.10 54.11 54.08 54.11 54.08 54.10 54.08 54.13 54.04 54.10 54.09 54.09 54.04 54.12 54.05 54.13 54.06 54.09 54.10 54.15 54.08 54.14 54.07 54.09]
            det_1084=[54.22 54.22 54.20 54.15 54.17 54.14 54.23 54.22 54.22 54.22 54.15 54.21 54.15 54.19 54.23 54.16 54.23 54.20 54.16 54.18 54.13 54.18 54.23 54.17 54.20 54.16 54.14 54.13 54.13 54.14 54.20 54.18 54.17 54.17 54.18 54.15 54.13 54.13 54.21 54.21 54.13 54.18 54.18 54.13 54.21 54.17 54.22 54.24 54.13 54.20 54.22 54.18 54.16 54.16 54.20 54.23 54.16 54.24 54.24 54.24 54.17 54.16 54.22 54.25]
             
            SN002_HV = [mean(det_497(1:end)) mean(det_496(1:end)) mean(det_494(1:end)) mean(det_495(1:end))]
            SN005_HV = [mean(det_1068(1:end)) mean(det_1078(1:end)) mean(det_1083(1:end)) mean(det_1084(1:end))]
            SN007_HV = [1 mean(det_509(1:end)) mean(det_510(1:end)) mean(det_508(1:end))]
            SN008_HV = [mean(det_1074(1:end)) mean(det_1075(1:end)) mean(det_1076(1:end)) mean(det_1077(1:end))]
            SN009_HV = [mean(det_1070(1:end)) mean(det_1072(1:end)) mean(det_1073(1:end)) mean(det_1071(1:end))]
           % disp(SN009_HV)
            if strncmp(quabo, 'SN002',5)
                app.HV1 = SN002_HV(1);
                app.HV2 = SN002_HV(2);
                app.HV3 = SN002_HV(3);
                app.HV4 = SN002_HV(4);
            end
            if strncmp(quabo, 'SN005',5)
                app.HV1 = SN005_HV(1);
                app.HV2 = SN005_HV(2);
                app.HV3 = SN005_HV(3);
                app.HV4 = SN005_HV(4);
            end
                 if strncmp(quabo, 'SN007',5)
                app.HV1 = SN007_HV(1);
                app.HV2 = SN007_HV(2);
                app.HV3 = SN007_HV(3);
                app.HV4 = SN007_HV(4);
                 end
             if strncmp(quabo, 'SN008',5)
                app.HV1 = SN008_HV(1);
                app.HV2 = SN008_HV(2);
                app.HV3 = SN008_HV(3);
                app.HV4 = SN008_HV(4);
            end
            if strncmp(quabo, 'SN009',5)
                app.HV1 = SN009_HV(1);
                app.HV2 = SN009_HV(2);
                app.HV3 = SN009_HV(3);
                app.HV4 = SN009_HV(4);
            end
            app.Q0Label_3.Text=['Q0:' num2str(app.HV1)];
            app.Q1Label_3.Text=['Q1:' num2str(app.HV2)];
            app.Q2Label_3.Text=['Q2:' num2str(app.HV3)];
            app.Q3Label_3.Text=['Q3:' num2str(app.HV4)];
        end

        % Value changed function: SelectIPDropDown
        function SelectIPDropDownValueChanged(app, event)
            %value = app.SelectIPDropDown.Value;
            app.IP=app.SelectIPDropDown.Value
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.HVadjustmentLabel = uilabel(app.UIFigure);
            app.HVadjustmentLabel.Text = 'HV adjustment';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'PANOlogo0.png';
            app.Button.Text = '';
            app.AdjustmentperiodsecLabel = uilabel(app.UIFigure);
            app.AdjustmentperiodsecLabel.Text = 'Adjustment period [sec]:';
            app.AdjustmentperiodsecEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdjustmentperiodsecEditField.Limits = [0.001 Inf];
            app.AdjustmentperiodsecEditField.ValueChangedFcn = createCallbackFcn(app, @AdjustmentperiodsecEditFieldValueChanged, true);
            app.AdjustmentperiodsecEditField.Value = 10;
            app.MaxresidualmVEditFieldLabel = uilabel(app.UIFigure);
            app.MaxresidualmVEditFieldLabel.Text = 'Max residual [mV] :';
            app.MaxresidualmVEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxresidualmVEditField.Limits = [2 Inf];
            app.MaxresidualmVEditField.ValueChangedFcn = createCallbackFcn(app, @MaxresidualmVEditFieldValueChanged, true);
            app.MaxresidualmVEditField.Value = 4;
            app.TemperatureBoardTMP125CLabel = uilabel(app.UIFigure);
            app.TemperatureBoardTMP125CLabel.Text = 'Temperature Board (TMP125) [C]:';
            app.Q0Label_2 = uilabel(app.UIFigure);
            app.Q0Label_2.Text = 'Q0:        ';
            app.Q1Label = uilabel(app.UIFigure);
            app.Q1Label.Text = 'Q1:';
            app.Q2Label = uilabel(app.UIFigure);
            app.Q2Label.Text = 'Q2:';
            app.Q3Label = uilabel(app.UIFigure);
            app.Q3Label.Text = 'Q3:';
            app.Q0Label = uilabel(app.UIFigure);
            app.Q0Label.Text = 'Q0:';
            app.Q1Label_2 = uilabel(app.UIFigure);
            app.Q1Label_2.Text = 'Q1:';
            app.Q2Label_2 = uilabel(app.UIFigure);
            app.Q2Label_2.Text = 'Q2:';
            app.Q3Label_2 = uilabel(app.UIFigure);
            app.Q3Label_2.Text = 'Q3:';
            app.OptimalHVVLabel = uilabel(app.UIFigure);
            app.OptimalHVVLabel.Text = 'Optimal HV [V] :';
            app.ActualHVVLabel = uilabel(app.UIFigure);
            app.ActualHVVLabel.Text = 'Actual HV [V] :';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.QuaboDropDownLabel = uilabel(app.UIFigure);
            app.QuaboDropDownLabel.Text = 'Quabo';
            app.QuaboDropDown = uidropdown(app.UIFigure);
            app.QuaboDropDown.Items = {'Select ID', 'SN002', 'SN005', 'SN007', 'SN008', 'SN009', 'Option 4'};
            app.QuaboDropDown.ValueChangedFcn = createCallbackFcn(app, @QuaboDropDownValueChanged, true);
            app.QuaboDropDown.Value = 'Select ID';
            app.OptimalHVTEMP25CVLabel = uilabel(app.UIFigure);
            app.OptimalHVTEMP25CVLabel.Text = 'Optimal HV TEMP 25C [V] :';
            app.Q0Label_3 = uilabel(app.UIFigure);
            app.Q0Label_3.Text = 'Q0:        ';
            app.Q1Label_3 = uilabel(app.UIFigure);
            app.Q1Label_3.Text = 'Q1:';
            app.Q2Label_3 = uilabel(app.UIFigure);
            app.Q2Label_3.Text = 'Q2:';
            app.Q3Label_3 = uilabel(app.UIFigure);
            app.Q3Label_3.Text = 'Q3:';
            app.SelectIPLabel = uilabel(app.UIFigure);
            app.SelectIPLabel.Text = 'Select IP:';
            app.SelectIPDropDown = uidropdown(app.UIFigure);
            app.SelectIPDropDown.Items = {'192.168.1.10', '192.168.1.11'};
            app.SelectIPDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectIPDropDownValueChanged, true);
            app.SelectIPDropDown.Value = '192.168.1.10';
        end
    end

    methods (Access = public)

        % Construct app
        function app = HVloop14(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end