classdef PANOtv_20190216tests_udponly_pyfits < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadFitsFileMenu               matlab.ui.container.Menu
        ImageMenu                      matlab.ui.container.Menu
        SetGifparamsMenu               matlab.ui.container.Menu
        MakeGifMenu                    matlab.ui.container.Menu
        Button                         matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        PANOtvLabel                    matlab.ui.control.Label
        Switch                         matlab.ui.control.ToggleSwitch
        Lamp                           matlab.ui.control.Lamp
        Lamp_2                         matlab.ui.control.Lamp
        UIAxes                         matlab.ui.control.UIAxes
        DataDirectoryEditFieldLabel    matlab.ui.control.Label
        DataDirectoryEditField         matlab.ui.control.EditField
        DirButton                      matlab.ui.control.Button
        SaveFITSslowCheckBox           matlab.ui.control.CheckBox
        InfoonImagePanel               matlab.ui.container.Panel
        AcqmodeLabel                   matlab.ui.control.Label
        BoardNoLabel                   matlab.ui.control.Label
        PacketNoLabel                  matlab.ui.control.Label
        UTCLabel                       matlab.ui.control.Label
        NanosecLabel                   matlab.ui.control.Label
        TimeLabel                      matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        MinMaxButton                   matlab.ui.control.RadioButton
        FixedButton                    matlab.ui.control.RadioButton
        MinEditFieldLabel              matlab.ui.control.Label
        MinEditField                   matlab.ui.control.NumericEditField
        MaxEditFieldLabel              matlab.ui.control.Label
        MaxEditField                   matlab.ui.control.NumericEditField
        NbFramesperfileEditFieldLabel  matlab.ui.control.Label
        NbFramesperfileEditField       matlab.ui.control.NumericEditField
        SaveTXTfastnodisplayCheckBox   matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        filevideo % Description
        ctvideo
        hfig % Description
        fhand
        fitsnum
        fitscurrentname
        fptr
        fitsmax
        sock
        videoFolder;
        videoname;
        videoAddtimeinvideoname;
        videoNbofimagesEditField;
        videocomments;
        videoappend;
        videotextxpos;
        videotextypos;
        videotextfontsize;
        videotextcolor;
    end
    
    properties (Access = private)
    end
    
    methods (Access = private)
        
        function results = updateconsoleapp(app,newtext)
            h=app.TextArea;
            text=h.Value;
            text(end+1)= {newtext};
                szt=size(text);
            %cut if needed, so to avoid long list
            if szt(1)>3
                text=text(2:end);
               
              
            end
            app.TextArea.Value=text;
            %scroll(app.ListBox, 'bottom')
                %textarea = uitextarea('Value',cellArrayText);
        end
        
        function results = displayimageapp(app,pixels)
            app.UIAxes.Visible='On';
            app.ButtonGroup.Visible='On';
            app.MinEditField.Visible='On';
            app.MinEditFieldLabel.Visible='On';
            app.MaxEditField.Visible='On';
            app.MaxEditFieldLabel.Visible='On';
            
            if strncmp(app.ButtonGroup.SelectedObject.Text,'Min',3)
                CLOW=min(min(pixels));
                CHIGH=max(max(pixels));
            else
                CLOW=app.MinEditField.Value;
                CHIGH=app.MaxEditField.Value;
            end
            %axes(gca)
            scaleunits=1.;
            imagesc(scaleunits*(1:16),scaleunits*(1:16),reshape(pixels,[16,16]),'Parent',app.UIAxes)
            axis(app.UIAxes,'image')
            if CLOW<CHIGH
                app.UIAxes.CLim=[CLOW CHIGH];
            end
            colorbar(app.UIAxes);
            
            %%add video?
            if strncmp(app.MakeGifMenu.Checked,'on',2) && (numel(app.ctvideo)>0) && (app.ctvideo<=app.videoNbofimagesEditField)
                app.hfig=figure
                imagesc(reshape(pixels,[16,16]) )
             
                text(app.videotextxpos,app.videotextypos,app.videocomments,'FontSize',app.videotextfontsize,'FontWeight','bold','Color',app.videotextcolor);
                axis(gca,'image')
                if CLOW<CHIGH
                    set(gca,'CLim',[CLOW CHIGH])
                    %gca.CLim=[CLOW CHIGH];
                end
                colorbar;
                set(gcf,'Color','w')
                frame = getframe(app.hfig);
                imframe = frame2im(frame)
                [imind,cm]= rgb2ind(imframe,256)
                if app.ctvideo==0 
                     if app.videoAddtimeinvideoname
                         tim=datestr(timenow+utcshift,'yyyymmdd_HHMMSS');
                     else tim='';
                     end
                      
                      app.filevideo=[app.videoFolder '\' app.videoname '.gif'];
                      if ~exist(app.filevideo) || (app.videoappend~=1)
                    imwrite(imind,cm,app.filevideo,'gif','Loopcount',inf,'DelayTime',0);
                      end
                elseif  app.ctvideo<=app.videoNbofimagesEditField
                    imwrite(imind,cm,app.filevideo,'gif','WriteMode','append','DelayTime',0);
                    
                end
                if (app.ctvideo>=app.videoNbofimagesEditField)
                    app.MakeGifMenu.Checked='off'
                end
                app.ctvideo=app.ctvideo+1;
                close all
            end


        end
        
        function results = displayinfoapp(app,acq_mode,packet_no,boardloc,utc,nanosec,timenow)
            
            %DISP Info
            
            app.AcqmodeLabel.Text=['Acq. mode: ' num2str(acq_mode)];
            
            app.BoardNoLabel.Text=['Packet No: ' num2str(packet_no)];
            
            app.PacketNoLabel.Text=['Board No: ' num2str(boardloc)];
            
            app.UTCLabel.Text=['UTC: ' num2str(utc)];
            
            app.NanosecLabel.Text=['Nanosec: ' num2str(nanosec)];
            
            if isnumeric(timenow)
               
            app.TimeLabel.Text= datestr(timenow,'HH:MM:SS.FFF') ;
            else
              app.TimeLabel.Text=    datestr(str2num(timenow),'HH:MM:SS');
            end
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            instrreset
            %app.filevideo='C:\DATA\videos\testpanovid.gif';
            app.fitsnum=1;
             if ~exist([getuserdir '\panoseti\'],'dir') 
                mkdir([getuserdir '\panoseti\'])
             end
              if ~exist([getuserdir '\panoseti\panotv\'],'dir') 
                mkdir([getuserdir '\panoseti\panotv\'])
              end
               if ~exist([getuserdir '\panoseti\DATA\'],'dir') 
                mkdir([getuserdir '\panoseti\DATA'])
            end
            app.Lamp.Color='r';
             utcshift=7/24;
            if exist([getuserdir '\panoseti\panotv\saveddatarep.mat'], 'file') == 2
                load([getuserdir '\panoseti\panotv\saveddatarep.mat'])
%                 dataFolder=['C:\DATA\' datestr(now+utcshift,'yyyymmdd') '\'];
%                 if ~exist(dataFolder)
%                     mkdir('C:\DATA\',datestr(now+utcshift,'yyyymmdd'))
%                 end
            else
                if isdeployed && ismac
                    NameOfDeployedApp = 'PANOtv'; % do not include the '.app' extension
                    [~, result] = system(['top -n100 -l1 | grep ' NameOfDeployedApp ' | awk ''{print $1}''']);
                    result=strtrim(result);
                    [status, result] = system(['ps xuwww -p ' result ' | tail -n1 | awk ''{print $NF}''']);
                    if status==0
                        diridx=strfind(result,[NameOfDeployedApp '.app']);
                        dataFolder=result(1:diridx-2);
                    else
                        msgbox({'realpwd not set:',result})
                    end
                else
                    dataFolder = [getuserdir '\panoseti\DATA\'];
                end
                % if isdeployed % Stand-alone mode.
                %     [status, result] = system('path');
                %     currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                % else % MATLAB mode.
                %     currentDir = pwd;
                % end
                %dataFolder = pwd;
            end
            if exist([getuserdir '\panoseti\panotv\panotvconfig.mat'], 'file') == 2
                load([getuserdir '\panoseti\panotv\panotvconfig.mat'])
                app.fitsmax=fitsmax;
            else
                 app.fitsmax=30;
            end
                
            app.DataDirectoryEditField.Value=dataFolder;
            %updateconsoleapp(app,'Welcome in PANOSETI packet viewer!')
            updateconsoleapp(app,'Push the Switch to start receiving packets.');

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            % value = app.Switch.Value;
            %if strncmp(value, 'On',2)
            
            %receive_udp_fits_app;
            import matlab.io.*
            utcshift=7/24;
            value = app.Switch.Value;
            if strncmp(value, 'On',2)
                % h=findobj('Tag','listen');
                %txt= h(1).String;
                %  if strncmp(txt,'Stop',4) %txt updated just before
                app.Lamp.Color='g';
                app.Lamp_2.Color=[1.00 0.96 0.93];
                dolisten=1;
            else
                app.Lamp.Color='r';
                app.Lamp_2.Color=[1.00 0.96 0.93];
                dolisten=0;
            end
            
            port=60001;
           %    u = udp('127.0.0.1',4012);
       %    porthost=49153;
          %  u = udp('192.168.1.10',porthost, 'LocalPort', port,'InputBufferSize',528,'DatagramTerminateMode','on');
         %    u = udp('192.168.1.11',porthost, 'LocalPort', port,'InputBufferSize',528*13,'DatagramTerminateMode','off');
        %  py.store_sci_data_quabo_init_Matlab
       %     fopen(u)
            while  dolisten==1
                
               %   if app.fitsnum==1
                           
              %                end
                %  disp(ct(end))
                %re=fread(u2);
                %disp('Awaiting packets...')
   %             updateconsoleapp(app,['Listening packets on port ' num2str(port)])
   %             drawnow
%             try
%                  %   [packet,SOURCEHOST]  = judp('RECEIVE',port,528, 2000);
%                   ti=      tic;
%                     %packet = fread(u,528,'uint8');
%                      [packet,SOURCEHOST]  = judp('RECEIVE',port,528, 2000);
%                        disp(['udp time:' num2str(toc(ti))])
%                 catch
%                   packet=[];
%      %               updateconsoleapp(app,'No packet received.')
%             end
              if size(app.sock,1)==0
         %           port=60000
                    %judp('SEND',port,'192.168.1.10', packet);
                    aa=py.socket.AF_INET
                    ab=py.socket.SOCK_DGRAM
                    sock = py.socket.socket(aa, ab)
                    
                    sock.settimeout(5)
                    
                    
                    host=py.tuple({'', uint32(60001)})
                    sock.bind(host)
                    app.sock=sock;
                    
                     h=app.DataDirectoryEditField;
                            panodatadir=  h.Value;
                            %       hdr=cell_fitshead_addkey(hdr0,'UTSTARTC' ,	 datestr(timenow+utc,'HH:MM:SS.FFF'), 'UT at UDP reception (computer)');
                            %       hdr=cell_fitshead_addkey(hdr,'ETSEC1' ,	ET_ticks, ' ');
                            %       hdr=cell_fitshead_addkey(hdr,'ETSEC2' ,	ET_ticks, ' ');
                            %       hdr=cell_fitshead_addkey(hdr,'ETSEC3' ,	ET_ticks, ' ');
                            %TODO: test if filename exist, then padd ['_' num2str(i)]
                          
                    %app.fhand = py.open(fullfilename, 'w')
                end
                %%%%%%%%%%%%%%testudp_fpga_new;packet=packet';pause(1)
     %           drawnow
             %   reply = app.sock.recvfrom(unit32(2048))
                timenow=now;
                           % if app.fitsnum==1
                                filename = ['p_d0w0m0q0_' datestr(timenow+utcshift,'yyyymmdd_HHMMSS_FFF') ];
                                  filesep='\';
                                fullfilename=[panodatadir filesep filename];
                             
              
             % tic
               %  [chip0,chip1,chip2,chip3]=
                recorddata=app.SaveFITSslowCheckBox.Value;
                 recorddatafast=app.SaveTXTfastnodisplayCheckBox.Value
                         if recorddatafast==1
                              ti= tic;
                                py.store_sci_data_quabo_packet_MatlabEpyfits3p.makeim(sock,fullfilename,app.fitsmax);
                               disp(['udp py time:' num2str(toc(ti))])
                         end
%                 %char(packet')
%                 
%                 %packet identification
%packet=[];
% ti= tic;
%                  szp=size(packet,2);
% %                 %szp=sz(1);
% %         %       if szp>0
% %   %                  app.Lamp_2.Color='g';
% %    %                 updateconsoleapp(app,['1 Packet of size ' num2str(sz(1)) ' bytes received!'])
% %                     
% %                     %% read data
%                      if szp==257 %528  %SCIENCE
% %                  %    parfor ipa=1:1
% %                         acq_mode=typecast(uint8(packet(1)),'uint8');
% %                         packet_no=typecast(uint8(packet(3:4)),'uint16');
% %                         %ct=[ct packet_no];
% %                         boardloc=typecast(uint8(packet(5:6)),'uint16');
% %                         utc=typecast(uint8(packet(7:10)),'uint32');
% %                         nanosec=typecast(uint8(packet(11:14)),'uint32');
% %                         
% %                         % ET_ticks=typecast(uint8(packet(3:6)),'uint32');
% %                         % ET_seconds1=typecast(uint8(packet(7:10)),'uint32');
% %                         % ET_seconds2=typecast(uint8(packet(11:14)),'uint32');
% %                         % ET_seconds3=typecast(uint8(packet(15:18)),'uint32');
% %                         pixels=typecast(uint8(packet(17:17+2*256-1)),'uint16');
% %                       %  pixels=typecast(uint8(packet(17:end)),'uint16');
% pixels0=packet(1:256); 
% pixelsC=cell(pixels0);
% pixels=zeros(1,256);
% for pp=1:256    
% pixels(pp)=double(pixelsC{pp});
% end
% packet_no=cell(packet(257)); 
%   disp(['udp conv time:' num2str(toc(ti))])
% %disp(double(packet_no{1}))
% 
% %                         %all values should be 12-bit
% %                         % in pulse height mode (trigger mode) MS bit (most significant bit) is 1
% %                         % for the pixel that triggered
% % %                         MSind=find(pixels>2^12);
% % %                         pixels(MSind)=pixels(MSind)-2^15;
% % %                         if numel(MSind)>0
% % %                             %updateconsole(app,'Found triggering pixel.')
% % %                         end
% % %                         %%save data
% %                         
% t1=tic;
%                          timenow=now;
% %                         
% %                         
% %                       %  h=app.SaveFITSslowCheckBox;
% %                        % recorddata=  h.Value;
%                        recorddata=app.SaveFITSslowCheckBox.Value;
%                         if recorddata==1
%             %                 if 1==0
%                             h=app.DataDirectoryEditField;
%                             panodatadir=  h.Value;
%                             %       hdr=cell_fitshead_addkey(hdr0,'UTSTARTC' ,	 datestr(timenow+utc,'HH:MM:SS.FFF'), 'UT at UDP reception (computer)');
%                             %       hdr=cell_fitshead_addkey(hdr,'ETSEC1' ,	ET_ticks, ' ');
%                             %       hdr=cell_fitshead_addkey(hdr,'ETSEC2' ,	ET_ticks, ' ');
%                             %       hdr=cell_fitshead_addkey(hdr,'ETSEC3' ,	ET_ticks, ' ');
%                             %TODO: test if filename exist, then padd ['_' num2str(i)]
%                             
%                             if app.fitsnum==1
%                                 filename = ['p_d0w0m0q0_' datestr(timenow+utcshift,'yyyymmdd_HHMMSS') '_' num2str(double(packet_no{1}))];
%                                   filesep='\';
%                                 fullfilename=[panodatadir filesep filename];
%                                 app.fitscurrentname=fullfilename;
%                                
%                                 %4   hdr=cell_fitshead_addkey(hdr,'SEQID' ,	seqID, 'Name of the sequence');
%                                 %        hdr=cell_fitshead_addkey(hdr,'FILENAME' ,	[filename '.fits'], 'Name of the file');
%                                 %        hdr=cell_fitshead_addkey(hdr,'ABORTED' ,	'F', 'Exposure was stopped before completion');
%                                 
%                                 %[Flag,hdr]=fitswrite_my(pixels,[fullfilename '.fits'],hdr,'int16');
%                                 %  fitswrite_my(pixels',[fullfilename '.fits'],hdr,'int16');
%                                 % fitswrite(pixels',[fullfilename '.fits'],hdr);
%                                 
%                                 app.fptr = fits.createFile([fullfilename '.fits']);
%                                 
%                                 fits.createImg(app.fptr,'int32',[16 16]);
%                                 %fitsmode='overwrite';
%                                 img=int32(reshape(pixels,[16 16]));
%                             fits.writeImg(app.fptr,img)
%                             else
%                              %   app.fptr = fits.openFile([app.fitscurrentname '.fits'],'readwrite');
%                                % fitsmode='append'
%                                  fits.createImg(app.fptr,'int32',[16 16]);
%                                  img=int32(reshape(pixels,[16 16]));
%                             %fits.insertImage(fptr,img,'Writemode',fitsmode)
%                              fits.writeImg(app.fptr,img)
%                             end
%                     disp(['udp fits time:' num2str(toc(ti))])        
% 
%                             %                             h=findobj('Tag','dispacqmode');
%                             %                             h.String=['Acq. mode: ' num2str(acq_mode)];
%                             %                             h=findobj('Tag','disppacket_no');
%                             %                             h.String=['Packet No: ' num2str(packet_no)];
%                             %                             h=findobj('Tag','dispboardloc');
%                             %                             h.String=['Board No: ' num2str(boardloc)];
%                             %                             h=findobj('Tag','disputc');
%                             %                             h.String=['UTC: ' num2str(utc)];
%                             %                             h=findobj('Tag','dispnanosec');
%                             %                             h.String=['Nanosec: ' num2str(nanosec)];
%                             %                             h=findobj('Tag','disptime');
%                             %                             timenow=[char((string(datetime('now'))))];
%                             %                             h.String=timenow;
% %                             fits.writeKey(app.fptr,'DOMENO',0,'Dome No');
% %                             fits.writeKey(app.fptr,'MODULENO',0,'Module No');
% %                             fits.writeKey(app.fptr,'WAVELEN',0,'Waveband 0:vis 1:NIR');
% %                             fits.writeKey(app.fptr,'QUABO',0,'Quad board No');
% %                             fits.writeKey(app.fptr,'ACQMODE',acq_mode,'Acq. mode');
% %                             fits.writeKey(app.fptr,'PACKETNO',typecast([packet_no 0],'int32'),'Packet No');
% %                             fits.writeKey(app.fptr,'BOARDLOC',typecast([boardloc 0],'int32'),'aperture number (8b) concatenated with the quadrant number (2b) padded with 0s.');
% %                             fits.writeKey(app.fptr,'UTC',typecast([utc 0],'int64'),'Universal Time, in seconds, from the White Rabbit infrastructure');
% %                             fits.writeKey(app.fptr,'NANOSEC',typecast([nanosec 0],'int64'),'number of nanoseconds since the last tick of UTC');
% %                             fits.writeKey(app.fptr,'TIMECOMP',timenow,'int32');
% %                             if numel(MSind)>0
% %                                 fits.writeKey(app.fptr,'MSIND',MSind(1),'int16');
% %                             end
%                              end
%                              
%                             
%                              app.fitsnum=app.fitsnum+1;
%                             if app.fitsnum==app.fitsmax+1
% %                                 fits.closeFile(app.fptr);
%                                 app.fitsnum=1;
%                                 t1= toc
%                                 disp(['time per frame (s):' num2str( (1/app.fitsmax)*   t1)])
%                                 disp(['pack/s:' num2str(app.fitsmax/t1)])
% % 
%                              end
% %                              
% %                         end
% %              %     end
% %                         
% %                         %
% %                         % %draw pixels
% %                         % imagesc(reshape(pixels,[8,8]))
% %                         % drawnow
% %          %               displayinfoapp(app,acq_mode,packet_no,boardloc,utc,nanosec,timenow);
% %          %               displayimageapp(app,pixels);
% %          %               app.Lamp_2.Color=[1.00 0.96 0.93];
%                      end%SCIENCE
% %                     
% %             
                    
                    
          %      end %szp>0
                %%be sure none changed the Listen bouton
                drawnow;
                value = app.Switch.Value;
                
                if strncmp(value, 'On',2)
                    % h=findobj('Tag','listen');
                    %txt= h(1).String;
                    %  if strncmp(txt,'Stop',4) %txt updated just before
                     dolisten=1;
                else
                    dolisten=0;
                    app.Lamp.Color='r';
                    updateconsoleapp(app,['Listening packet stopped.'])
                    %fclose(u)
                end
            end
            %%disp time
            % timenow=[char((string(datetime('now'))))];
            %toc
            
            %else
            
       
        end

        % Menu selected function: LoadFitsFileMenu
        function LoadFitsFileMenuSelected(app, event)
             [file,path] = uigetfile('*.fits');
        if numel(file)>1
            %open fits file
            import matlab.io.*
            fptr = fits.openFile([path file]);
            pixels = fits.readImg(fptr);
          
            
            info = fitsinfo([path file])
            testifmsind=any(strcmp(info.PrimaryData.Keywords,'MSIND'));
            if testifmsind(1)==1
            [MSind,comment] = fits.readKey(fptr,'MSIND')
            MSind=str2num(MSind);
            else
                MSind=[];
            end


            %display fits file and update info
            displayimageapp(app,pixels);
            
            [acq_mode,comment] = fits.readKey(fptr,'ACQMODE')
            [packet_no,comment] = fits.readKey(fptr,'PACKETNO')
            [boardloc,comment] = fits.readKey(fptr,'BOARDLOC')
            [utc,comment] = fits.readKey(fptr,'UTC')
            [nanosec,comment] = fits.readKey(fptr,'NANOSEC')
            [timenow,comment] = fits.readKey(fptr,'TIMECOMP')
            
            displayinfoapp(app,acq_mode,packet_no,boardloc,utc,nanosec,timenow);
            updateconsoleapp(app,['New Packet opened and displayed: ' file])
             fits.closeFile(fptr);
        else
             updateconsoleapp(app,['No file selected.'])
        end
        end

        % Button pushed function: DirButton
        function DirButtonPushed(app, event)
                  % h=findobj('Tag','repdata');
                    dataFolder = uigetdir(app.DataDirectoryEditField.Value);
                        if numel(dataFolder)>1
                            app.DataDirectoryEditField.Value=dataFolder;
                            %h(1).String=dataFolder;
                            save([getuserdir '\panoseti\panotv\' 'saveddatarep.mat'],'dataFolder')
         
                                updateconsoleapp(app,'New data repertory selected.')
                         end
  
        end

        % Menu selected function: MakeGifMenu
        function MakeGifMenuSelected(app, event)
             if strncmp(app.MakeGifMenu.Checked,'on',2)
            app.MakeGifMenu.Checked='off';
            
            else
                app.MakeGifMenu.Checked='on';
                %load video params
                load([getuserdir '\panoseti\panotv\' 'savedvideorep.mat'])
                app.videoFolder= videoFolder;
            app.videoname=videoname;
            app.videoAddtimeinvideoname=videoAddtimeinvideoname;
            app.videoNbofimagesEditField=videoNbofimagesEditField;
            app.videocomments=videocomments;
            app.videoappend=videoappend;
            app.videotextxpos=videotextxpos;
            app.videotextypos=videotextypos;
            app.videotextfontsize=videotextfontsize;
            app.videotextcolor=videotextcolor;
            app.ctvideo=0;
               
            end
        end

        % Menu selected function: SetGifparamsMenu
        function SetGifparamsMenuSelected(app, event)
            videoparams
        end

        % Value changed function: SaveFITSslowCheckBox
        function SaveFITSslowCheckBoxValueChanged(app, event)
            value = app.SaveFITSslowCheckBox.Value;
            %make sure to close last multi-fits file
            if value==0 &&  numel(app.fptr)>0
                 import matlab.io.*
                 fits.closeFile(app.fptr);
            end
            if value==0 
            app.fitsnum=1;
            %app.SaveTXTfastnodisplayCheckBox.Value=1;
            else
                app.SaveTXTfastnodisplayCheckBox.Value=0;
            end
                     
        end

        % Value changed function: NbFramesperfileEditField
        function NbFramesperfileEditFieldValueChanged(app, event)
            value = app.NbFramesperfileEditField.Value;
            app.fitsmax=value;
            fitsmax=value;
            save([getuserdir '\panoseti\panotv\' 'panotvconfig.mat'],'fitsmax')
        end

        % Value changed function: SaveTXTfastnodisplayCheckBox
        function SaveTXTfastnodisplayCheckBoxValueChanged(app, event)
            value = app.SaveTXTfastnodisplayCheckBox.Value;
            if value==1
             
                 app.SaveFITSslowCheckBox.Value=0;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadFitsFileMenu = uimenu(app.FileMenu);
            app.LoadFitsFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFitsFileMenuSelected, true);
            app.LoadFitsFileMenu.Text = 'Load Fits File...';
            app.ImageMenu = uimenu(app.UIFigure);
            app.ImageMenu.Text = 'Image';
            app.SetGifparamsMenu = uimenu(app.ImageMenu);
            app.SetGifparamsMenu.MenuSelectedFcn = createCallbackFcn(app, @SetGifparamsMenuSelected, true);
            app.SetGifparamsMenu.Text = 'Set Gif params...';
            app.MakeGifMenu = uimenu(app.ImageMenu);
            app.MakeGifMenu.MenuSelectedFcn = createCallbackFcn(app, @MakeGifMenuSelected, true);
            app.MakeGifMenu.Text = 'Make Gif';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'PANOlogo0.png';
            app.Button.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Welcome in PANOSETI packet viewer!'};
            app.PANOtvLabel = uilabel(app.UIFigure);
            app.PANOtvLabel.Text = 'PANOtv';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.DataDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.DataDirectoryEditFieldLabel.Text = 'Data Directory:';
            app.DataDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.DirButton = uibutton(app.UIFigure, 'push');
            app.DirButton.ButtonPushedFcn = createCallbackFcn(app, @DirButtonPushed, true);
            app.DirButton.Text = 'Dir...';
            app.SaveFITSslowCheckBox = uicheckbox(app.UIFigure);
            app.SaveFITSslowCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveFITSslowCheckBoxValueChanged, true);
            app.SaveFITSslowCheckBox.Text = 'Save FITS (slow)';
            app.SaveFITSslowCheckBox.Value = true;
            app.InfoonImagePanel = uipanel(app.UIFigure);
            app.InfoonImagePanel.Title = 'Info on Image:';
            app.AcqmodeLabel = uilabel(app.InfoonImagePanel);
            app.AcqmodeLabel.Text = 'Acq. mode:';
            app.BoardNoLabel = uilabel(app.InfoonImagePanel);
            app.BoardNoLabel.Text = 'Board No:';
            app.PacketNoLabel = uilabel(app.InfoonImagePanel);
            app.PacketNoLabel.Text = 'Packet No:';
            app.UTCLabel = uilabel(app.InfoonImagePanel);
            app.UTCLabel.Text = 'UTC:';
            app.NanosecLabel = uilabel(app.InfoonImagePanel);
            app.NanosecLabel.Text = 'Nanosec:';
            app.TimeLabel = uilabel(app.InfoonImagePanel);
            app.TimeLabel.Text = 'Time:';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Visible = 'off';
            app.MinMaxButton = uiradiobutton(app.ButtonGroup);
            app.MinMaxButton.Text = 'Min/Max';
            app.MinMaxButton.Value = true;
            app.FixedButton = uiradiobutton(app.ButtonGroup);
            app.FixedButton.Text = 'Fixed:';
            app.MinEditFieldLabel = uilabel(app.ButtonGroup);
            app.MinEditFieldLabel.Visible = 'off';
            app.MinEditFieldLabel.Text = 'Min:';
            app.MinEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.MinEditField.Visible = 'off';
            app.MaxEditFieldLabel = uilabel(app.ButtonGroup);
            app.MaxEditFieldLabel.Visible = 'off';
            app.MaxEditFieldLabel.Text = 'Max:';
            app.MaxEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.MaxEditField.Visible = 'off';
            app.MaxEditField.Value = 1000;
            app.NbFramesperfileEditFieldLabel = uilabel(app.UIFigure);
            app.NbFramesperfileEditFieldLabel.Text = 'Nb Frames per file:';
            app.NbFramesperfileEditField = uieditfield(app.UIFigure, 'numeric');
            app.NbFramesperfileEditField.Limits = [1 Inf];
            app.NbFramesperfileEditField.RoundFractionalValues = 'on';
            app.NbFramesperfileEditField.ValueDisplayFormat = '%11.3g';
            app.NbFramesperfileEditField.ValueChangedFcn = createCallbackFcn(app, @NbFramesperfileEditFieldValueChanged, true);
            app.NbFramesperfileEditField.Value = 1000;
            app.SaveTXTfastnodisplayCheckBox = uicheckbox(app.UIFigure);
            app.SaveTXTfastnodisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveTXTfastnodisplayCheckBoxValueChanged, true);
            app.SaveTXTfastnodisplayCheckBox.Text = 'Save TXT (fast, no display)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PANOtv_20190216tests_udponly_pyfits

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end