classdef PANOtv_20200521 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadFitsFileMenu               matlab.ui.container.Menu
        nosocket                       matlab.ui.container.Menu
        ImageMenu                      matlab.ui.container.Menu
        SetGifparamsMenu               matlab.ui.container.Menu
        MakeGifMenu                    matlab.ui.container.Menu
        NIRMenu                        matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        Button                         matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        PANOtvLabel                    matlab.ui.control.Label
        Switch                         matlab.ui.control.ToggleSwitch
        Lamp                           matlab.ui.control.Lamp
        Lamp_2                         matlab.ui.control.Lamp
        UIAxes                         matlab.ui.control.UIAxes
        DataDirectoryEditFieldLabel    matlab.ui.control.Label
        DataDirectoryEditField         matlab.ui.control.EditField
        DirButton                      matlab.ui.control.Button
        SaveFITSCheckBox               matlab.ui.control.CheckBox
        InfoonImagePanel               matlab.ui.container.Panel
        AcqmodeLabel                   matlab.ui.control.Label
        BoardNoLabel                   matlab.ui.control.Label
        PacketNoLabel                  matlab.ui.control.Label
        UTCLabel                       matlab.ui.control.Label
        NanosecLabel                   matlab.ui.control.Label
        TimeLabel                      matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        MinMaxButton                   matlab.ui.control.RadioButton
        FixedfasterButton              matlab.ui.control.RadioButton
        MinEditFieldLabel              matlab.ui.control.Label
        MinEditField                   matlab.ui.control.NumericEditField
        MaxEditFieldLabel              matlab.ui.control.Label
        MaxEditField                   matlab.ui.control.NumericEditField
        NbFramesperfileEditFieldLabel  matlab.ui.control.Label
        NbFramesperfileEditField       matlab.ui.control.NumericEditField
        SaveTXTfastnodisplayCheckBox   matlab.ui.control.CheckBox
        SaveFITSnodisplayCheckBox      matlab.ui.control.CheckBox
        DisplayMEANonlyCheckBox        matlab.ui.control.CheckBox
        DisplayboardEditFieldLabel     matlab.ui.control.Label
        DisplayboardEditField          matlab.ui.control.NumericEditField
        AllLabel                       matlab.ui.control.Label
        dispmoboCheckBox               matlab.ui.control.CheckBox
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
        J1Label                        matlab.ui.control.Label
        J4Label                        matlab.ui.control.Label
        J3Label                        matlab.ui.control.Label
        J2Label_2                      matlab.ui.control.Label
        cleardispButton                matlab.ui.control.Button
        FilterDropDownLabel            matlab.ui.control.Label
        FilterDropDown                 matlab.ui.control.DropDown
        DisprefreshratefrEditFieldLabel  matlab.ui.control.Label
        DisprefreshratefrEditField     matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        filevideo % Description
        ctvideo
        hfig % Description
        fhand
        fitsnum
        fitscurrentname
        fptr
        fitsmax
        NIRview
        NIRmap
        sock
        videoFolder;
        videoname;
        videoAddtimeinvideoname;
        videoNbofimagesEditField;
        videocomments;
        videoappend;
        videotextxpos;
        videotextypos;
        videotextfontsize;
        videotextcolor;
        u;
        acqmode;
    end
    
    properties (Access = private)
    end
    
    methods (Access = private)
        
        function results = updateconsoleapp(app,newtext)
            h=app.TextArea;
            text=h.Value;
            text(end+1)= {newtext};
            szt=size(text);
            %cut if needed, so to avoid long list
            if szt(1)>3
                text=text(2:end);
                
                
            end
            app.TextArea.Value=text;
            %scroll(app.ListBox, 'bottom')
            %textarea = uitextarea('Value',cellArrayText);
        end
        
        function results = displayimageapp(app,pixels, dispmobo)
            %             if dispmobo==0
            %                 app.UIAxes.Visible='On';
            %                 app.UIAxes.Position=[10 134 403 323];
            %                 app.UIAxes2.Visible='Off';
            %                 app.UIAxes3.Visible='Off';
            %                 app.UIAxes4.Visible='Off';
            %                 app.UIAxes5.Visible='Off';
            %                 app.UIAxes6.Visible='Off';
            %                 app.J1Label.Visible='Off';
            %                 app.J4Label.Visible='Off';
            %                 app.J3Label.Visible='Off';
            %                 app.J2Label_2.Visible='Off';
            %                 app.UIAxes2.Position=[0 0 0 0];
            %                 app.UIAxes3.Position=[0 0 0 0];
            %                 app.UIAxes4.Position=[0 0 0 0];
            %                 app.UIAxes5.Position=[0 0 0 0];
            %                 app.UIAxes6.Position=[0 0 0 0];
            %             else
            %                 app.UIAxes.Visible='Off';
            %                 app.UIAxes.Position=[0 0 0 0];
            %                 app.UIAxes2.Visible='On';
            %                 app.UIAxes3.Visible='On';
            %                 app.UIAxes4.Visible='On';
            %                 app.UIAxes5.Visible='On';
            %                 app.UIAxes6.Visible='Off';
            %                 app.J1Label.Visible='On';
            %                 app.J4Label.Visible='On';
            %                 app.J3Label.Visible='On';
            %                 app.J2Label_2.Visible='On';
            %                 axis(app.UIAxes2,'off')
            %                 axis(app.UIAxes3,'off')
            %                 axis(app.UIAxes4,'off')
            %                 axis(app.UIAxes5,'off')
            %                 axis(app.UIAxes6,'off')
            %                 app.UIAxes2.Position=[70 250 200 200];
            %                 app.UIAxes3.Position=[270 250 200 200];
            %                 app.UIAxes4.Position=[270 50 200 200];
            %                  app.UIAxes5.Position=[70 50 200 200];
            %                  %colorbar:
            %                   app.UIAxes6.Position=[-160 50 240 400];
            %              %   app.UIAxes5.Position=[210 239 255 235];
            %             end
    %        disptic=tic;
            dimima=16;
            shapedima= reshape(pixels,[dimima,dimima]);
            switch dispmobo
                case 0
                    parentUIaxes=app.UIAxes;
                    imadisp=fliplr(shapedima);
                case 1
                    parentUIaxes=app.UIAxes2;
                    imadisp=fliplr(shapedima);
                case 2
                    parentUIaxes=app.UIAxes3;
                    imadisp=flipud(fliplr((shapedima)'));
                case 3
                    parentUIaxes=app.UIAxes4;
                    imadisp=flipud(shapedima);
                case 4
                    parentUIaxes=app.UIAxes5;
                    imadisp=shapedima';
            end
            %             app.ButtonGroup.Visible='On';
            %             app.MinEditField.Visible='On';
            %             app.MinEditFieldLabel.Visible='On';
            %             app.MaxEditField.Visible='On';
            %             app.MaxEditFieldLabel.Visible='On';
            
            %app.NIRview=1;
      %      disp('disp:')
      %      disp(toc(disptic))
            if app.NIRview==1
                NIRmap=app.NIRmap;
                %pixels2=reshape(pixels,[16,16]);
                %pixels=reshape(pixels(NIRmap),[1,25]);
                pixels=reshape(pixels(NIRmap)',[1 25]);
                remap=[5 19 23 22 1 ...
                    10 7 18 21 6 ...
                    14 15 24 11 12 ...
                    20 25 8 2 16 ...
                    9 4 3 13 17];
                pixels=pixels(remap);
                dimima=5;
            end
     %       disp(toc(disptic))
           
            %axes(gca)
       %     disp(toc(disptic))
            %scaleunits=1.;
            % imagesc(scaleunits*(1:dimima),scaleunits*(1:dimima),reshape(pixels,[dimima,dimima])','Parent',parentUIaxes)
            imagesc(parentUIaxes,imadisp,'Parent',parentUIaxes)
%             disp(toc(disptic))
%             %axis(parentUIaxes,'image')

%             disp(toc(disptic))
            
             if strncmp(app.ButtonGroup.SelectedObject.Text,'Min',3)
                CLOW=double(min(min(pixels)));
                CHIGH=double(max(max(pixels)));
                     if CLOW<CHIGH
                        parentUIaxes.CLim=[CLOW CHIGH];
                    end
                  %  disp(toc(disptic))
                    if dispmobo==0
                        colorbar(app.UIAxes);
                    else
                        %    oldsize= get(app.UIAxes5,'Position')
                        % colorbar(app.UIAxes5);
                        hbar= colorbar(app.UIAxes6);
                        %set(hbar,'location','manual','ActivePositionProperty','OuterPosition');
                        % hbarPos=get(hbar,'OuterPosition');
                        if CLOW<CHIGH
                            set(hbar,'ylim',[CLOW CHIGH])
                         caxis(app.UIAxes6,[CLOW CHIGH])
%                           caxis(app.UIAxes2,[CLOW CHIGH])
%                         caxis(app.UIAxes3,[CLOW CHIGH])
%                         caxis(app.UIAxes4,[CLOW CHIGH])
%                         caxis(app.UIAxes5,[CLOW CHIGH])
                        end
                        %     oldsize(2)=oldsize(2)-50;
                        %     oldsize(3)=oldsize(3)+50;
                        %     oldsize(4)=oldsize(4)+100;
                        %     set(app.UIAxes5,'Position',oldsize)
                    end
            else
               % CLOW=app.MinEditField.Value;
               % CHIGH=app.MaxEditField.Value;
             end
              %disp('end disp')
             drawnow
             
            %%add video?
            if strncmp(app.MakeGifMenu.Checked,'on',2) && (numel(app.ctvideo)>0) && (app.ctvideo<=app.videoNbofimagesEditField)
                app.hfig=figure
                imagesc((reshape(pixels,[dimima,dimima]))' )
                
                text(app.videotextxpos,app.videotextypos,app.videocomments,'FontSize',app.videotextfontsize,'FontWeight','bold','Color',app.videotextcolor);
                axis(gca,'image')
                if CLOW<CHIGH
                    set(gca,'CLim',[CLOW CHIGH])
                    %gca.CLim=[CLOW CHIGH];
                end
                colorbar;
                set(gcf,'Color','w')
                frame = getframe(app.hfig);
                imframe = frame2im(frame)
                [imind,cm]= rgb2ind(imframe,256)
                if app.ctvideo==0
                    if app.videoAddtimeinvideoname
                        tim=datestr(timenow+utcshift,'yyyymmdd_HHMMSS');
                    else tim='';
                    end
                    
                    app.filevideo=[app.videoFolder filesep app.videoname '.gif'];
                    if ~exist(app.filevideo) || (app.videoappend~=1)
                        imwrite(imind,cm,app.filevideo,'gif','Loopcount',inf,'DelayTime',0);
                    end
                elseif  app.ctvideo<=app.videoNbofimagesEditField
                    imwrite(imind,cm,app.filevideo,'gif','WriteMode','append','DelayTime',0);
                    
                end
                if (app.ctvideo>=app.videoNbofimagesEditField)
                    app.MakeGifMenu.Checked='off'
                end
                app.ctvideo=app.ctvideo+1;
                close all
            end


        end
        
        function results = displayinfoapp(app,acq_mode,packet_no,boardloc,utc,nanosec,timenow)
            
            %DISP Info
            
            app.AcqmodeLabel.Text=['Acq. mode: ' num2str(acq_mode)];
            
            app.BoardNoLabel.Text=['Packet No: ' num2str(packet_no)];
            
            app.PacketNoLabel.Text=['Board No: ' num2str(boardloc)];
            
            app.UTCLabel.Text=['UTC: ' num2str(utc)];
            
            app.NanosecLabel.Text=['Nanosec: ' num2str(nanosec)];
            
            if isnumeric(timenow)
                
                app.TimeLabel.Text= datestr(timenow,'HH:MM:SS.FFF') ;
            else
                app.TimeLabel.Text=    datestr(str2num(timenow),'HH:MM:SS');
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            instrreset
          %  removeToolbarExplorationButtons(app.UIFigure)
          app.acqmode=0
            app.NIRview=0;
            % NIRmapping;
            %real physical detector 5x5 array with corresponding sma#
            NIRArr=[13 14 16 18 20;11 12 17 19 21;10 9 15 22 23;8 7 4 1 24;6 5 3 2 25];
            %array 25 sma 1..25 to pixelquadrant nomenclature
            %dizaines: A=1 . B=2 c=3 d=4 e=5 f=6 g=7 h=8
            %F7=>G7
            NIRSma=['H3'; 'G3'; 'G2'; 'H2'; 'H1'; 'G1';...
                'E4'; 'F4'; 'F5'; 'E5'; 'E6'; 'G7';...
                'F6'; 'E3'; 'F3'; 'F2'; 'E2'; 'E1'; 'F1';...
                'G6'; 'H6'; 'H5'; 'G5'; 'G4'; 'H4'];
            %map NIRsma into panotv:
            NIRquabo=zeros(size(NIRSma,1),2);
            for ii=1:size(NIRSma,1)
                pixquad=NIRSma(ii,:);
                column=pixquad(1);
                switch column
                    case 'E'
                        colnum=5;
                    case 'F'
                        colnum=6;
                    case 'G'
                        colnum=7;
                    case 'H'
                        colnum=8;
                end
                NIRquabo(ii,:)=[colnum str2num(pixquad(2)) ];
            end
            %correct for pos of quad in quabo
            NIRquabo(:,1)=NIRquabo(:,1);
            NIRquabo(:,2)=NIRquabo(:,2)+8;
            
            NIRarrX=1; %1..5
            NIRarrY=1; %1..5
            dimNIRarr=5;
            NIRpano=zeros(dimNIRarr,dimNIRarr);
            for ii=1:dimNIRarr
                for jj=1:dimNIRarr
                    NIRpano(ii,jj)=sub2ind([16 16],NIRquabo(NIRArr(ii,jj),2),NIRquabo(NIRArr(ii,jj),1));
                    
                end
            end
            %%%
            app.NIRmap=NIRpano;
            
            %app.filevideo='C:\DATA\videos\testpanovid.gif';
            app.fitsnum=1;
            if ~exist([getuserdir filesep 'panoseti' filesep],'dir')
                mkdir([getuserdir filesep 'panoseti' filesep])
            end
            if ~exist([getuserdir filesep 'panoseti' filesep 'panotv' filesep],'dir')
                mkdir([getuserdir filesep 'panoseti' filesep 'panotv' filesep])
            end
            if ~exist([getuserdir filesep 'panoseti' filesep 'DATA' filesep],'dir')
                mkdir([getuserdir filesep 'panoseti' filesep 'DATA'])
            end
            if ~exist([getuserdir filesep 'panoseti' filesep 'pythonlib' filesep],'dir')
                mkdir([getuserdir filesep 'panoseti' filesep 'pythonlib'])
            end
            app.Lamp.Color='r';
            utcshift=7/24;
            if exist([getuserdir filesep 'panoseti' filesep 'panotv' filesep 'saveddatarep.mat'], 'file') == 2
                load([getuserdir filesep 'panoseti' filesep 'panotv' filesep 'saveddatarep.mat'])
                %                 dataFolder=['C:\DATA\' datestr(now+utcshift,'yyyymmdd') '\'];
                %                 if ~exist(dataFolder)
                %                     mkdir('C:\DATA\',datestr(now+utcshift,'yyyymmdd'))
                %                 end
            else
                if isdeployed && ismac
                    NameOfDeployedApp = 'PANOtv'; % do not include the '.app' extension
                    [~, result] = system(['top -n100 -l1 | grep ' NameOfDeployedApp ' | awk ''{print $1}''']);
                    result=strtrim(result);
                    [status, result] = system(['ps xuwww -p ' result ' | tail -n1 | awk ''{print $NF}''']);
                    if status==0
                        diridx=strfind(result,[NameOfDeployedApp '.app']);
                        dataFolder=result(1:diridx-2);
                    else
                        msgbox({'realpwd not set:',result})
                    end
                else
                    dataFolder = [getuserdir '\panoseti\DATA\'];
                end
                % if isdeployed % Stand-alone mode.
                %     [status, result] = system('path');
                %     currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                % else % MATLAB mode.
                %     currentDir = pwd;
                % end
                %dataFolder = pwd;
            end
            if exist([getuserdir  filesep 'panoseti' filesep 'panotv' filesep 'panotvconfig.mat'], 'file') == 2
                load([getuserdir  filesep 'panoseti' filesep 'panotv' filesep 'panotvconfig.mat'])
                app.fitsmax=fitsmax;
                app.NbFramesperfileEditField.Value=fitsmax;
            else
                app.fitsmax=app.NbFramesperfileEditField.Value;
            end
            if count(py.sys.path,[getuserdir  filesep 'panoseti' filesep 'pythonlib' filesep]) == 0
                insert(py.sys.path,int32(0),[getuserdir  filesep 'panoseti' filesep 'pythonlib' filesep]);
                % uialert(app.UIFigure,char(py.sys.path),'py.sys.path2')
            end
            if  ~isfile([getuserdir  filesep 'panoseti' filesep 'pythonlib' filesep 'store_sci_data_quabo_packet_MatlabEpyfits3s.py'])
                
                if ~exist('installdir','var')
                    
                    if ~exist(['C:\Program Files\UC San Diego\PANOtv\application'],'dir')
                        %ask to select folder of installation
                        installdir= uigetdir('C:\','Select panotv installation folder with the panotv.exe file');
                        
                    else
                        installdir='C:\Program Files\UC San Diego\PANOtv\application';
                    end
                    
                end
                sourc=[installdir '\*.py'];
                destina=[getuserdir '\panoseti\pythonlib\' ''];
                
                copyfile(sourc, destina)
            end
            app.DataDirectoryEditField.Value=dataFolder;
            axis(app.UIAxes,'image')
            axis(app.UIAxes2,'image')
            axis(app.UIAxes3,'image')
            axis(app.UIAxes4,'image')
            axis(app.UIAxes5,'image')
            app.UIAxes.Visible='Off';
            app.UIAxes.Position=[10 134 403 323];
            app.UIAxes2.Visible='Off';
            app.UIAxes3.Visible='Off';
            app.UIAxes4.Visible='Off';
            app.UIAxes5.Visible='Off';
            app.UIAxes6.Visible='Off';
            app.J1Label.Visible='Off';
            app.J4Label.Visible='Off';
            app.J3Label.Visible='Off';
            app.J2Label_2.Visible='Off';
            app.UIAxes2.Position=[0 0 0 0];
            app.UIAxes3.Position=[0 0 0 0];
            app.UIAxes4.Position=[0 0 0 0];
            app.UIAxes5.Position=[0 0 0 0];
            app.UIAxes6.Position=[0 0 0 0];
            axtoolbar(app.UIAxes,'Visible','Off')
            axtoolbar(app.UIAxes2,'Visible','Off')
            axtoolbar(app.UIAxes3,'Visible','Off')
            axtoolbar(app.UIAxes4,'Visible','Off')
            axtoolbar(app.UIAxes5,'Visible','Off')
            axtoolbar(app.UIAxes6,'Visible','Off')
            %removeToolbarExplorationButtons(fig)
            %updateconsoleapp(app,'Welcome in PANOSETI packet viewer!')
            updateconsoleapp(app,'Push the Switch to start receiving packets.');
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            % value = app.Switch.Value;
            %if strncmp(value, 'On',2)
            
            %receive_udp_fits_app;
            import matlab.io.*
            utcshift=7/24;
            value = app.Switch.Value;
            if strncmp(value, 'On',2)
                % h=findobj('Tag','listen');
                %txt= h(1).String;
                %  if strncmp(txt,'Stop',4) %txt updated just before
                app.Lamp.Color='g';
                app.Lamp_2.Color=[1.00 0.96 0.93];
                dolisten=1;
            else
                app.Lamp.Color='r';
                app.Lamp_2.Color=[1.00 0.96 0.93];
                dolisten=0;
            end
            
            app.ButtonGroup.Visible='On';
            app.MinEditField.Visible='On';
            app.MinEditFieldLabel.Visible='On';
            app.MaxEditField.Visible='On';
            app.MaxEditFieldLabel.Visible='On';
            
            nbimdisp=0;
            nbimread=0;
            appDisprefreshratefrEditFieldValue=app.DisprefreshratefrEditField.Value;
            
            port=60001;
            porthost=49153;
            h=app.DataDirectoryEditField;
            panodatadir=  h.Value;
            %    u = udp('127.0.0.1',4012);
            %    porthost=49153;
            %  u = udp('192.168.1.10',porthost, 'LocalPort', port,'InputBufferSize',528,'DatagramTerminateMode','on');
            %     u = udp('192.168.1.11',porthost, 'LocalPort', port,'InputBufferSize',528*8,'DatagramTerminateMode','off');
            %  py.store_sci_data_quabo_init_Matlab
            %     fopen(u)
            while  dolisten==1
               % tStart=tic;
                %   if app.fitsnum==1
                
                %                end
                %  disp(ct(end))
                %re=fread(u2);
                %disp('Awaiting packets...')
                %             updateconsoleapp(app,['Listening packets on port ' num2str(port)])
                %             drawnow
                %             try
                %                  %   [packet,SOURCEHOST]  = judp('RECEIVE',port,528, 2000);
                %                   ti=      tic;
                %                     %packet = fread(u,528,'uint8');
                %                      [packet,SOURCEHOST]  = judp('RECEIVE',port,528, 2000);
                %                        disp(['udp time:' num2str(toc(ti))])
                %                 catch
                %                   packet=[];
                %      %               updateconsoleapp(app,'No packet received.')
                %             end
                nosocket=uint8(strncmp(app.nosocket.Checked,'on',2));
                recorddata=app.SaveFITSCheckBox.Value;
                recorddatafast=app.SaveTXTfastnodisplayCheckBox.Value;
                recorddataudpnodisp=  app.SaveFITSnodisplayCheckBox.Value;
                displaymean=app.DisplayMEANonlyCheckBox.Value;
                if (recorddatafast==1) || (displaymean ==1)
                    if exist('u','var')
                        fclose(u)
                    end
                    if size(app.sock,1)==0
                        %           port=60000
                        %judp('SEND',port,'192.168.1.10', packet);
                        aa=py.socket.AF_INET;
                        ab=py.socket.SOCK_DGRAM;
                        sock = py.socket.socket(aa, ab);
                        
                        sock.settimeout(5)
                        
                        
                        host=py.tuple({'', uint32(60001)});
                        sock.bind(host);
                        app.sock=sock;
                        
                        
                        %       hdr=cell_fitshead_addkey(hdr0,'UTSTARTC' ,	 datestr(timenow+utc,'HH:MM:SS.FFF'), 'UT at UDP reception (computer)');
                        %       hdr=cell_fitshead_addkey(hdr,'ETSEC1' ,	ET_ticks, ' ');
                        %       hdr=cell_fitshead_addkey(hdr,'ETSEC2' ,	ET_ticks, ' ');
                        %       hdr=cell_fitshead_addkey(hdr,'ETSEC3' ,	ET_ticks, ' ');
                        %TODO: test if filename exist, then padd ['_' num2str(i)]
                        
                        %app.fhand = py.open(fullfilename, 'w')
                    end
                    %%%%%%%%%%%%%%testudp_fpga_new;packet=packet';pause(1)
                    %           drawnow
                    %   reply = app.sock.recvfrom(unit32(2048))
                    timenow=now;
                    % if app.fitsnum==1
                    filename = ['p_d0w0m0q0_' datestr(timenow+utcshift,'yyyymmdd_HHMMSS_FFF') ];
                    %filesep='\';
                    fullfilename=[panodatadir filesep filename];
                    
                    
                    % tic
                    %  [chip0,chip1,chip2,chip3]=
                    
                    ti= tic;
                    try
                        if displaymean==0
                            py.store_sci_data_quabo_packet_MatlabEpyfits3sc.makeim(sock,fullfilename,app.fitsmax);
                            disp(['udp py time:' num2str(toc(ti))])
                        else
                            im=py.store_sci_data_quabo_packet_MatlabEpyfits3s.makemean(sock,fullfilename,app.fitsmax);
                            im2=double(py.array.array('d',py.numpy.nditer(im)));
                            displayimageapp(app,im2);
                            
                        end
                    catch
                        packet=[];
                        updateconsoleapp(app,'No packet received.')
                    end
                    
                else
                    
                    %if  recorddataudpnodisp==1 && (~exist('u','var') || strncmp(u.Status,'clo',3))
                   % if   (~exist('u','var') || strncmp(u.Status,'clo',3))
                   if  (recorddataudpnodisp==1 || nosocket==0) && (~exist('u','var') || strncmp(u.Status,'clo',3))
                        u = udp('192.168.0.4',porthost, 'LocalPort', port,'InputBufferSize',8*528,'DatagramTerminateMode','on');
                        %u.ReadAsyncMode = 'manual';
                        u.EnablePortSharing = 'on';
                        fopen(u)
                        app.u=u;
                    end
                    try
                        %  [packet,SOURCEHOST]  = judp('RECEIVE',port,2048, 2000);
                          if (mod(nbimread, appDisprefreshratefrEditFieldValue)==0) 
                        if   (recorddataudpnodisp==1 || nosocket==0) 
                           %readasync(u,528)
                           [A,count,msg,datagramaddress,datagramport] = fread(u,1024);
                           packet = uint8(A);
                           % packet = uint8(fread(u,528));
                          
                        else
                            % packet = uint8(fread(u,528));
                            [packet,SOURCEHOST]  = judp('RECEIVE',port,2048, 2000);
                          end
                    end
                        nbimread=nbimread+1;
                    catch
                        packet=[];
                        updateconsoleapp(app,'No packet received.')
                    end
                    % acq_mode=typecast(uint8(packet(1)),'uint8')
                    
                    
              %      disp('elapsed time')
             %       tElapsed=toc(tStart);
              %      disp(tElapsed)
                   % tElapsed=toc(ti);
                   % disp(tElapsed)
                    %%%%%%%%%%%%%%testudp_fpga_new;packet=packet';pause(1)
                    %if  recorddataudpnodisp~=1
  %                  drawnow
                    % end
                                    timenow=now;            
                    %char(packet')
                    
                    %packet identification
                    sz=size(packet);
                    szp=sz(1);
                    if szp>0
                        app.Lamp_2.Color='g';
                        updateconsoleapp(app,['1 Packet of size ' num2str(sz(1)) ' bytes received!'])
                        
                        %% read data
                        if (szp==272) || (szp==528) %SCIENCE
                            
                            acq_mode=typecast(uint8(packet(1)),'uint8');
                            packet_no=typecast((packet(3:4)),'uint16');
                            %ct=[ct packet_no];
                            boardloc=typecast((packet(5:6)),'uint16');
                            utc=typecast(uint8(packet(7:10)),'uint32');
                            nanosec=typecast(uint8(packet(11:14)),'uint32');
                            if acq_mode==1
                                disp('')
                            end
                           
                            % ET_ticks=typecast(uint8(packet(3:6)),'uint32');
                            % ET_seconds1=typecast(uint8(packet(7:10)),'uint32');
                            % ET_seconds2=typecast(uint8(packet(11:14)),'uint32');
                            % ET_seconds3=typecast(uint8(packet(15:18)),'uint32');
                            if  (szp==528)
                               pixels=typecast((packet(17:17+2*256-1)),'uint16');
                            else
                               pixels=typecast((packet(17:17+256-1)),'uint8');
                            end
                            %all values should be 12-bit
                            % in pulse height mode (trigger mode) MS bit (most significant bit) is 1
                            % for the pixel that triggered
%                             MSind=find(pixels>=2^16);
%                             pixels(MSind)=pixels(MSind)-2^16;
%                             if numel(MSind)>0
%                                 %updateconsole(app,'Found triggering pixel.')
%                             end
                            %%save data
                           nbimdisp=nbimdisp+1; 

               if  (app.acqmode==0) || ((acq_mode==app.acqmode ) && (acq_mode==1 )) || ((app.acqmode==3 ) && (acq_mode>1 ))         
                            
                            h=app.SaveFITSCheckBox;
                            recorddata=  h.Value;
                            if (recorddata==1) || (recorddataudpnodisp==1)
                                h=app.DataDirectoryEditField;
                                panodatadir=  h.Value;
                                %       hdr=cell_fitshead_addkey(hdr0,'UTSTARTC' ,	 datestr(timenow+utc,'HH:MM:SS.FFF'), 'UT at UDP reception (computer)');
                                %       hdr=cell_fitshead_addkey(hdr,'ETSEC1' ,	ET_ticks, ' ');
                                %       hdr=cell_fitshead_addkey(hdr,'ETSEC2' ,	ET_ticks, ' ');
                                %       hdr=cell_fitshead_addkey(hdr,'ETSEC3' ,	ET_ticks, ' ');
                                %TODO: test if filename exist, then padd ['_' num2str(i)]
                                
                                if app.fitsnum==1
                                    filename = ['p_d0w0m0q0_' datestr(timenow+utcshift,'yyyymmdd_HHMMSS') '_' num2str(packet_no)];
                                    %filesep='\';
                                    fullfilename=[panodatadir filesep filename];
                                    app.fitscurrentname=fullfilename;
                                    
                                    %4   hdr=cell_fitshead_addkey(hdr,'SEQID' ,	seqID, 'Name of the sequence');
                                    %        hdr=cell_fitshead_addkey(hdr,'FILENAME' ,	[filename '.fits'], 'Name of the file');
                                    %        hdr=cell_fitshead_addkey(hdr,'ABORTED' ,	'F', 'Exposure was stopped before completion');
                                    
                                    %[Flag,hdr]=fitswrite_my(pixels,[fullfilename '.fits'],hdr,'int16');
                                    %  fitswrite_my(pixels',[fullfilename '.fits'],hdr,'int16');
                                    % fitswrite(pixels',[fullfilename '.fits'],hdr);
                                    
                                    app.fptr = fits.createFile([fullfilename '.fits']);
                                    
                                    fits.createImg(app.fptr,'int16',[16 16]);
                                    %fitsmode='overwrite';
                                    img=int16(reshape(pixels,[16 16]));
                                    fits.writeImg(app.fptr,img)
                                else
                                    %   app.fptr = fits.openFile([app.fitscurrentname '.fits'],'readwrite');
                                    % fitsmode='append'
                                    fits.createImg(app.fptr,'int16',[16 16]);
                                    img=int16(reshape(pixels,[16 16]));
                                    %fits.insertImage(fptr,img,'Writemode',fitsmode)
                                    fits.writeImg(app.fptr,img)
                                end
                                
                                
                                %                             h=findobj('Tag','dispacqmode');
                                %                             h.String=['Acq. mode: ' num2str(acq_mode)];
                                %                             h=findobj('Tag','disppacket_no');
                                %                             h.String=['Packet No: ' num2str(packet_no)];
                                %                             h=findobj('Tag','dispboardloc');
                                %                             h.String=['Board No: ' num2str(boardloc)];
                                %                             h=findobj('Tag','disputc');
                                %                             h.String=['UTC: ' num2str(utc)];
                                %                             h=findobj('Tag','dispnanosec');
                                %                             h.String=['Nanosec: ' num2str(nanosec)];
                                %                             h=findobj('Tag','disptime');
                                %                             timenow=[char((string(datetime('now'))))];
                                %                             h.String=timenow;
                                fits.writeKey(app.fptr,'DOMENO',0,'Dom No');
                                fits.writeKey(app.fptr,'MODULENO',0,'Module No');
                                fits.writeKey(app.fptr,'WAVELEN',0,'Waveband 0:vis 1:NIR');
                                fits.writeKey(app.fptr,'QUABO',0,'Quad board No');
                                fits.writeKey(app.fptr,'ACQMODE',acq_mode,'Acq. mode');
                                fits.writeKey(app.fptr,'PACKETNO',typecast([packet_no 0],'int32'),'Packet No');
                                fits.writeKey(app.fptr,'BOARDLOC',typecast([boardloc 0],'int32'),'aperture number (8b) concatenated with the quadrant number (2b) padded with 0s.');
                                fits.writeKey(app.fptr,'UTC',typecast([utc 0],'int64'),'Universal Time, in seconds, from the White Rabbit infrastructure');
                                fits.writeKey(app.fptr,'NANOSEC',typecast([nanosec 0],'int64'),'number of nanoseconds since the last tick of UTC');
                                fits.writeKey(app.fptr,'TIMECOMP',timenow,'int32');
%                                 if numel(MSind)>0
%                                     fits.writeKey(app.fptr,'MSIND',MSind(1),'int16');
%                                 end
                                app.fitsnum=app.fitsnum+1;
                                if app.fitsnum==app.fitsmax
                                    fits.closeFile(app.fptr);
                                    app.fitsnum=1;
                                end
                            end
                            
                            %
                            % %draw pixels
                            % imagesc(reshape(pixels,[8,8]))
                            % drawnow
                            % add baordloc if not in field
                            %                             boardloc
                            %                             boards=app.DisplayBoardDropDown.Items
                            %                             if size(boards,2)>1
                            %                             boardsnums=uint16(str2num(cell2mat(boards(2:end))))
                            %                             else
                            %                                 boardsnums=-1
                            %                             end
                            %                                 %if not in the list, add it
                            %                             if sum(boardsnums==boardloc)<1
                            %                                 app.DisplayBoardDropDown.Items=[app.DisplayBoardDropDown.Items mat2cell(num2str(boardloc),1)]
                            %                             end
                            
                            if (mod(nbimdisp, appDisprefreshratefrEditFieldValue)==0)
                            boardchoice=app.DisplayboardEditField.Value;
                            mobodisp=app.dispmoboCheckBox.Value;
                            boardpossible=boardchoice;
                            if mobodisp==1
                                boardj1=boardchoice-mod(boardchoice,4);
                                boardpossible=boardj1+(0:3);
                                mobodisp=1+mod(boardloc,4);
                            end
                            %if (boardchoice==-1) || (boardloc==boardchoice)
                            if (boardchoice==-1) || logical(sum(boardloc==boardpossible))
                                
                                displayinfoapp(app,acq_mode,packet_no,boardloc,utc,nanosec,timenow);
                                %if  ~exist('u','var') || strncmp(u.Status,'clo',3)
                                %if  ~exist('u','var') || strncmp(u.Status,'clo',3)
                                  %  tElapsed=toc(tStart);
                                  %  disp(tElapsed)
                                  if recorddataudpnodisp==false && recorddatafast==false
                                    displayimageapp(app,pixels,mobodisp);
                                  end
                                 %   tElapsed=toc(tStart);
                                 %   disp(tElapsed)
                                %end
                            end
                            app.Lamp_2.Color=[1.00 0.96 0.93];
                            end
                            
               end %acqmode
                        end%SCIENCE


                    end %szp>0


                end
                %                 %char(packet')
                %
                %                 %packet identification
                %packet=[];
                % ti= tic;
                %                  szp=size(packet,2);
                % %                 %szp=sz(1);
                % %         %       if szp>0
                % %   %                  app.Lamp_2.Color='g';
                % %    %                 updateconsoleapp(app,['1 Packet of size ' num2str(sz(1)) ' bytes received!'])
                % %
                % %                     %% read data
                %                      if szp==257 %528  %SCIENCE
                % %                  %    parfor ipa=1:1
                % %                         acq_mode=typecast(uint8(packet(1)),'uint8');
                % %                         packet_no=typecast(uint8(packet(3:4)),'uint16');
                % %                         %ct=[ct packet_no];
                % %                         boardloc=typecast(uint8(packet(5:6)),'uint16');
                % %                         utc=typecast(uint8(packet(7:10)),'uint32');
                % %                         nanosec=typecast(uint8(packet(11:14)),'uint32');
                % %
                % %                         % ET_ticks=typecast(uint8(packet(3:6)),'uint32');
                % %                         % ET_seconds1=typecast(uint8(packet(7:10)),'uint32');
                % %                         % ET_seconds2=typecast(uint8(packet(11:14)),'uint32');
                % %                         % ET_seconds3=typecast(uint8(packet(15:18)),'uint32');
                % %                         pixels=typecast(uint8(packet(17:17+2*256-1)),'uint16');
                % %                       %  pixels=typecast(uint8(packet(17:end)),'uint16');
                % pixels0=packet(1:256);
                % pixelsC=cell(pixels0);
                % pixels=zeros(1,256);
                % for pp=1:256
                % pixels(pp)=double(pixelsC{pp});
                % end
                % packet_no=cell(packet(257));
                %   disp(['udp conv time:' num2str(toc(ti))])
                % %disp(double(packet_no{1}))
                %
                % %                         %all values should be 12-bit
                % %                         % in pulse height mode (trigger mode) MS bit (most significant bit) is 1
                % %                         % for the pixel that triggered
                % % %                         MSind=find(pixels>2^12);
                % % %                         pixels(MSind)=pixels(MSind)-2^15;
                % % %                         if numel(MSind)>0
                % % %                             %updateconsole(app,'Found triggering pixel.')
                % % %                         end
                % % %                         %%save data
                % %
                % t1=tic;
                %                          timenow=now;
                % %
                % %
                % %                       %  h=app.SaveFITSCheckBox;
                % %                        % recorddata=  h.Value;
                %                        recorddata=app.SaveFITSCheckBox.Value;
                %                         if recorddata==1
                %             %                 if 1==0
                %                             h=app.DataDirectoryEditField;
                %                             panodatadir=  h.Value;
                %                             %       hdr=cell_fitshead_addkey(hdr0,'UTSTARTC' ,	 datestr(timenow+utc,'HH:MM:SS.FFF'), 'UT at UDP reception (computer)');
                %                             %       hdr=cell_fitshead_addkey(hdr,'ETSEC1' ,	ET_ticks, ' ');
                %                             %       hdr=cell_fitshead_addkey(hdr,'ETSEC2' ,	ET_ticks, ' ');
                %                             %       hdr=cell_fitshead_addkey(hdr,'ETSEC3' ,	ET_ticks, ' ');
                %                             %TODO: test if filename exist, then padd ['_' num2str(i)]
                %
                %                             if app.fitsnum==1
                %                                 filename = ['p_d0w0m0q0_' datestr(timenow+utcshift,'yyyymmdd_HHMMSS') '_' num2str(double(packet_no{1}))];
                %                                   filesep='\';
                %                                 fullfilename=[panodatadir filesep filename];
                %                                 app.fitscurrentname=fullfilename;
                %
                %                                 %4   hdr=cell_fitshead_addkey(hdr,'SEQID' ,	seqID, 'Name of the sequence');
                %                                 %        hdr=cell_fitshead_addkey(hdr,'FILENAME' ,	[filename '.fits'], 'Name of the file');
                %                                 %        hdr=cell_fitshead_addkey(hdr,'ABORTED' ,	'F', 'Exposure was stopped before completion');
                %
                %                                 %[Flag,hdr]=fitswrite_my(pixels,[fullfilename '.fits'],hdr,'int16');
                %                                 %  fitswrite_my(pixels',[fullfilename '.fits'],hdr,'int16');
                %                                 % fitswrite(pixels',[fullfilename '.fits'],hdr);
                %
                %                                 app.fptr = fits.createFile([fullfilename '.fits']);
                %
                %                                 fits.createImg(app.fptr,'int32',[16 16]);
                %                                 %fitsmode='overwrite';
                %                                 img=int32(reshape(pixels,[16 16]));
                %                             fits.writeImg(app.fptr,img)
                %                             else
                %                              %   app.fptr = fits.openFile([app.fitscurrentname '.fits'],'readwrite');
                %                                % fitsmode='append'
                %                                  fits.createImg(app.fptr,'int32',[16 16]);
                %                                  img=int32(reshape(pixels,[16 16]));
                %                             %fits.insertImage(fptr,img,'Writemode',fitsmode)
                %                              fits.writeImg(app.fptr,img)
                %                             end
                %                     disp(['udp fits time:' num2str(toc(ti))])
                %
                %                             %                             h=findobj('Tag','dispacqmode');
                %                             %                             h.String=['Acq. mode: ' num2str(acq_mode)];
                %                             %                             h=findobj('Tag','disppacket_no');
                %                             %                             h.String=['Packet No: ' num2str(packet_no)];
                %                             %                             h=findobj('Tag','dispboardloc');
                %                             %                             h.String=['Board No: ' num2str(boardloc)];
                %                             %                             h=findobj('Tag','disputc');
                %                             %                             h.String=['UTC: ' num2str(utc)];
                %                             %                             h=findobj('Tag','dispnanosec');
                %                             %                             h.String=['Nanosec: ' num2str(nanosec)];
                %                             %                             h=findobj('Tag','disptime');
                %                             %                             timenow=[char((string(datetime('now'))))];
                %                             %                             h.String=timenow;
                % %                             fits.writeKey(app.fptr,'DOMENO',0,'Dome No');
                % %                             fits.writeKey(app.fptr,'MODULENO',0,'Module No');
                % %                             fits.writeKey(app.fptr,'WAVELEN',0,'Waveband 0:vis 1:NIR');
                % %                             fits.writeKey(app.fptr,'QUABO',0,'Quad board No');
                % %                             fits.writeKey(app.fptr,'ACQMODE',acq_mode,'Acq. mode');
                % %                             fits.writeKey(app.fptr,'PACKETNO',typecast([packet_no 0],'int32'),'Packet No');
                % %                             fits.writeKey(app.fptr,'BOARDLOC',typecast([boardloc 0],'int32'),'aperture number (8b) concatenated with the quadrant number (2b) padded with 0s.');
                % %                             fits.writeKey(app.fptr,'UTC',typecast([utc 0],'int64'),'Universal Time, in seconds, from the White Rabbit infrastructure');
                % %                             fits.writeKey(app.fptr,'NANOSEC',typecast([nanosec 0],'int64'),'number of nanoseconds since the last tick of UTC');
                % %                             fits.writeKey(app.fptr,'TIMECOMP',timenow,'int32');
                % %                             if numel(MSind)>0
                % %                                 fits.writeKey(app.fptr,'MSIND',MSind(1),'int16');
                % %                             end
                %                              end
                %
                %
                %                              app.fitsnum=app.fitsnum+1;
                %                             if app.fitsnum==app.fitsmax+1
                % %                                 fits.closeFile(app.fptr);
                %                                 app.fitsnum=1;
                %                                 t1= toc
                %                                 disp(['time per frame (s):' num2str( (1/app.fitsmax)*   t1)])
                %                                 disp(['pack/s:' num2str(app.fitsmax/t1)])
                % %
                %                              end
                % %
                % %                         end
                % %              %     end
                % %
                % %                         %
                % %                         % %draw pixels
                % %                         % imagesc(reshape(pixels,[8,8]))
                % %                         % drawnow
                % %          %               displayinfoapp(app,acq_mode,packet_no,boardloc,utc,nanosec,timenow);
                % %          %               displayimageapp(app,pixels);
                % %          %               app.Lamp_2.Color=[1.00 0.96 0.93];
                %                      end%SCIENCE
                % %
                % %
                
                
                %      end %szp>0
                %%be sure none changed the Listen bouton
                drawnow;
                value = app.Switch.Value;
                
                if strncmp(value, 'On',2)
                    % h=findobj('Tag','listen');
                    %txt= h(1).String;
                    %  if strncmp(txt,'Stop',4) %txt updated just before
                    dolisten=1;
                else
                    dolisten=0;
                    app.Lamp.Color='r';
                    updateconsoleapp(app,['Listening packet stopped.'])
                    if exist('u','var')
                        fclose(u)
                    end
                end
            end
            %%disp time
            % timenow=[char((string(datetime('now'))))];
            %toc
            
            %else
            
            
        end

        % Menu selected function: LoadFitsFileMenu
        function LoadFitsFileMenuSelected(app, event)
            [file,path] = uigetfile('*.fits');
            if numel(file)>1
                %open fits file
                import matlab.io.*
                fptr = fits.openFile([path file]);
                pixels = fits.readImg(fptr);
                
                
                info = fitsinfo([path file])
                testifmsind=any(strcmp(info.PrimaryData.Keywords,'MSIND'));
                if testifmsind(1)==1
                    [MSind,comment] = fits.readKey(fptr,'MSIND')
                    MSind=str2num(MSind);
                else
                    MSind=[];
                end


                %display fits file and update info
                displayimageapp(app,pixels);
                
                [acq_mode,comment] = fits.readKey(fptr,'ACQMODE')
                [packet_no,comment] = fits.readKey(fptr,'PACKETNO')
                [boardloc,comment] = fits.readKey(fptr,'BOARDLOC')
                [utc,comment] = fits.readKey(fptr,'UTC')
                [nanosec,comment] = fits.readKey(fptr,'NANOSEC')
                [timenow,comment] = fits.readKey(fptr,'TIMECOMP')
                
                displayinfoapp(app,acq_mode,packet_no,boardloc,utc,nanosec,timenow);
                updateconsoleapp(app,['New Packet opened and displayed: ' file])
                fits.closeFile(fptr);
            else
                updateconsoleapp(app,['No file selected.'])
            end
        end

        % Button pushed function: DirButton
        function DirButtonPushed(app, event)
            % h=findobj('Tag','repdata');
            dataFolder = uigetdir(app.DataDirectoryEditField.Value);
            if numel(dataFolder)>1
                app.DataDirectoryEditField.Value=dataFolder;
                %h(1).String=dataFolder;
                save([getuserdir filesep 'panoseti' filesep 'panotv' filesep 'saveddatarep.mat'],'dataFolder')
                
                updateconsoleapp(app,'New data repertory selected.')
            end
            
        end

        % Menu selected function: MakeGifMenu
        function MakeGifMenuSelected(app, event)
            if strncmp(app.MakeGifMenu.Checked,'on',2)
                app.MakeGifMenu.Checked='off';
                
            else
                app.MakeGifMenu.Checked='on';
                %load video params
                load([getuserdir filesep 'panoseti' filesep 'panotv' filesep  'savedvideorep.mat'])
                app.videoFolder= videoFolder;
                app.videoname=videoname;
                app.videoAddtimeinvideoname=videoAddtimeinvideoname;
                app.videoNbofimagesEditField=videoNbofimagesEditField;
                app.videocomments=videocomments;
                app.videoappend=videoappend;
                app.videotextxpos=videotextxpos;
                app.videotextypos=videotextypos;
                app.videotextfontsize=videotextfontsize;
                app.videotextcolor=videotextcolor;
                app.ctvideo=0;
                
            end
        end

        % Menu selected function: SetGifparamsMenu
        function SetGifparamsMenuSelected(app, event)
            videoparams
        end

        % Value changed function: SaveFITSCheckBox
        function SaveFITSCheckBoxValueChanged(app, event)
            value = app.SaveFITSCheckBox.Value;
            %make sure to close last multi-fits file
            app.UIAxes.Visible='on'
            if value==0 %&&  numel(app.fptr)>0
                import matlab.io.*
                try
                    fits.closeFile(app.fptr);
                catch
                end
                app.fptr=[];
                if size(app.u,1)~=0
                    fclose(app.u)
                end
            end
            if value==0
                app.fitsnum=1;
                
                %app.SaveTXTfastnodisplayCheckBox.Value=1;
            else
                app.SaveTXTfastnodisplayCheckBox.Value=0;
                app.SaveFITSnodisplayCheckBox.Value=0;
                app.DisplayMEANonlyCheckBox.Value=0;
                if size(app.sock,1)~=0
                    app.sock.close()
                    app.sock=[];
                end
                if size(app.u,1)~=0
                    fclose(app.u)
                end
            end
            
        end

        % Value changed function: NbFramesperfileEditField
        function NbFramesperfileEditFieldValueChanged(app, event)
            value = app.NbFramesperfileEditField.Value;
            app.fitsmax=value;
            fitsmax=value;
            save([getuserdir  filesep 'panoseti' filesep 'panotv' filesep  'panotvconfig.mat'],'fitsmax')
        end

        % Value changed function: SaveTXTfastnodisplayCheckBox
        function SaveTXTfastnodisplayCheckBoxValueChanged(app, event)
            value = app.SaveTXTfastnodisplayCheckBox.Value;
            if value==1
                app.UIAxes.Visible='off';
                plot(app.UIAxes,1)
                app.SaveFITSCheckBox.Value=0;
                app.SaveFITSnodisplayCheckBox.Value=0;
                app.DisplayMEANonlyCheckBox.Value=0;
                if size(app.u,1)~=0
                    fclose(app.u)
                end
            else
                app.UIAxes.Visible='on'
                %if numel(app.sock)
                app.sock.close()
                app.sock=[];
                %end
            end
        end

        % Value changed function: SaveFITSnodisplayCheckBox
        function SaveFITSnodisplayCheckBoxValueChanged(app, event)
            value = app.SaveFITSnodisplayCheckBox.Value;
            %make sure to close last multi-fits file
            if value==0 %&&  numel(app.fptr)>0
                import matlab.io.*
                try
                    fits.closeFile(app.fptr);
                catch
                end
                app.fptr=[];
                if size(app.u,1)~=0
                    fclose(app.u)
                end
                
            end
            if value==0
                app.UIAxes.Visible='on'
                app.fitsnum=1;
                %app.SaveTXTfastnodisplayCheckBox.Value=1;
            else
                app.UIAxes.Visible='off';
                plot(app.UIAxes,1)%cla(app.UIAxes)
                app.SaveTXTfastnodisplayCheckBox.Value=0;
                app.SaveFITSCheckBox.Value=0;
                app.DisplayMEANonlyCheckBox.Value=0;
                if size(app.sock,1)~=0
                    app.sock.close()
                    app.sock=[];
                end
                if size(app.u,1)~=0
                    fclose(app.u)
                end
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            uialert(app.UIFigure,'PANOtv v1.9 (jmaire@ucsd.edu)','About','Icon','info')
        end

        % Menu selected function: NIRMenu
        function NIRMenuSelected(app, event)
            if strmatch(app.NIRMenu.Checked,'off')
                app.NIRMenu.Checked='on'
                app.NIRview=1;
            else
                app.NIRMenu.Checked='off'
                app.NIRview=0;
            end
        end

        % Value changed function: DisplayMEANonlyCheckBox
        function DisplayMEANonlyCheckBoxValueChanged(app, event)
            value = app.DisplayMEANonlyCheckBox.Value;
            % value = app.SaveTXTfastnodisplayCheckBox.Value;
            if value==1
                app.UIAxes.Visible='on';
                plot(app.UIAxes,1)
                app.SaveFITSCheckBox.Value=0;
                app.SaveFITSnodisplayCheckBox.Value=0;
                app.SaveTXTfastnodisplayCheckBox.Value=0;
                if size(app.u,1)~=0
                    fclose(app.u)
                end
            else
                %app.UIAxes.Visible='on'
                %if numel(app.sock)
                app.sock.close()
                app.sock=[];
                %end
            end
        end

        % Value changed function: dispmoboCheckBox
        function dispmobo(app, event)
            dispmobo = app.dispmoboCheckBox.Value;
            if dispmobo==0
                app.UIAxes.Visible='On';
                app.UIAxes.Position=[10 134 403 323];
                app.UIAxes2.Visible='Off';
                app.UIAxes3.Visible='Off';
                app.UIAxes4.Visible='Off';
                app.UIAxes5.Visible='Off';
                app.UIAxes6.Visible='Off';
                app.J1Label.Visible='Off';
                app.J4Label.Visible='Off';
                app.J3Label.Visible='Off';
                app.J2Label_2.Visible='Off';
                app.UIAxes2.Position=[0 0 0 0];
                app.UIAxes3.Position=[0 0 0 0];
                app.UIAxes4.Position=[0 0 0 0];
                app.UIAxes5.Position=[0 0 0 0];
                app.UIAxes6.Position=[0 0 0 0];
            else
                app.UIAxes.Visible='Off';
                app.UIAxes.Position=[0 0 0 0];
                app.UIAxes2.Visible='On';
                app.UIAxes3.Visible='On';
                app.UIAxes4.Visible='On';
                app.UIAxes5.Visible='On';
                app.UIAxes6.Visible='Off';
                app.J1Label.Visible='On';
                app.J4Label.Visible='On';
                app.J3Label.Visible='On';
                app.J2Label_2.Visible='On';
                axis(app.UIAxes2,'off')
                axis(app.UIAxes3,'off')
                axis(app.UIAxes4,'off')
                axis(app.UIAxes5,'off')
                axis(app.UIAxes6,'off')
                app.UIAxes2.Position=[100 250 200 200];
                app.UIAxes3.Position=[300 250 200 200];
                app.UIAxes4.Position=[300 50 200 200];
                app.UIAxes5.Position=[100 50 200 200];
                %colorbar:
                app.UIAxes6.Position=[-130 50 240 400];
                %   app.UIAxes5.Position=[210 239 255 235];
            end
        end

        % Button pushed function: cleardispButton
        function cleardispButtonPushed(app, event)
            app.UIAxes.Visible='Off';
            
            app.UIAxes2.Visible='Off';
            app.UIAxes3.Visible='Off';
            app.UIAxes4.Visible='Off';
            app.UIAxes5.Visible='Off';
            app.UIAxes6.Visible='Off';
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)
            cla(app.UIAxes5)
            cla(app.UIAxes6)
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            value = app.MaxEditField.Value;
            if strncmp(app.ButtonGroup.SelectedObject.Text,'Fix',3)
                CLOW=app.MinEditField.Value;
                CHIGH=value;
                     
                   dispmobo=app.dispmoboCheckBox.Value;
                    if dispmobo==0
                        colorbar(app.UIAxes);
                        if CLOW<CHIGH
                              app.UIAxes.CLim=[CLOW CHIGH];
                        end
                    else
                        %    oldsize= get(app.UIAxes5,'Position')
                        % colorbar(app.UIAxes5);
                        hbar= colorbar(app.UIAxes6);
                        %set(hbar,'location','manual','ActivePositionProperty','OuterPosition');
                        % hbarPos=get(hbar,'OuterPosition');
                        if CLOW<CHIGH
                            set(hbar,'ylim',[CLOW CHIGH])
                        end
                        app.UIAxes2.CLim=[CLOW CHIGH];
                        app.UIAxes3.CLim=[CLOW CHIGH];
                        app.UIAxes4.CLim=[CLOW CHIGH];
                        app.UIAxes5.CLim=[CLOW CHIGH];
                        app.UIAxes6.CLim=[CLOW CHIGH];
                        %     oldsize(2)=oldsize(2)-50;
                        %     oldsize(3)=oldsize(3)+50;
                        %     oldsize(4)=oldsize(4)+100;
                        %     set(app.UIAxes5,'Position',oldsize)
                    end
            else
               % CLOW=app.MinEditField.Value;
               % CHIGH=app.MaxEditField.Value;
            end
            drawnow
        end

        % Menu selected function: FileMenu
        function FileMenuSelected(app, event)
            
        end

        % Menu selected function: nosocket
        function nosocketMenuSelected(app, event)
           if strncmp(app.nosocket.Checked,'on',2)==1
               app.nosocket.Checked='off';
           else
               app.nosocket.Checked='on';
           end
               
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if ~strncmp(selectedButton.Text,'Min',3)
                   CLOW=app.MinEditField.Value;
                   CHIGH=app.MaxEditField.Value;
                
                
                  dispmobo=app.dispmoboCheckBox.Value;
                    if dispmobo==0
                        hbar=colorbar(app.UIAxes);
                        if CLOW<CHIGH
                              app.UIAxes.CLim=[CLOW CHIGH];
                              
                            caxis(app.UIAxes,[CLOW CHIGH])
                             set(hbar,'ylim',[CLOW CHIGH])
                        end
                    else
                        %    oldsize= get(app.UIAxes5,'Position')
                        % colorbar(app.UIAxes5);
                        hbar= colorbar(app.UIAxes6);
                        %set(hbar,'location','manual','ActivePositionProperty','OuterPosition');
                        % hbarPos=get(hbar,'OuterPosition');
                       
                        app.UIAxes2.CLim=[CLOW CHIGH];
                        app.UIAxes3.CLim=[CLOW CHIGH];
                        app.UIAxes4.CLim=[CLOW CHIGH];
                        app.UIAxes5.CLim=[CLOW CHIGH];
                        caxis(app.UIAxes2,[CLOW CHIGH])
                        caxis(app.UIAxes3,[CLOW CHIGH])
                        caxis(app.UIAxes4,[CLOW CHIGH])
                        caxis(app.UIAxes5,[CLOW CHIGH])
                        caxis(app.UIAxes6,[CLOW CHIGH])
                        
                         if CLOW<CHIGH
                             
                            set(hbar,'ylim',[CLOW CHIGH])
                        end
                        %     oldsize(2)=oldsize(2)-50;
                        %     oldsize(3)=oldsize(3)+50;
                        %     oldsize(4)=oldsize(4)+100;
                        %     set(app.UIAxes5,'Position',oldsize)
                    end


            end
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            value = app.FilterDropDown.Value;
            if strncmp(value,'Ima',3)
            app.acqmode=3
            elseif strncmp(value,'PH',2)
                app.acqmode=1
            else
                app.acqmode=0
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.FileMenu.Text = 'File';
            app.LoadFitsFileMenu = uimenu(app.FileMenu);
            app.LoadFitsFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFitsFileMenuSelected, true);
            app.LoadFitsFileMenu.Text = 'Load Fits File...';
            app.nosocket = uimenu(app.FileMenu);
            app.nosocket.MenuSelectedFcn = createCallbackFcn(app, @nosocketMenuSelected, true);
            app.nosocket.Separator = 'on';
            app.nosocket.Text = 'Multi-instances mode (slower, no socket)';
            app.ImageMenu = uimenu(app.UIFigure);
            app.ImageMenu.Text = 'Image';
            app.SetGifparamsMenu = uimenu(app.ImageMenu);
            app.SetGifparamsMenu.MenuSelectedFcn = createCallbackFcn(app, @SetGifparamsMenuSelected, true);
            app.SetGifparamsMenu.Text = 'Set Gif params...';
            app.MakeGifMenu = uimenu(app.ImageMenu);
            app.MakeGifMenu.MenuSelectedFcn = createCallbackFcn(app, @MakeGifMenuSelected, true);
            app.MakeGifMenu.Text = 'Make Gif';
            app.NIRMenu = uimenu(app.ImageMenu);
            app.NIRMenu.MenuSelectedFcn = createCallbackFcn(app, @NIRMenuSelected, true);
            app.NIRMenu.Text = 'NIR';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'PANOlogo0.png';
            app.Button.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Welcome in PANOSETI packet viewer!'};
            app.PANOtvLabel = uilabel(app.UIFigure);
            app.PANOtvLabel.Text = 'PANOtv';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.Visible = 'off';
            app.DataDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.DataDirectoryEditFieldLabel.Text = 'Data Directory:';
            app.DataDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.DirButton = uibutton(app.UIFigure, 'push');
            app.DirButton.ButtonPushedFcn = createCallbackFcn(app, @DirButtonPushed, true);
            app.DirButton.Text = 'Dir...';
            app.SaveFITSCheckBox = uicheckbox(app.UIFigure);
            app.SaveFITSCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveFITSCheckBoxValueChanged, true);
            app.SaveFITSCheckBox.Text = 'Save FITS (slow, keep display)';
            app.InfoonImagePanel = uipanel(app.UIFigure);
            app.InfoonImagePanel.Title = 'Info on Image:';
            app.AcqmodeLabel = uilabel(app.InfoonImagePanel);
            app.AcqmodeLabel.Text = 'Acq. mode:';
            app.BoardNoLabel = uilabel(app.InfoonImagePanel);
            app.BoardNoLabel.Text = 'Board No:';
            app.PacketNoLabel = uilabel(app.InfoonImagePanel);
            app.PacketNoLabel.Text = 'Packet No:';
            app.UTCLabel = uilabel(app.InfoonImagePanel);
            app.UTCLabel.Text = 'UTC:';
            app.NanosecLabel = uilabel(app.InfoonImagePanel);
            app.NanosecLabel.Text = 'Nanosec:';
            app.TimeLabel = uilabel(app.InfoonImagePanel);
            app.TimeLabel.Text = 'Time:';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.MinMaxButton = uiradiobutton(app.ButtonGroup);
            app.MinMaxButton.Text = 'Min/Max';
            app.MinMaxButton.Value = true;
            app.FixedfasterButton = uiradiobutton(app.ButtonGroup);
            app.FixedfasterButton.Text = 'Fixed (faster):';
            app.MinEditFieldLabel = uilabel(app.ButtonGroup);
            app.MinEditFieldLabel.Visible = 'off';
            app.MinEditFieldLabel.Text = 'Min:';
            app.MinEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.MinEditField.Visible = 'off';
            app.MaxEditFieldLabel = uilabel(app.ButtonGroup);
            app.MaxEditFieldLabel.Visible = 'off';
            app.MaxEditFieldLabel.Text = 'Max:';
            app.MaxEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MaxEditField.Visible = 'off';
            app.MaxEditField.Value = 1000;
            app.NbFramesperfileEditFieldLabel = uilabel(app.UIFigure);
            app.NbFramesperfileEditFieldLabel.Text = 'Nb Frames per file:';
            app.NbFramesperfileEditField = uieditfield(app.UIFigure, 'numeric');
            app.NbFramesperfileEditField.Limits = [1 Inf];
            app.NbFramesperfileEditField.RoundFractionalValues = 'on';
            app.NbFramesperfileEditField.ValueDisplayFormat = '%11.3g';
            app.NbFramesperfileEditField.ValueChangedFcn = createCallbackFcn(app, @NbFramesperfileEditFieldValueChanged, true);
            app.NbFramesperfileEditField.Value = 100;
            app.SaveTXTfastnodisplayCheckBox = uicheckbox(app.UIFigure);
            app.SaveTXTfastnodisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveTXTfastnodisplayCheckBoxValueChanged, true);
            app.SaveTXTfastnodisplayCheckBox.Text = 'Save TXT (fast, no display)';
            app.SaveFITSnodisplayCheckBox = uicheckbox(app.UIFigure);
            app.SaveFITSnodisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveFITSnodisplayCheckBoxValueChanged, true);
            app.SaveFITSnodisplayCheckBox.Text = 'Save FITS (no display)';
            app.DisplayMEANonlyCheckBox = uicheckbox(app.UIFigure);
            app.DisplayMEANonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayMEANonlyCheckBoxValueChanged, true);
            app.DisplayMEANonlyCheckBox.Text = 'Display MEAN only';
            app.DisplayboardEditFieldLabel = uilabel(app.UIFigure);
            app.DisplayboardEditFieldLabel.Text = 'Display board::';
            app.DisplayboardEditField = uieditfield(app.UIFigure, 'numeric');
            app.DisplayboardEditField.Value = -1;
            app.AllLabel = uilabel(app.UIFigure);
            app.AllLabel.Text = '(-1=All)';
            app.dispmoboCheckBox = uicheckbox(app.UIFigure);
            app.dispmoboCheckBox.ValueChangedFcn = createCallbackFcn(app, @dispmobo, true);
            app.dispmoboCheckBox.Text = 'disp. mobo';
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.Visible = 'off';
            app.UIAxes4 = uiaxes(app.UIFigure);
            app.UIAxes4.Visible = 'off';
            app.UIAxes5 = uiaxes(app.UIFigure);
            app.UIAxes5.Visible = 'off';
            app.UIAxes6 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.UIAxes6.Visible = 'off';
            app.J1Label = uilabel(app.UIFigure);
            app.J1Label.Visible = 'off';
            app.J1Label.Text = 'J1';
            app.J4Label = uilabel(app.UIFigure);
            app.J4Label.Visible = 'off';
            app.J4Label.Text = 'J4';
            app.J3Label = uilabel(app.UIFigure);
            app.J3Label.Visible = 'off';
            app.J3Label.Text = 'J3';
            app.J2Label_2 = uilabel(app.UIFigure);
            app.J2Label_2.Visible = 'off';
            app.J2Label_2.Text = 'J2';
            app.cleardispButton = uibutton(app.UIFigure, 'push');
            app.cleardispButton.ButtonPushedFcn = createCallbackFcn(app, @cleardispButtonPushed, true);
            app.cleardispButton.Text = 'clear disp';
            app.FilterDropDownLabel = uilabel(app.UIFigure);
            app.FilterDropDownLabel.Text = 'Filter:';
            app.FilterDropDown = uidropdown(app.UIFigure);
            app.FilterDropDown.Items = {'All modes', 'Ima only', 'PH only'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Value = 'All modes';
            app.DisprefreshratefrEditFieldLabel = uilabel(app.UIFigure);
            app.DisprefreshratefrEditFieldLabel.Text = 'Disp refresh rate (fr.)';
            app.DisprefreshratefrEditField = uieditfield(app.UIFigure, 'numeric');
            app.DisprefreshratefrEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PANOtv_20200521

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end