classdef housekeeping19 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        HouseKeepingLabel              matlab.ui.control.Label
        Switch                         matlab.ui.control.ToggleSwitch
        Lamp                           matlab.ui.control.Lamp
        SaveData                       matlab.ui.control.CheckBox
        TabGroup                       matlab.ui.container.TabGroup
        Module1Tab                     matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        HVtable                        matlab.ui.control.Table
        Vtable                         matlab.ui.control.Table
        Itable2                        matlab.ui.control.Table
        Itable                         matlab.ui.control.Table
        UTCLabel                       matlab.ui.control.Label
        UIDLabel                       matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        PlotButtonGroup                matlab.ui.container.ButtonGroup
        HVVoltageButton                matlab.ui.control.RadioButton
        CurrentHVButton                matlab.ui.control.RadioButton
        V1237Button                    matlab.ui.control.RadioButton
        I1033Button                    matlab.ui.control.RadioButton
        TempButton                     matlab.ui.control.RadioButton
        LastminuteButton               matlab.ui.control.StateButton
        NightButton                    matlab.ui.control.StateButton
        ResetButton                    matlab.ui.control.StateButton
        TTMP125CGaugeLabel             matlab.ui.control.Label
        Temp1CGauge                    matlab.ui.control.SemicircularGauge
        TFPGACGaugeLabel               matlab.ui.control.Label
        Temp2CGauge                    matlab.ui.control.SemicircularGauge
        PacketreceivedLamp             matlab.ui.control.Lamp
        ConvertHKdatatoTXTButton       matlab.ui.control.Button
        Module2Tab                     matlab.ui.container.Tab
        Module3Tab                     matlab.ui.container.Tab
        Module4Tab                     matlab.ui.container.Tab
        Button_4                       matlab.ui.control.Button
        deployfig                      matlab.ui.control.Button
        DisplayBoardlocEditFieldLabel  matlab.ui.control.Label
        DisplayBoardlocEditField       matlab.ui.control.EditField
    end

    
    properties (Access = public)
        indlastreset;
        indcurrenttime;
        runatstart;
%          hvmon0tab; % Description
%          hvmon1tab;
%                      hvmon2tab;
%                      hvmon3tab;
%                      ihvmon0tab;
%                      ihvmon1tab;
%                      ihvmon2tab;
%                      ihvmon3tab;
%                      rawhvmontab;
%                      v12montab;
%                      v18montab;
%                      v33montab;
%                      v37montab;
%                      i10montab;
%                      i18montab;
%                      i33montab;
%                      temp1tab;
%                      temp2tab;
%                      vcodac0tab;
%                      vcodac1tab;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.UIFigure.Position = [700 40 450 946];
            app.deployfig.Text='>';
            if length(varargin)>=1
               app.runatstart=1 
               app.Switch.Value='On'
               SwitchValueChanged(app,[])
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strncmp(value,'On',2)
                app.Lamp.Color=[0 1 0];
                drawnow
                %listen packets
                while strncmp(value,'On',2)
                    
                    %read packets
                    port=60002;
                    [packet,SOURCEHOST]  = judp('RECEIVE',port,64, 10000);
                    %convert values
                    sz=size(packet);
                    szp=sz(1);
                    Vref=1250.;
                    
                    %% read data
                    if szp==64 %HK data
                        
                        byte0=typecast(uint8(packet(1)),'uint8');
                        %if byte0~ should display Firstboot
                        boardloc=typecast((packet(3:4)),'uint16');
                        hvmon0=Vref*(10/(65536*158))*double(typecast(([packet(5) packet(6)]),'uint16'));
                        hvmon1=Vref*(10/(65536*158))*double(typecast(([packet(7) packet(8)]),'uint16'));
                        hvmon2=Vref*(10/(65536*158))*double(typecast(([packet(9) packet(10)]),'uint16'));
                        hvmon3=Vref*(10/(65536*158))*double(typecast(([packet(11) packet(12)]),'uint16'));
                        ihvmon0=Vref/499 *1000 * ((1/65536)*(65535 -double(typecast((packet(13:14)),'uint16')))) ;
                        ihvmon1=Vref/499 *1000 * ((1/65536)*(65535 -double(typecast((packet(15:16)),'uint16')))) ;
                        ihvmon2=Vref/499 *1000 * ((1/65536)*(65535 -double(typecast((packet(17:18)),'uint16')))) ;
                        ihvmon3=Vref/499 *1000 * ((1/65536)*(65535 -double(typecast((packet(19:20)),'uint16')))) ;
                        rawhvmon= (Vref/65536) * 10/158 * double( typecast((packet(21:22)),'uint16'));
                        v12mon=(Vref/65536/1000) * double(typecast((packet(23:24)),'uint16')) ;
                        v18mon=(2*Vref/65536/1000) * double(typecast((packet(25:26)),'uint16'));
                        v33mon=(Vref/65536/ 1000) * 13.3/3.3 * double(typecast((packet(27:28)),'uint16'));
                        v37mon= (Vref/65536/ 1000) * 13.3/3.3 * double(typecast((packet(29:30)),'uint16'))  ;
                        i10mon=(Vref/65536) /.105 * double( typecast((packet(31:32)),'uint16')) /1000;
                        i18mon=(Vref/65536) /.505 * double(typecast((packet(33:34)),'uint16')) /1000 ;
                        i33mon=(Vref/65536) /.505 * double(typecast((packet(35:36)),'uint16')) /1000 ;
                        temp1=double(typecast((packet(37:38)),'uint16'));
                        
                        temp2=double(typecast((packet(39:40)),'uint16'));
                        %disp temps
                        temp1c = (temp1)/4
                        if temp1c>127.75
                            temp1c = temp1c - 128;
                        end
                        temp2c = (((temp2/65536)/0.00198421639) - 273.15);
                        %                         if temp2c>127.75
                        %                             temp2c = temp2c - 128;
                        %                         end
                        vccint=(3/65536)*double(typecast((packet(41:42)),'uint16'));
                        vccaux=(3/65536)*double(typecast((packet(43:44)),'uint16'));
                        %                           vcodac0=typecast((packet(41:42)),'uint16');
                        %                         vcodac1=typecast((packet(43:44)),'uint16');
                        uid=double(typecast((packet(45:52)),'uint64'));
                        utc=double(typecast((packet(53:56)),'uint32'));
                        nanosec=typecast((packet(57:60)),'uint32');
                    end
                    
                    %blink to alert new packet arrived
                    app.PacketreceivedLamp.Color=[0 1 0];drawnow;
                    %save data
                    utclag=-1.*(getutcdelay('LICK')/24.);% in days
                    time=now; %in days --break here for different time in observability plots
                    timeutc=time+utclag;
                    dateutc=datestr(timeutc,'yyyymmdd');
                    
                    
                    %rep=[readconfig('maindatadirectory') dateutc '\'];
                    if ~exist([getuserdir '\panoseti\'],'dir')
                        mkdir([getuserdir '\panoseti\'])
                    end
                    rep=[getuserdir  '\panoseti\panoHK\'];
                    if ~exist(rep,'dir')
                        mkdir(rep)
                    end
                    
                    if ~isequal(exist(rep, 'dir'),7)
                        mkdir(rep)
                    end
                    
                    if isequal(exist([rep 'HK_' dateutc '_' num2str(boardloc) '.mat']),2)
                        load([rep 'HK_' dateutc '_' num2str(boardloc) '.mat'])
                        hvmon0tab=[hvmon0tab hvmon0];
                        hvmon1tab=[hvmon1tab hvmon1];
                        hvmon2tab=[hvmon2tab hvmon2];
                        hvmon3tab=[hvmon3tab hvmon3];
                        ihvmon0tab=[ihvmon0tab ihvmon0];
                        ihvmon1tab=[ihvmon1tab ihvmon1];
                        ihvmon2tab=[ihvmon2tab ihvmon2];
                        ihvmon3tab=[ihvmon3tab ihvmon3];
                        rawhvmontab=[rawhvmontab rawhvmon];
                        v12montab=[v12montab v12mon];
                        v18montab=[v18montab v18mon];
                        v33montab=[v33montab v33mon];
                        v37montab=[v37montab v37mon];
                        i10montab=[i10montab i10mon];
                        i18montab=[i18montab i18mon];
                        i33montab=[i33montab i33mon];
                        temp1tab=[temp1tab temp1c];
                        temp2tab=[temp2tab temp2c];
                        vccinttab=[vccinttab vccint];
                        vccauxtab=[vccauxtab vccaux];
                        utctab=[utctab utc];
                        timecomptab=[timecomptab now];
                    else
                        hvmon0tab=[hvmon0];
                        hvmon1tab=[hvmon1];
                        hvmon2tab=[hvmon2];
                        hvmon3tab=[hvmon3];
                        ihvmon0tab=[ihvmon0];
                        ihvmon1tab=[ihvmon1];
                        ihvmon2tab=[ihvmon2];
                        ihvmon3tab=[ihvmon3];
                        rawhvmontab=[rawhvmon];
                        v12montab=[v12mon];
                        v18montab=[v18mon];
                        v33montab=[v33mon];
                        v37montab=[v37mon];
                        i10montab=[i10mon];
                        i18montab=[i18mon];
                        i33montab=[i33mon];
                        temp1tab=[temp1c];
                        temp2tab=[temp2c];
                        vccinttab=[vccint];
                        vccauxtab=[vccaux];
                        utctab=[utc];
                        timecomptab=[now];
                        
                    end
                    if app.SaveData.Value==1
                        save([rep 'HK_' dateutc '_' num2str(boardloc) '.mat'], 'hvmon0tab',...
                            'hvmon1tab',...
                            'hvmon2tab',...
                            'hvmon3tab',...
                            'ihvmon0tab',...
                            'ihvmon1tab',...
                            'ihvmon2tab',...
                            'ihvmon3tab',...
                            'rawhvmontab',...
                            'v12montab',...
                            'v18montab',...
                            'v33montab',...
                            'v37montab',...
                            'i10montab',...
                            'i18montab',...
                            'i33montab',...
                            'temp1tab',...
                            'temp2tab',...
                            'vccinttab',...
                            'vccauxtab','utctab','timecomptab')
                    end
                    
                    %display
                    dispBoardLoc=app.DisplayBoardlocEditField.Value
                    if boardloc==str2num(dispBoardLoc)
                    
                    app.UTCLabel.Text=['UTC: ' num2str(utc)];
                    app.UIDLabel.Text=['UID: ' num2str(uid)];
                    
                    tdata={hvmon0,hvmon1, hvmon2, hvmon3};
                    app.HVtable.Data=tdata;
                    tdata= { ihvmon0 , ihvmon1 , ...
                        ihvmon2 , ihvmon3 };
                    app.Itable.Data=tdata;
                    
                    
                    % #Print HVRaw,...
                    rawHVmont = rawhvmon % #in volts
                    v12mont=v12mon  % #in volts
                    v18mont=v18mon  % #in volts
                    v33mont=v33mon% # in volts
                    v37mont= v37mon  %# in volts
                    app.Vtable.Data={rawHVmont, v12mont, v18mont,  v33mont,  v37mont};
                    
                    %print I10,...
                    i10mont= i10mon %  #in amps
                    i18mont= i18mon  %# in amps
                    i33mont= i33mon %# in amps
                    app.Itable2.Data={i10mont, i18mont,  i33mont, vccint, vccaux};
                    
                    
                    app.Temp1CGauge.Value=temp1c;
                    app.TTMP125CGaugeLabel.Text=['Temp 1 (C): ' num2str(temp1c)];
                    app.Temp2CGauge.Value=temp2c;
                    app.TFPGACGaugeLabel.Text=['Temp 2 (C): ' num2str(temp2c)];
                    %  drawnow;
                    %                 %%should be avoided:
                    %                    app.hvmon0tab=hvmon0tab;
                    %              app.hvmon1tab=hvmon1tab;
                    %                    app.hvmon2tab=  hvmon2tab;
                    %                      app.hvmon3tab=hvmon3tab;
                    %                      app.ihvmon0tab=ihvmon0tab;
                    %                      app.ihvmon1tab=ihvmon1tab;
                    %                      app.ihvmon2tab=ihvmon2tab;
                    %                      app.ihvmon3tab=ihvmon3tab;
                    %                      app.rawhvmontab=rawhvmontab;
                    %                      app.v12montab=v12montab;
                    %                      app.v18montab=v18montab;
                    %                      app.v33montab=v33montab;
                    %                      app.v37montab=v37montab;
                    %                      app.i10montab=i10montab;
                    %                      app.i18montab=i18montab;
                    %                      app.i33montab=i33montab;
                    %                      app.temp1tab=temp1tab;
                    %                      app.temp2tab=temp2tab;
                    %                      app.vcodac0tab=vcodac0tab;
                    %                      app.vcodac1tab=vcodac1tab;
                    %%plot on axes
                    selectedButton = app.PlotButtonGroup.SelectedObject.Text;
                    %             hvmon0tab=app.hvmon0tab;
                    %              hvmon1tab=app.hvmon1tab;
                    %                      hvmon2tab=app.hvmon2tab;
                    %                      hvmon3tab=app.hvmon3tab;
                    %                      ihvmon0tab=app.ihvmon0tab;
                    %                      ihvmon1tab=app.ihvmon1tab;
                    %                      ihvmon2tab=app.ihvmon2tab;
                    %                      ihvmon3tab=app.ihvmon3tab;
                    %                      rawhvmontab=app.rawhvmontab;
                    %                      v12montab=app.v12montab;
                    %                      v18montab=app.v18montab;
                    %                      v33montab=app.v33montab;
                    %                      v37montab=app.v37montab;
                    %                      i10montab=app.i10montab;
                    %                      i18montab=app.i18montab;
                    %                      i33montab=app.i33montab;
                    %                      temp1tab=app.temp1tab;
                    %                      temp2tab=app.temp2tab;
                    %                      vcodac0tab=app.vcodac0tab;
                    %                      vcodac1tab=app.vcodac1tab;
                    app.UIAxes.Visible=1;
                    app.NightButton.Visible=1;
                    app.LastminuteButton.Visible=1;
                    app.ResetButton.Visible=1;
                    drawnow
                    
                    app.indcurrenttime=size(hvmon0tab,2);
                    
                    indfigxtime=find([app.NightButton.Value app.LastminuteButton.Value  app.ResetButton.Value]==1);
                    minaxrangetab=[1 max([size(hvmon0tab,2)-30 1]) app.indlastreset];
                    if strncmp(selectedButton,'HV',2)
                        nper=size(hvmon0tab,2);
                        plot(app.UIAxes,  [1:nper]', hvmon0tab,'b', ...
                            [1:nper]', hvmon1tab,'r', ...
                            [1:nper]', hvmon2tab,'y', ...
                            [1:nper]', hvmon3tab,'g') ;
                        xlabel(app.UIAxes,'Time (acq no)')
                        ylabel(app.UIAxes,'Voltage (V)')
                        title(app.UIAxes,'Voltage (V)')
                        legend(app.UIAxes,'array 0','1','2','3')
                        if minaxrangetab(indfigxtime)<nper
                            xlim(app.UIAxes,[minaxrangetab(indfigxtime) nper])
                        end
                        %  xlim(app.axPrincipalInterest,[0 nper]) ;
                    elseif  strncmp(selectedButton,'Cu',2)
                        nper=size(ihvmon0tab,2);
                        plot(app.UIAxes,  [1:nper]', ihvmon0tab,'b', ...
                            [1:nper]', ihvmon1tab,'r', ...
                            [1:nper]', ihvmon2tab,'y', ...
                            [1:nper]', ihvmon3tab,'g') ;
                        xlabel(app.UIAxes,'Time (acq no)')
                        ylabel(app.UIAxes,'Current (\muA)')
                        title(app.UIAxes,'Current (\muA)')
                        legend(app.UIAxes,'array 0','1','2','3')
                        if minaxrangetab(indfigxtime)<nper
                            xlim(app.UIAxes,[minaxrangetab(indfigxtime) nper])
                        end
                    elseif strncmp(selectedButton,'V12',2)
                        nper=size(rawhvmontab,2);
                        plot(app.UIAxes,  [1:nper]', rawhvmontab,'b', ...
                            [1:nper]', v12montab,'r', ...
                            [1:nper]', v18montab,'y', ...
                            [1:nper]', v33montab,'g',...
                            [1:nper]', v37montab,'m') ;
                        xlabel(app.UIAxes,'Time (acq no)')
                        ylabel(app.UIAxes,'Voltage (V)')
                        title(app.UIAxes,'Voltage (V)')
                        legend(app.UIAxes,'Raw HV','V12','V18','V33','V37')
                        if minaxrangetab(indfigxtime)<nper
                            xlim(app.UIAxes,[minaxrangetab(indfigxtime) nper])
                        end
                    elseif  strncmp(selectedButton,'I10',2)
                        nper=size(i10montab,2);
                        plot(app.UIAxes,  [1:nper]', i10montab,'b', ...
                            [1:nper]', i18montab, 'r',...
                            [1:nper]', i33montab,'g') ;
                        xlabel(app.UIAxes,'Time (acq no)')
                        ylabel(app.UIAxes,'Current (\muA)')
                        title(app.UIAxes,'Current (\muA)')
                        legend(app.UIAxes,'I10','I18','I33')
                        if minaxrangetab(indfigxtime)<nper
                            xlim(app.UIAxes,[minaxrangetab(indfigxtime) nper])
                        end
                    elseif  strncmp(selectedButton,'Te',2)
                        % temp1tab=[temp1];
                        %   temp2tab=[temp2];
                        nper=size(temp1tab,2);
                        plot(app.UIAxes,  [1:nper]', temp1tab,'b', ...
                            [1:nper]', temp2tab,'r') ;
                        xlabel(app.UIAxes,'Time (acq no)')
                        ylabel(app.UIAxes,'Temperature (C)')
                        title(app.UIAxes,'Temperature (C)')
                        legend(app.UIAxes,'TMP125 ','T FPGA')
                        if minaxrangetab(indfigxtime)<nper
                            xlim(app.UIAxes,[minaxrangetab(indfigxtime) nper])
                        end
                    else
                    end
                    %blink to alert new packet arrived
                    app.PacketreceivedLamp.Color=[0.94 0.94 0.94];
                    drawnow
                    value = app.Switch.Value;
                end
                end
                
            else
                app.Lamp.Color=[1.0 0.96 0.93];
                drawnow
            end
        end

        % Callback function
        function SelectPlot(app, event)
          
        end

        % Value changed function: LastminuteButton
        function FigTimeLastmin(app, event)
            value = app.LastminuteButton.Value;
            if value==1
                %assure all other buttons are unselected
                app.NightButton.Value=0;
                app.ResetButton.Value=0;
                % xlim(app.UIAxes,[])
            else
                app.LastminuteButton.Value=1; % force to stay the same (should be unselected as a radio buitton)
                
            end
        end

        % Value changed function: NightButton
        function FigTimeNight(app, event)
            value = app.NightButton.Value;
            if value==1
                %assure all other buttons are unselected
                app.LastminuteButton.Value=0;
                app.ResetButton.Value=0;
                %
            else
                app.NightButton.Value=1; % force to stay the same (should be unselected as a radio buitton)
                
            end
        end

        % Value changed function: ResetButton
        function FigTimeReset(app, event)
            value = app.ResetButton.Value;
            if value==1
                %assure all other buttons are unselected
                app.LastminuteButton.Value=0;
                app.NightButton.Value=0;
                app.indlastreset=app.indcurrenttime;
                % xlim(app.UIAxes,[])
            else
                app.ResetButton.Value=1; % force to stay the same (should be unselected as a radio buitton)
                
            end
        end

        % Button pushed function: deployfig
        function deployfigPushed(app, event)
              if strncmp(app.deployfig.Text,'<',1)
            app.UIFigure.Position = [700 40 450 946];
            app.deployfig.Text='>';
            else
            app.UIFigure.Position = [700 40 1600 946];
            app.deployfig.Text='<';
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            uialert(app.UIFigure,'PANO HK v1.8 (jmaire@ucsd.edu)','About','Icon','info')
        end

        % Button pushed function: ConvertHKdatatoTXTButton
        function ConvertHKdatatoTXTButtonPushed(app, event)
              [file,path] = uigetfile([getuserdir '\panoseti\panoHK\*.mat'],'Select your HK mat file');
            if isfile([path file])
             load([path file])
             nbacq=numel(hvmon0tab);
             fileID = fopen([path file(1:end-4) '.txt'],'w');
            fprintf(fileID, 'datecmp\t\ttimecmp\t\thv0\thv1\thv2\thv3\tihv0\tihv1\tihv2\tihv3\trawhv\tv12 \tv18 \tv33 \tv37 \tf10 \tf18 \tI10 \tI18\tI33\ttemp\tftemp\tvccinttab\tvccauxtab\tutctab\t\n')
            y = [str2num(datestr(timecomptab,'yyyymmdd')) ...
                str2num(datestr(timecomptab,'HHMMSS.FFF')) ...
                        hvmon0tab' ...
                        hvmon1tab' ...
                        hvmon2tab' ...
                        hvmon3tab' ...
                        ihvmon0tab' ...
                        ihvmon1tab' ...
                        ihvmon2tab' ...
                        ihvmon3tab' ...
                        rawhvmontab' ...
                        v12montab' ...
                        v18montab' ...
                        v33montab' ...
                        v37montab' ...
                        i10montab' ...
                        i18montab' ...
                        i33montab' ...
                        temp1tab' ...
                        temp2tab' ...
                        vccinttab' ...
                        vccauxtab' ...
                        utctab'];
            fprintf(fileID,'%d %f %f %f %f %f %f %f %f %f %f %f %f %f %f %f %f %f %f %f %f %f %f\n',y');
                fclose(fileID);
             
             
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.HouseKeepingLabel = uilabel(app.UIFigure);
            app.HouseKeepingLabel.Text = 'House Keeping';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.SaveData = uicheckbox(app.UIFigure);
            app.SaveData.Text = 'Save House keeping data';
            app.SaveData.Value = true;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Module1Tab = uitab(app.TabGroup);
            app.Module1Tab.Title = 'Module1';
            app.Panel = uipanel(app.Module1Tab);
            app.Panel.Title = 'Panel';
            app.HVtable = uitable(app.Panel);
            app.HVtable.ColumnName = {' HV1 (V)'; 'HV2 (V)'; 'HV3 (V)'; 'HV4 (V)'};
            app.HVtable.RowName = {};
            app.HVtable.Tooltip = {'Bias voltage to detector array x'};
            app.Vtable = uitable(app.Panel);
            app.Vtable.ColumnName = {'RAW HV (V)'; 'V12 (V)'; 'V18 (V)'; 'V33 (V)'; 'V37 (V)'};
            app.Vtable.RowName = {};
            app.Vtable.Tooltip = {'Raw:Nominal -70V supply voltage'; 'V12: voltage of the xV supply to FPGA '; 'V18V33:voltage to the MAROC'; 'V37: voltage of the 3.7V switching supply'};
            app.Itable2 = uitable(app.Panel);
            app.Itable2.ColumnName = {'I10 (A)'; 'I18 (A)'; 'I33 (A)'; 'VccInt(V)'; 'VccAux(V)'};
            app.Itable2.RowName = {};
            app.Itable2.Tooltip = {'Current of the xV supply to the FPGA core'};
            app.Itable = uitable(app.Panel);
            app.Itable.ColumnName = {' I HV1 (uA)'; 'I HV2 (uA)'; 'I HV3 (uA)'; 'I HV4 (uA)'};
            app.Itable.RowName = {};
            app.Itable.Tooltip = {'Current of the xV supply to the FPGA'};
            app.UTCLabel = uilabel(app.Panel);
            app.UTCLabel.Text = 'UTC:';
            app.UIDLabel = uilabel(app.Panel);
            app.UIDLabel.Text = 'UID:';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.PlotButtonGroup = uibuttongroup(app.Panel);
            app.PlotButtonGroup.Title = 'Plot:';
            app.HVVoltageButton = uiradiobutton(app.PlotButtonGroup);
            app.HVVoltageButton.Text = 'HV Voltage';
            app.HVVoltageButton.Value = true;
            app.CurrentHVButton = uiradiobutton(app.PlotButtonGroup);
            app.CurrentHVButton.Text = 'Current HV';
            app.V1237Button = uiradiobutton(app.PlotButtonGroup);
            app.V1237Button.Text = 'V12-37';
            app.I1033Button = uiradiobutton(app.PlotButtonGroup);
            app.I1033Button.Text = 'I10-33';
            app.TempButton = uiradiobutton(app.PlotButtonGroup);
            app.TempButton.Text = 'Temp';
            app.LastminuteButton = uibutton(app.Panel, 'state');
            app.LastminuteButton.ValueChangedFcn = createCallbackFcn(app, @FigTimeLastmin, true);
            app.LastminuteButton.Visible = 'off';
            app.LastminuteButton.Text = 'Last minute';
            app.NightButton = uibutton(app.Panel, 'state');
            app.NightButton.ValueChangedFcn = createCallbackFcn(app, @FigTimeNight, true);
            app.NightButton.Visible = 'off';
            app.NightButton.Text = 'Night';
            app.NightButton.Value = true;
            app.ResetButton = uibutton(app.Panel, 'state');
            app.ResetButton.ValueChangedFcn = createCallbackFcn(app, @FigTimeReset, true);
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.TTMP125CGaugeLabel = uilabel(app.Panel);
            app.TTMP125CGaugeLabel.Text = 'T (TMP125) [C]:        ';
            app.Temp1CGauge = uigauge(app.Panel, 'semicircular');
            app.Temp1CGauge.Tooltip = {'Temperature from the TMP125'};
            app.TFPGACGaugeLabel = uilabel(app.Panel);
            app.TFPGACGaugeLabel.Text = 'T (FPGA) [C]:       ';
            app.Temp2CGauge = uigauge(app.Panel, 'semicircular');
            app.Temp2CGauge.Tooltip = {'Temperature from the FPGA'};
            app.PacketreceivedLamp = uilamp(app.Module1Tab);
            app.ConvertHKdatatoTXTButton = uibutton(app.Module1Tab, 'push');
            app.ConvertHKdatatoTXTButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertHKdatatoTXTButtonPushed, true);
            app.ConvertHKdatatoTXTButton.Text = 'Convert HK data to TXT...';
            app.Module2Tab = uitab(app.TabGroup);
            app.Module2Tab.Title = 'Module 2';
            app.Module3Tab = uitab(app.TabGroup);
            app.Module3Tab.Title = 'Module 3';
            app.Module4Tab = uitab(app.TabGroup);
            app.Module4Tab.Title = 'Module 4';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'PANOlogo0.png';
            app.Button_4.Text = '';
            app.deployfig = uibutton(app.UIFigure, 'push');
            app.deployfig.ButtonPushedFcn = createCallbackFcn(app, @deployfigPushed, true);
            app.deployfig.Text = '<';
            app.DisplayBoardlocEditFieldLabel = uilabel(app.UIFigure);
            app.DisplayBoardlocEditFieldLabel.Text = 'Display Boardloc:';
            app.DisplayBoardlocEditField = uieditfield(app.UIFigure, 'text');
            app.DisplayBoardlocEditField.Value = '1016';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = housekeeping19(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end