classdef panocommands4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        Module1Tab                 matlab.ui.container.Tab
        Quabo1Panel                matlab.ui.container.Panel
        HighVoltagesPanel          matlab.ui.container.Panel
        UITableHV                  matlab.ui.control.Table
        MarocPanel                 matlab.ui.container.Panel
        UITableMaroc               matlab.ui.control.Table
        ThresholdLabel             matlab.ui.control.Label
        ThresholdSlider            matlab.ui.control.Slider
        ThreshEditField            matlab.ui.control.NumericEditField
        GainSliderLabel            matlab.ui.control.Label
        GainSlider                 matlab.ui.control.Slider
        ThreshDropDownQuads        matlab.ui.control.DropDown
        GainEditField              matlab.ui.control.NumericEditField
        GainDropDownQuads          matlab.ui.control.DropDown
        UITableGain                matlab.ui.control.Table
        UITableMask                matlab.ui.control.Table
        MasksLabel                 matlab.ui.control.Label
        applyMaskButton            matlab.ui.control.Button
        ApplyMarocButton           matlab.ui.control.Button
        ApplyHVsButton             matlab.ui.control.Button
        AcquisitionsPanel          matlab.ui.container.Panel
        UITableAcq                 matlab.ui.control.Table
        ModeLabel                  matlab.ui.control.Label
        ModeDropDown               matlab.ui.control.DropDown
        StimLabel                  matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        STIMSwitch                 matlab.ui.control.ToggleSwitch
        AmplitpeSliderLabel        matlab.ui.control.Label
        AmplitpeSlider             matlab.ui.control.Slider
        MeasbaselineButton         matlab.ui.control.Button
        ResetBoardButton           matlab.ui.control.Button
        DropDownFreq               matlab.ui.control.DropDown
        ApplyButton                matlab.ui.control.Button
        Module2Tab                 matlab.ui.container.Tab
        Quabo1Panel_2              matlab.ui.container.Panel
        HighVoltagesPanel_2        matlab.ui.container.Panel
        UITableHV_2                matlab.ui.control.Table
        MarocPanel_2               matlab.ui.container.Panel
        UITableMaroc_2             matlab.ui.control.Table
        ThresholdSlider_2Label     matlab.ui.control.Label
        ThresholdSlider_2          matlab.ui.control.Slider
        ThreshEditField_2          matlab.ui.control.NumericEditField
        GainSlider_2Label          matlab.ui.control.Label
        GainSlider_2               matlab.ui.control.Slider
        ThreshDropDownQuads_2      matlab.ui.control.DropDown
        GainEditField_2            matlab.ui.control.NumericEditField
        GainDropDownQuads_2        matlab.ui.control.DropDown
        UITableGain_2              matlab.ui.control.Table
        UITableMask_2              matlab.ui.control.Table
        MasksLabel_2               matlab.ui.control.Label
        applyMaskButton_2          matlab.ui.control.Button
        ApplyMarocButton_2         matlab.ui.control.Button
        ApplyHVsButton_2           matlab.ui.control.Button
        AcquisitionsPanel_2        matlab.ui.container.Panel
        UITableAcq_2               matlab.ui.control.Table
        ModeDropDown_2Label        matlab.ui.control.Label
        ModeDropDown_2             matlab.ui.control.DropDown
        StimLabel_2                matlab.ui.control.Label
        Lamp_2                     matlab.ui.control.Lamp
        STIMSwitch_2               matlab.ui.control.ToggleSwitch
        AmplitpeSlider_2Label      matlab.ui.control.Label
        AmplitpeSlider_2           matlab.ui.control.Slider
        MeasbaselineButton_2       matlab.ui.control.Button
        ResetBoardButton_2         matlab.ui.control.Button
        DropDownFreq_2             matlab.ui.control.DropDown
        ApplyButton_2              matlab.ui.control.Button
        Module3Tab                 matlab.ui.container.Tab
        Module4Tab                 matlab.ui.container.Tab
        Button                     matlab.ui.control.Button
        CommandsLabel              matlab.ui.control.Label
        LoadDefaultConfigButton_3  matlab.ui.control.Button
        LoadLastConfigButton       matlab.ui.control.Button
        SaveConfigButton           matlab.ui.control.Button
        deployfig                  matlab.ui.control.Button
        LoadConfigButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        currentconfig; % Description
        lastconfigfile;
        defautconfigfile;
        requestedconfigfile
        configdir;
        sock;
    end
    
    methods (Access = private)
        
        function results = sentacqparams(app)
%              quaboconfig=app.currentconfig;
%             [ia,index]=ismember('ACQMODE ',quaboconfig);
%             aqm=char(quaboconfig(index,2));
%             indx=strfind(aqm,'x');
%              ACQMODE=str2num(aqm(indx+1:end)) ;
%         %= 0x1 FOR PH, , 0x11 for PH, no BL subtract, 0x02 FOR IM
%         
%       %  ACQINT = 255 	%*frame interval for IMage mode, x1.024us
%          [ia,index]=ismember('ACQINT ',quaboconfig);
%              ACQINT=str2num(quaboconfig(index,2)) ;
% 
%       %  HOLD1 = 0	%*time after trigger that hold1 is asserted, x5ns, 0 to 15
%          [ia,index]=ismember('HOLD1 ',quaboconfig);
%              HOLD1=str2num(quaboconfig(index,2)) ;
% 
%        % HOLD2 = 0	%*time after trigger that hold1 is asserted, x5ns, 0 to 15
%          [ia,index]=ismember('HOLD2 ',quaboconfig);
%              HOLD2=str2num(quaboconfig(index,2)) ;
% 
%       %  ADCCLKPH = 5	%*phase of PH ADC clock, in 10ns steps, 0 to 7
%         % [ia,index]=ismember('ADCCLKPH ',quaboconfig);
%          %    ADCCLKPH=str2num(quaboconfig(index,2)) ;
% 
%        % MONCHAN = 70	%*Channel to stop the analog mux at for monitor points
%          [ia,index]=ismember('MONCHAN ',quaboconfig);
%             MONCHAN=str2num(quaboconfig(index,2)) ;
% 
%         %STIMON = 1
%         [ia,index]=ismember('STIMON ',quaboconfig);
%             STIMON=str2num(quaboconfig(index,2)) ;
%        
%        % STIM_LEVEL = 16%*0 to 255
%         [ia,index]=ismember('STIM_LEVEL ',quaboconfig);
%             STIM_LEVEL=str2num(quaboconfig(index,2)) ;
%             
%        % STIM_RATE = 
%         [ia,index]=ismember('STIM_RATE ',quaboconfig);
%             STIM_RATE=str2num(quaboconfig(index,2)) ;
%         
%         packet=int8(zeros(64,1));
%         packet(1)=int8(3);
%         %hv1=20000;
%         % hv1h=dec2hex(ACQMODE)
%         % LB=hv1h(3:4)
%         % MB=hv1h(1:2)
%         %packet(3)=int8(1);
%            packet(3)=int8(ACQMODE);
%            
%        acqint8= typecast(uint16(ACQINT),'uint8')
%           
%         packet(5)=int8(acqint8(1));
%             % packet(6)=int8(acqint8(2));
%              
%         %packet(11)=int8(ADCCLKPH);
%         packet(13)=int8(MONCHAN);
%         packet(15)=int8(STIMON);
%         packet(17)=int8(STIM_LEVEL);
%         packet(19)=int8(STIM_RATE);
        
           filename=saveconfile(app)
         %   uialert(app.UIFigure,filename,'filename')
             fhand=py.open(filename);
           %  uialert(app.UIFigure,char(fhand),'fhand')
             %system('python control_quabo_matlab7.py')
             %system('python control_quabo_v04acq.py')
           %  uialert(app.UIFigure,char(py.sys.path),'py.sys.path1')
             if count(py.sys.path,[getuserdir '\panoseti\pythonlib\']) == 0
                insert(py.sys.path,int32(0),[getuserdir '\panoseti\pythonlib\']); 
                % uialert(app.UIFigure,char(py.sys.path),'py.sys.path2')
             end
             cmd_payload=py.control_quabo_v04acq.send_acq_parameters(fhand);
            % uialert(app.UIFigure,char(cmd_payload),'cmd_payload')
               
             sendpacket(app,cmd_payload)
      %  sendpacket(app,packet)
        
%              utcshift=7/24; 
%              filename=[app.configdir 'config_d0w0m0q0_' datestr(time+utcshift,'yyyymmdd_HHMMSS') '.txt']
%              app.lastconfigfile=filename;
%              savequaboconfig(filename, quaboconfig);
             saveconfile(app)
     
        end
        
        function results = sentHVs(app)
              quaboconfig=app.currentconfig;
              
              if size(app.sock,1)~=0
             [ia,index]=ismember('HV_0 ',quaboconfig);
             HV_0=str2num(quaboconfig(index,2)) ;
             [ia,index]=ismember('HV_1 ',quaboconfig);
             HV_1=str2num(quaboconfig(index,2)) ;
             [ia,index]=ismember('HV_2 ',quaboconfig);
             HV_2=str2num(quaboconfig(index,2)) ;
             [ia,index]=ismember('HV_3 ',quaboconfig);
             HV_3=str2num(quaboconfig(index,2)) ;
               packet=int8(zeros(64,1));
             packet(1)=int8(2);
             
             Vref=1250.;
             %convert V in int16
                %HV_0=floor((1/(Vref*(10/(65536*158))))*HV_0v);
                 hv1h=dec2hex(HV_0);
              LB=hex2dec(hv1h(3:4));
              MB=hex2dec(hv1h(1:2));
            packet(3)=int8(LB);
            packet(4)=int8((MB));
            %HV_1=floor((1/(Vref*(10/(65536*158))))*HV_1v);
                     hv1h=dec2hex(HV_1);
              LB=hex2dec(hv1h(3:4));
              MB=hex2dec(hv1h(1:2));
            packet(5)=int8((LB));
            packet(6)=int8((MB));
            %HV_2=floor((1/(Vref*(10/(65536*158))))*HV_2v);
                          hv1h=dec2hex(HV_2);
              LB=hex2dec(hv1h(3:4));
              MB=hex2dec(hv1h(1:2));
            packet(7)=int8((LB));
            packet(8)=int8((MB));
            %HV_3=floor((1/(Vref*(10/(65536*158))))*HV_3v);
                          hv1h=dec2hex(HV_3);
              LB=hex2dec(hv1h(3:4));
              MB=hex2dec(hv1h(1:2));
            packet(9)=int8((LB));
            packet(10)=int8((MB));
              
               sendpacket(app,packet)
             
%                utcshift=7/24; 
%              filename=[app.configdir 'config_d0w0m0q0_' datestr(time+utcshift,'yyyymmdd_HHMMSS') '.txt']
%              app.lastconfigfile=filename;
%              savequaboconfig(filename, quaboconfig);
             saveconfile(app)
              else
                   filename=saveconfile(app)
             fhand=py.open(filename);
             %system('python control_quabo_matlab7.py')
             cmd_payload=py.control_quabo_matlab7ihv.send_HV_params(fhand);
              sendpacket(app,cmd_payload)

                  
              end
        end
        
        function results = sentMaroc(app)
%              quaboconfig=app.currentconfig;
%              %dome (0-7), wavelength(0=VIS, 1=IR), module (0-127), quadrant (0-3), maroc (0-3), pixel (0-63) 
%             utcshift=7/24; 
%              filename=[app.configdir 'config_d0w0m0q0_' datestr(time+utcshift,'yyyymmdd_HHMMSS') '.txt']
%              app.lastconfigfile=filename;
%              savequaboconfig(filename, quaboconfig);
             filename=saveconfile(app)
             fhand=py.open(filename);
             %system('python control_quabo_matlab7.py')
             cmd_payload=py.control_quabo_matlab7i.send_maroc_params(fhand);
              sendpacket(app,cmd_payload)


        end
        
        function filename = saveconfile(app)
             utcshift=7/24; 
               %dome (0-7), wavelength(0=VIS, 1=IR), module (0-127), quadrant (0-3), maroc (0-3), pixel (0-63) 
           
             filename=[app.configdir 'config_d0w0m0q0_' datestr(now+utcshift,'yyyymmdd_HHMMSS') '.txt']
             app.lastconfigfile=filename;
             quaboconfig=app.currentconfig;
             savequaboconfig(filename, quaboconfig);
            
        end


        function results = sendmeasbaseline(app)
            panobasedir=[getuserdir 'panoseti\baselines\'];
            if ~exist(panobasedir,'dir')
                mkdir(panobasedir)
            end
            packet=int8(zeros(64,1));
        packet(1)=int8(7);
        
        sendpacket(app,packet)
        %recup baseline
         try
             [packet,SOURCEHOST] = judp('RECEIVE',60000,1064,4000)
               pixels=typecast((packet(5:5+2*256-1)),'uint16');
         %save baseline
            import matlab.io.*
            utcshift=7/24;
               timenow=now;
         filename = ['b_d0w0m0q0_' datestr(timenow+utcshift,'yyyymmdd_HHMMSS') ];
         fullfilename=[ panobasedir filename];

                                
                                fptr = fits.createFile([fullfilename '.fits']);
                                
                                fits.createImg(fptr,'int32',[16 16]);
                                %fitsmode='overwrite';
                                img=int32(reshape(pixels,[16 16]));
                            fits.writeImg(fptr,img)
                                fits.closeFile(fptr);
         
         catch
         packet=[]
                  
         end
       
       
        end
        
        function results = sendreset(app)
            packet=int8(zeros(64,1));
        packet(1)=int8(4);
        
        sendpacket(app,packet)
        end
        
        function results = refreshconfig(app)
            
        app.UITableMaroc.RowName=cellstr({'OTABG_ON',...
                    'DAC_ON',...
                    'SMALL_DAC',...
                    'DAC1',...
                    'DAC2',...
                    'ENB_OUT_ADC',...
                    'INV_START_GRAY',...
                    'RAMP8B',...
                    'RAMP10B',...
                    'CMD_CK_MUX',...
                    'D1_D2',...
                    'INV_DISCR_ADC',...
                    'POLAR_DISCRI',...
                    'ENB3ST',...
                    'VAL_DC_FSB2',...
                    'SW_FSB2_50F',...
                    'SW_FSB2_100F',...
                    'SW_FSB2_100K',...
                    'SW_FSB2_50K',...
                    'VALID_DC_FS',...
                    'CMD_FSB_FSU',...
                    'SW_FSB1_50F',...
                    'SW_FSB1_100F',...
                    'SW_FSB1_100K',...
                    'SW_FSB1_50k',...
                    'SW_FSU_100K',...
                    'SW_FSU_50K',...
                    'SW_FSU_25K',...
                    'SW_FSU_40F',...
                    'SW_FSU_20F',...
                    'H1H2_CHOICE',...
                    'EN_ADC',...
                    'SW_SS_1200F',...
                    'SW_SS_600F',...
                    'SW_SS_300F',...
                    'ON_OFF_SS',...
                    'SWB_BUF_2P',...
                    'SWB_BUF_1P',...
                    'SWB_BUF_500F',...
                    'SWB_BUF_250F',...
                    'CMD_FSB',...
                    'CMD_SS ',...
                    'CMD_FSU',...
                    'MASKOR1_63',...
                    'MASKOR1_62',...
                    'MASKOR1_61',...
                    'MASKOR1_60',...
                    'MASKOR1_59',...
                    'MASKOR1_58',...
                    'MASKOR1_57',...
                    'MASKOR1_56',...
                    'MASKOR1_55',...
                    'MASKOR1_54',...
                    'MASKOR1_53',...
                    'MASKOR1_52',...
                    'MASKOR1_51',...
                    'MASKOR1_50',...
                    'MASKOR1_49',...
                    'MASKOR1_48',...
                    'MASKOR1_47',...
                    'MASKOR1_46',...
                    'MASKOR1_45',...
                    'MASKOR1_44',...
                    'MASKOR1_43',...
                    'MASKOR1_42',...
                    'MASKOR1_41',...
                    'MASKOR1_40',...
                    'MASKOR1_39',...
                    'MASKOR1_38',...
                    'MASKOR1_37',...
                    'MASKOR1_36',...
                    'MASKOR1_35',...
                    'MASKOR1_34',...
                    'MASKOR1_33',...
                    'MASKOR1_32',...
                    'MASKOR1_31',...
                    'MASKOR1_30',...
                    'MASKOR1_29',...
                    'MASKOR1_28',...
                    'MASKOR1_27',...
                    'MASKOR1_26',...
                    'MASKOR1_25',...
                    'MASKOR1_24',...
                    'MASKOR1_23',...
                    'MASKOR1_22',...
                    'MASKOR1_21',...
                    'MASKOR1_20',...
                    'MASKOR1_19',...
                    'MASKOR1_18',...
                    'MASKOR1_17',...
                    'MASKOR1_16',...
                    'MASKOR1_15',...
                    'MASKOR1_14',...
                    'MASKOR1_13',...
                    'MASKOR1_12',...
                    'MASKOR1_11',...
                    'MASKOR1_10',...
                    'MASKOR1_9',...
                    'MASKOR1_8',...
                    'MASKOR1_7',...
                    'MASKOR1_6',...
                    'MASKOR1_5',...
                    'MASKOR1_4',...
                    'MASKOR1_3',...
                    'MASKOR1_2',...
                    'MASKOR1_1',...
                    'MASKOR1_0',...
                    'MASKOR2_63',...
                    'MASKOR2_62',...
                    'MASKOR2_61',...
                    'MASKOR2_60',...
                    'MASKOR2_59',...
                    'MASKOR2_58',...
                    'MASKOR2_57',...
                    'MASKOR2_56',...
                    'MASKOR2_55',...
                    'MASKOR2_54',...
                    'MASKOR2_53',...
                    'MASKOR2_52',...
                    'MASKOR2_51',...
                    'MASKOR2_50',...
                    'MASKOR2_49',...
                    'MASKOR2_48',...
                    'MASKOR2_47',...
                    'MASKOR2_46',...
                    'MASKOR2_45',...
                    'MASKOR2_44',...
                    'MASKOR2_43',...
                    'MASKOR2_42',...
                    'MASKOR2_41',...
                    'MASKOR2_40',...
                    'MASKOR2_39',...
                    'MASKOR2_38',...
                    'MASKOR2_37',...
                    'MASKOR2_36',...
                    'MASKOR2_35',...
                    'MASKOR2_34',...
                    'MASKOR2_33',...
                    'MASKOR2_32',...
                    'MASKOR2_31',...
                    'MASKOR2_30',...
                    'MASKOR2_29',...
                    'MASKOR2_28',...
                    'MASKOR2_27',...
                    'MASKOR2_26',...
                    'MASKOR2_25',...
                    'MASKOR2_24',...
                    'MASKOR2_23',...
                    'MASKOR2_22',...
                    'MASKOR2_21',...
                    'MASKOR2_20',...
                    'MASKOR2_19',...
                    'MASKOR2_18',...
                    'MASKOR2_17',...
                    'MASKOR2_16',...
                    'MASKOR2_15',...
                    'MASKOR2_14',...
                    'MASKOR2_13',...
                    'MASKOR2_12',...
                    'MASKOR2_11',...
                    'MASKOR2_10',...
                    'MASKOR2_9',...
                    'MASKOR2_8',...
                    'MASKOR2_7',...
                    'MASKOR2_6',...
                    'MASKOR2_5',...
                    'MASKOR2_4',...
                    'MASKOR2_3',...
                    'MASKOR2_2',...
                    'MASKOR2_1',...
                    'MASKOR2_0',...
                    'GAIN63',...
                    'GAIN62',...
                    'GAIN61',...
                    'GAIN60',...
                    'GAIN59',...
                    'GAIN58',...
                    'GAIN57',...
                    'GAIN56',...
                    'GAIN55',...
                    'GAIN54',...
                    'GAIN53',...
                    'GAIN52',...
                    'GAIN51',...
                    'GAIN50',...
                    'GAIN49',...
                    'GAIN48',...
                    'GAIN47',...
                    'GAIN46',...
                    'GAIN45',...
                    'GAIN44',...
                    'GAIN43',...
                    'GAIN42',...
                    'GAIN41',...
                    'GAIN40',...
                    'GAIN39',...
                    'GAIN38',...
                    'GAIN37',...
                    'GAIN36',...
                    'GAIN35',...
                    'GAIN34',...
                    'GAIN33',...
                    'GAIN32',...
                    'GAIN31',...
                    'GAIN30',...
                    'GAIN29',...
                    'GAIN28',...
                    'GAIN27',...
                    'GAIN26',...
                    'GAIN25',...
                    'GAIN24',...
                    'GAIN23',...
                    'GAIN22',...
                    'GAIN21',...
                    'GAIN20',...
                    'GAIN19',...
                    'GAIN18',...
                    'GAIN17',...
                    'GAIN16',...
                    'GAIN15',...
                    'GAIN14',...
                    'GAIN13',...
                    'GAIN12',...
                    'GAIN11',...
                    'GAIN10',...
                    'GAIN9',...
                    'GAIN8',...
                    'GAIN7',...
                    'GAIN6',...
                    'GAIN5',...
                    'GAIN4',...
                    'GAIN3',...
                    'GAIN2',...
                    'GAIN1',...
                    'GAIN0',...
                    'CTEST_63',...
                    'CTEST_62',...
                    'CTEST_61',...
                    'CTEST_60',...
                    'CTEST_59',...
                    'CTEST_58',...
                    'CTEST_57',...
                    'CTEST_56',...
                    'CTEST_55',...
                    'CTEST_54',...
                    'CTEST_53',...
                    'CTEST_52',...
                    'CTEST_51',...
                    'CTEST_50',...
                    'CTEST_49',...
                    'CTEST_48',...
                    'CTEST_47',...
                    'CTEST_46',...
                    'CTEST_45',...
                    'CTEST_44',...
                    'CTEST_43',...
                    'CTEST_42',...
                    'CTEST_41',...
                    'CTEST_40',...
                    'CTEST_39',...
                    'CTEST_38',...
                    'CTEST_37',...
                    'CTEST_36',...
                    'CTEST_35',...
                    'CTEST_34',...
                    'CTEST_33',...
                    'CTEST_32',...
                    'CTEST_31',...
                    'CTEST_30',...
                    'CTEST_29',...
                    'CTEST_28',...
                    'CTEST_27',...
                    'CTEST_26',...
                    'CTEST_25',...
                    'CTEST_24',...
                    'CTEST_23',...
                    'CTEST_22',...
                    'CTEST_21',...
                    'CTEST_20',...
                    'CTEST_19',...
                    'CTEST_18',...
                    'CTEST_17',...
                    'CTEST_16',...
                    'CTEST_15',...
                    'CTEST_14',...
                    'CTEST_13',...
                    'CTEST_12',...
                    'CTEST_11',...
                    'CTEST_10',...
                    'CTEST_9',...
                    'CTEST_8',...
                    'CTEST_7',...
                    'CTEST_6',...
                    'CTEST_5',...
                    'CTEST_4',...
                    'CTEST_3',...
                    'CTEST_2',...
                    'CTEST_1',...
                    'CTEST_0'}); %342-44+1 elems
            app.UITableMaroc.Data=cell(299,5);
            Data=cell(299,5);
           
            quaboconfig=importquaboconfig(app.requestedconfigfile)
            app.currentconfig= quaboconfig;
             app.lastconfigfile=app.requestedconfigfile;
            %Maroc
            sz=size(app.UITableMaroc.RowName,1);
            for ii =1:sz
             [ia, index]=ismember(app.UITableMaroc.RowName(ii),quaboconfig);
             Data(ii,1)={char(quaboconfig(index,2))} ;
             Data(ii,2)={char(quaboconfig(index,3))} ;
             Data(ii,3)={char(quaboconfig(index,4))} ;
             Data(ii,4)={char(quaboconfig(index,5))} ;
              Data(ii,5)={false} ;
            end
          app.UITableMaroc.Data=Data;
          %HV tab
          Data=cell(1,5);
          Vref=1250.;
          index=find(ismember(quaboconfig,'HV_0 '));
          HV_0=  (Vref*(10/(65536*158)))*str2num(char(quaboconfig(index,2)));
          Data(1)={HV_0};
          index=find(ismember(quaboconfig,'HV_1 '));
           HV_1=  (Vref*(10/(65536*158)))*str2num(char(quaboconfig(index,2)));
          Data(2)={HV_1};
          index=find(ismember(quaboconfig,'HV_2 '));
           HV_2=  (Vref*(10/(65536*158)))*str2num(char(quaboconfig(index,2)));
          Data(3)={HV_2};
          index=find(ismember(quaboconfig,'HV_3 '));
           HV_4=  (Vref*(10/(65536*158)))*str2num(char(quaboconfig(index,2)));
           Data(4)={HV_4};
          Data(5)={false} ;
          app.UITableHV.Data=Data;
          %ACQ PARAMS:
           Data=cell(1,4);
          index=find(ismember(quaboconfig,'ACQINT '));
          Data(1)={str2num(char(quaboconfig(index,2)))};
          index=find(ismember(quaboconfig,'HOLD1 '));
          Data(2)={str2num(char(quaboconfig(index,2)))};
          index=find(ismember(quaboconfig,'HOLD2 '));
          Data(3)={str2num(char(quaboconfig(index,2)))};
         % index=find(ismember(quaboconfig,'ADCCLKPH '));
         %  Data(4)={str2num(char(quaboconfig(index,2)))};
            index=find(ismember(quaboconfig,'MONCHAN '));
           Data(4)={str2num(char(quaboconfig(index,2)))};
              index=find(ismember(quaboconfig,'STIMON '));
              stim=str2num(char(quaboconfig(index,2)));
              if stim==1
                  val='On';
              else
                  val='Off';
              end
              app.STIMSwitch.Value=val;
          % Data(6)={str2num(char(quaboconfig(index,2)))};
       
          app.UITableAcq.Data=Data;
          %slider sti
             index=find(ismember(quaboconfig,'STIM_LEVEL '));
           app.AmplitpeSlider.Value=(23/255)*str2num(char(quaboconfig(index,2)));
           %STIM RATE
            index=find(ismember(quaboconfig,'STIM_RATE '));
             tabfreq= ['190Hz','380Hz','760Hz','1520Hz','3,040Hz','6,080Hz','12,160Hz','24,320Hz'];
          
             app.DropDownFreq.Value= app.DropDownFreq.Items(str2num(char(quaboconfig(index,2))));
          %acq mode
           index=find(ismember(quaboconfig(:,1),'ACQMODE '));
           hexval=(char(quaboconfig(index,2)));
           indx=findstr(hexval,'x');
           tabmode={'PulseHeight' 'Imaging'};
           app.ModeDropDown.Value=string(tabmode(str2num(hexval(indx+1:indx+3))));

           %thresh
           index=find(ismember(quaboconfig,'DAC1'));
          thresh_hex=(char(quaboconfig(index,2)));
          indx=findstr(thresh_hex,'x');
          if numel(indx)~=0
               thresh_hex2= thresh_hex(3:end);
          else 
               thresh_hex2= thresh_hex;
           end
               threshdec= hex2dec(thresh_hex2);
 
          app.ThreshEditField.Value=threshdec;
          app.ThresholdSlider.Value=threshdec;
          
          Gaintable=cell(9,9);
          Gaintable(:,:)={true};
          app.UITableGain.Data=Gaintable;
           %thresh
           index=find(ismember(quaboconfig,'GAIN0'));
          gain_hex=(char(quaboconfig(index,2)));
            indx=findstr(gain_hex,'x');
          if numel(indx)~=0
               gain_hex2= gain_hex(3:end);
          else 
               gain_hex2= gain_hex;
           end
          gaindec=(hex2dec(gain_hex2));
          app.GainSlider.Value=gaindec;
          app.GainEditField.Value=gaindec;
          
          Masktable=cell(17,17);
          Masktable(:,:)={true};
          app.UITableMask.Data=Masktable;


        end
    end
    
    methods (Access = public)
        
        function results = sendpacket(app,packet)
            %packet identification
            sz=size(packet);
            szp=sz(1);
            
            %% read data
          %  if szp==64 %SCIENCE
                
                if size(app.sock,1)==0
                    port=60000
                    %judp('SEND',port,'192.168.1.10', packet);
                    aa=py.socket.AF_INET
                    ab=py.socket.SOCK_DGRAM
                    sock = py.socket.socket(aa, ab)
                    
                    sock.settimeout(5)
                    
                    
                    host=py.tuple({'', uint32(60000)})
                    sock.bind(host)
                end
                zzb=py.bytes(packet)
                
                dest3=py.tuple({'192.168.1.10', uint32(60000)})
               %  uialert(app.UIFigure,char(dest3),'dest3')
               %  uialert(app.UIFigure,char(zzb),'zzb')
                sock.sendto(zzb,dest3)
                % reply=sock.recvfrom(uint8(64))
                %      test=reply(0);
            end
            
            %test if return packet?
            %read packets
            % port=60000;
            % [packetans,SOURCEHOST]  = judp('RECEIVE',port,64, 10000);
            %convert values
            %  sz=size(packetans);
            % szp=sz(1);
            %read packets
            %  pack=py.tuple({ uint8(64)})


       % end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.UIFigure.Position = [255 40 440 946];
            app.deployfig.Text='>';
            app.configdir=[getuserdir '\panoseti\configs\'];
            app.defautconfigfile=[getuserdir '\panoseti\defaultconfig\' 'quabo_config.txt'];
            if ~exist([getuserdir '\panoseti\'],'dir') 
                mkdir([getuserdir '\panoseti\'])
            end
            if ~exist([getuserdir '\panoseti\configs\'],'dir') 
                mkdir([getuserdir '\panoseti\configs\'])
            end
             if ~exist([getuserdir '\panoseti\defaultconfig\'],'dir') 
                mkdir([getuserdir '\panoseti\defaultconfig\'])
             end


            if ~isfile([getuserdir '\panoseti\defaultconfig\' 'quabo_config.txt'])
                             %need to copy files (config, python code) from install dir into user dir
                     %check install directory
                    if ~exist(['C:\Program Files\UC San Diego\panocommands\application'],'dir') 
                            %ask to select folder of installation
                            installdir= uigetdir('C:\','Select panocommand installation folder with the panocommands.exe file');
                        else
                      installdir='C:\Program Files\UC San Diego\panocommands\application';
                     end
                 if isfile([installdir '\quabo_config.txt'])
                     sourc=[installdir '\quabo_config.txt'];
                     destina=[getuserdir '\panoseti\defaultconfig\' 'quabo_config.txt'];
                        copyfile(sourc, destina)
                 else
                % uialert(app.UIFigure,'Invalid file',[pwd '\quabo_config.txt not found'])
                 end
             end
            if ~exist([getuserdir '\panoseti\pythonlib\'],'dir') 
                mkdir([getuserdir '\panoseti\pythonlib\'])
            end
              if  ~isfile([getuserdir '\panoseti\pythonlib\' '*.py'])
                 if ~exist('installdir','var') 
                   if ~exist(['C:\Program Files\UC San Diego\panocommands\application'],'dir') 
                            %ask to select folder of installation
                               installdir= uigetdir('C:\','Select panocommand installation folder with the panocommands.exe file');
  
                        else
                      installdir='C:\Program Files\UC San Diego\panocommands\application';
                   end
                 end
                   sourc=[installdir '\*.py'];
                     destina=[getuserdir '\panoseti\pythonlib\' ''];
                        copyfile(sourc, destina)
              end
                  
            %app.configdir='/Users/jeromemaire/Documents/SETI/PANOSETI/new/configs/';
          %app.defautconfigfile='C:\Users\jerome\Desktop\udp3\Control_quabo_v02\control_quabo_v02\config\quabo_config.txt';
          %app.defautconfigfile='/Users/jeromemaire/Documents/SETI/PANOSETI/new/testapp/quabo_config.txt'
          app.requestedconfigfile=app.defautconfigfile;
          LoadDefaultConfigButton_3Pushed(app) ; 
          %Just to start the socket inPython:
          %%%sentHVs(app)
          %sentacqparams(app)
          
        end

        % Cell edit callback: UITableHV
        function UITableHVCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices<5
                
                      Vref=1250.;
                tabnames={'HV_0 ','HV_1 ','HV_2 ',...
                    'HV_3 '};
                
                quaboconfig=app.currentconfig;
                datatab=app.UITableHV.Data;
                if cell2mat(datatab(5))==false
                    
                    [ia,index]=ismember(tabnames(indices(2)),quaboconfig);
                    oldvalue=quaboconfig(index,2);
                    if isnumeric(newData) && (newData>0) && (newData<70)
                       % (1/(Vref*(10/(65536*158))))*
                        quaboconfig(index,2)=string(floor(    (1/(Vref*(10/(65536*158))))*newData     ));
                        %quaboconfig(index,2)=string(floor(    (65536/75)*newData     ));
                    else
                        %Data(index)=quaboconfig(index,2);
                        app.UITableHV.Data(indices)=quaboconfig(index,2);
                    end
                else
                     
                    %if checkbox slected apply change to all columns
                    Data=cell(1,5);
                    for ii=1:size(tabnames,2)
                        [ia,index]=ismember(tabnames(ii),quaboconfig);
                         if isnumeric(newData) && (newData>0) && (newData<70)
                              quaboconfig(index,2)=string(floor(    (1/(Vref*(10/(65536*158))))*newData     ));
                        %quaboconfig(index,2)=string(floor(    (65536/75)*newData   ));
                        else
                        %Data(index)=quaboconfig(index,2);
                        app.UITableHV.Data(ii)=quaboconfig(index,2);
                    end
                        Data(ii)={newData};
                    end
                    
                    Data(5)=app.UITableHV.Data(5);
                    app.UITableHV.Data=Data;
                    
                end
                app.currentconfig=quaboconfig;
                
            end
        end

        % Value changed function: AmplitpeSlider
        function AmplitpeSliderValueChanged(app, event)
            value = floor((255/23)*floor(app.AmplitpeSlider.Value));
            
               quaboconfig=app.currentconfig;
            [ia,index]=ismember('STIM_LEVEL ',quaboconfig);
            quaboconfig(index,2)=string(value);
             app.currentconfig=quaboconfig;
            
        end

        % Button pushed function: LoadDefaultConfigButton_3
        function LoadDefaultConfigButton_3Pushed(app, event)
             app.requestedconfigfile= app.defautconfigfile;
       refreshconfig(app)
          
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            %SEND ACQ PARAMS
            sentacqparams(app)
            %turn stim lamp on/off:
                quaboconfig=app.currentconfig;
            %[ia,index]=ismember('STIMON ',quaboconfig);
            index=find(ismember(quaboconfig,'STIMON '));
              stim=str2num(char(quaboconfig(index,2)));
              if stim==1
                  app.Lamp.Color=[0. 1. 0.];
              else
                  app.Lamp.Color=[0.9 0.9 0.9]
              end
           

        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            field = app.ModeDropDown.Value;
            isima=strncmp(field,'Ima',3);
            ispul=strncmp(field,'Pul',3);
            if isima==false && ispul==false
                value='11';
            end
             if isima==true
                value='02'
             end
              if ispul==true
                value='1'
            end
               quaboconfig=app.currentconfig;
            [ia,index]=ismember('ACQMODE ',quaboconfig);
            quaboconfig(index,2)=string(['0x' (value)]);
             app.currentconfig=quaboconfig;
        end

        % Cell edit callback: UITableAcq
        function UITableAcqCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            tabnames={'ACQINT ','HOLD1 ','HOLD2 ',...
                'ADCCLKPH ','MONCHAN ','STIMON '};
            
             quaboconfig=app.currentconfig;
            [ia,index]=ismember(tabnames(indices(2)),quaboconfig);
            quaboconfig(index,2)=string(newData);
                 app.currentconfig=quaboconfig;
        end

        % Callback function
        function HighVoltagesPanelSizeChanged(app, event)
            position = app.HighVoltagesPanel.Position;
            
        end

        % Button pushed function: ApplyHVsButton
        function ApplyHVsButtonPushed(app, event)
            sentHVs(app)
        end

        % Button pushed function: ApplyMarocButton
        function ApplyMarocButtonPushed(app, event)
            sentMaroc(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if size(app.sock,1)~=0
            app.sock.close()
            end
            delete(app)
                
        end

        % Button pushed function: deployfig
        function deployfigPushed(app, event)
            if strncmp(app.deployfig.Text,'<',1)
            app.UIFigure.Position = [255 40 440 946];
            app.deployfig.Text='>';
            else
            app.UIFigure.Position = [255 40 1893 946];
            app.deployfig.Text='<';
            end
        end

        % Cell edit callback: UITableMaroc
        function UITableMarocCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
             quaboconfig=app.currentconfig;
            index=find(ismember(quaboconfig,app.UITableMaroc.RowName(indices(1,1))));
              quaboconfig(index,1+indices(2))=string(newData);
                 app.currentconfig=quaboconfig;
          %   savequaboconfig(filename, quaboconfig);
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            
             saveconfile(app)
        end

        % Button pushed function: LoadLastConfigButton
        function LoadLastConfigButtonPushed(app, event)
            lastconfigfile=  getlatestfile(app.configdir)
            quaboconfig=importquaboconfig([app.configdir lastconfigfile]);
            app.currentconfig= quaboconfig;
            
            app.requestedconfigfile=[app.configdir lastconfigfile];
          refreshconfig(app) ; 
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            %does change needs to be all quadrants?
            tabquad={'AllQuads','Quad1','Quad2','Quad3','Quad4'};
            [ia,index]=ismember(app.ThreshDropDownQuads.Value,tabquad)
            if index ==1 
                indcol=1:4;
            else
                indcol=index-1;
            end
            
             indexdac=find(ismember(app.UITableMaroc.RowName,'DAC1'));
            %update Maroc table with new desired value
            app.UITableMaroc.Data(indexdac,indcol)={['0x' dec2hex(floor(value))]}
            %update current quabo
            quaboconfig=app.currentconfig;
            indexquabo=find(ismember(quaboconfig,'DAC1'));
            quaboconfig(indexquabo,indcol+1)={['0x' dec2hex(floor(value))]};
            app.currentconfig=quaboconfig;
            % update thresh edit field
            app.ThreshEditField.Value=floor(value)
            
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ThreshEditField.Value=floor(changingValue)
            
        end

        % Value changed function: ThreshEditField
        function ThreshEditFieldValueChanged(app, event)
            value = app.ThreshEditField.Value;
            app.ThresholdSlider.Value=value;
              %does change needs to be all quadrants?
            tabquad={'AllQuads','Quad1','Quad2','Quad3','Quad4'};
            [ia,index]=ismember(app.ThreshDropDownQuads.Value,tabquad)
            if index ==1 
                indcol=1:4;
            else
                indcol=index-1;
            end
             indexdac=find(ismember(app.UITableMaroc.RowName,'DAC1'));
            %update Maroc table with new desired value
            app.UITableMaroc.Data(indexdac,indcol)={['0x' dec2hex(floor(value))]}
            %update current quabo
            quaboconfig=app.currentconfig;
            indexquabo=find(ismember(quaboconfig,'DAC1'));
            quaboconfig(indexquabo,indcol+1)={['0x' dec2hex(floor(value))]};
            app.currentconfig=quaboconfig;
            % update thresh edit field
            app.ThresholdSlider.Value=value
            
            
        end

        % Cell edit callback: UITableGain
        function UITableGainCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
           if indices(2)==1 %row selected
              app.UITableGain.Data(indices(1),:)={newData}; 
           end
            if indices(1)==1 %row selected
              app.UITableGain.Data(:,indices(2))={newData}; 
            end
            if (indices(1)==1) && indices(2)==1 %row selected
              app.UITableGain.Data(:,:)={newData}; 
            end
            
        end

        % Cell edit callback: UITableMask
        function UITableMaskCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            %%multi selections:
            if indices(2)==1 && indices(1)~=1%row selected
                app.UITableMask.Data(indices(1),:)={newData};
                indicesg=[indices(1) 1;indices(1) 2;indices(1) 3;indices(1) 4;...
                    indices(1) 5;indices(1) 6;indices(1) 7;indices(1) 8;...
                    indices(1) 9;indices(1) 10;indices(1) 11;indices(1) 12;...
                    indices(1) 13;indices(1) 14;indices(1) 15;indices(1) 16;...
                    ];
                indicesg(:,2)=indicesg(:,2)+1;
            end
            if indices(1)==1 && indices(2)~=1%row selected
                app.UITableMask.Data(:,indices(2))={newData};
                indicesg=[1 indices(2);2 indices(2);3 indices(2);4 indices(2);...
                    5 indices(2);6 indices(2);7 indices(2);8 indices(2);...
                    9 indices(2);10 indices(2);11 indices(2);12 indices(2);...
                    13 indices(2);14 indices(2);15 indices(2);16 indices(2);...
                    ];
                indicesg(:,1)=indicesg(:,1)+1;
            end
            if (indices(1)==1) && indices(2)==1 %row selected
                app.UITableMask.Data(:,:)={newData};
                indicesg=[ones(16,1), (1:16)';1+ones(16,1), (1:16)';2+ones(16,1), (1:16)';3+ones(16,1), (1:16)';...
                    4+ones(16,1), (1:16)';5+ones(16,1), (1:16)';6+ones(16,1), (1:16)';7+ones(16,1), (1:16)'];
                indicesg(:,:)=indicesg(:,:)+1;
            end
            if (indices(1)~=1) && indices(2)~=1 %row selected
                indicesg=indices;
            end
            %change values in the Maroc table:
            %do some pixel mapping:
            %   for gg=1:size(indicesg,1)
            %   linind=sub2ind([8 8],indicesg(gg,1)-1,indicesg(gg,2)-1)-1;
            %   indexmask=find(ismember(app.UITableMaroc.RowName,['MASKOR1_' num2str(linind)]));
            %does change needs to be all quadrants?
            %  tabquad={'AllQuads','Quad1','Quad2','Quad3','Quad4'};
            %  [ia,index]=ismember(app.MaskDropDownQuads.Value,tabquad)
            %             if index ==1
            %                 indcol=1:4;
            %             else
            %                 indcol=index-1;
            %             end
            %update Maroc table with new desired value
            % app.UITableMaroc.Data(indexmask,indcol)={num2str(uint8(newData))};
            %update current quabo
            quaboconfig=app.currentconfig;
            %  indexquabo=find(ismember(quaboconfig,['MASKOR1_' num2str(linind)]));
            %  quaboconfig(indexquabo,indcol+1)={num2str(uint8(newData))};
            %if index==2
            for col=1:8
                indexquabo=find(ismember(quaboconfig(:,1),['CHANMASK_' num2str(col-1) ' ']));
                indexmaska=find(cell2mat(app.UITableMask.Data(2*(col-1)+1+1,2:end)));
                indexmaskb=find(cell2mat(app.UITableMask.Data(2*(col-1)+2+1,2:end)));
                masklinedec=0;
                for iq=1:numel(indexmaska)
                    masklinedec=masklinedec+(2^(indexmaska(iq)-1));
                end
                for iq=1:numel(indexmaskb)
                    masklinedec=masklinedec+(2^(16+indexmaskb(iq)-1));
                end
                quaboconfig(indexquabo,2)=['0x' dec2hex(masklinedec)];
            end
            %chan0=quaboconfig(indexquabo,2)


            app.currentconfig=quaboconfig;
            
        %update current quabo
        % quaboconfig=app.currentconfig;
        
        end

        % Value changed function: GainSlider
        function GainSliderValueChanged(app, event)
            value = floor(app.GainSlider.Value);
            app.GainEditField.Value= value;
            %update Maroc table
            newgain=['0x' dec2hex(floor(value))];
              tabquad={'AllQuads','Quad1','Quad2','Quad3','Quad4'};
            [ia,index]=ismember(app.GainDropDownQuads.Value,tabquad)
            if index ==1 
                indcol=1:4;
            else
                indcol=index-1;
            end
            %
            indlingainpixselected=find(cell2mat(app.UITableGain.Data(2:end,2:end)));
            for gg=1:size(indlingainpixselected,1)
             indexgainMaroctable=find(ismember(app.UITableMaroc.RowName,['GAIN' num2str(indlingainpixselected(gg)-1)]));
             %update Maroc table with new desired value
            app.UITableMaroc.Data(indexgainMaroctable,indcol)={newgain}; 
%               %update Maroc table with new desired value
%             app.UITableMaroc.Data(indexmask,indcol)={num2str(uint8(newData))}; 
             %update current quabo
            quaboconfig=app.currentconfig;
            indexquabo=find(ismember(quaboconfig,['GAIN' num2str(indlingainpixselected(gg)-1)]));
            quaboconfig(indexquabo,indcol+1)={newgain};
            app.currentconfig=quaboconfig;

            end
                      
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            value = app.GainEditField.Value;
             app.GainSlider.Value=value;
              %update Maroc table
            newgain=['0x' dec2hex(floor(value))];
              tabquad={'AllQuads','Quad1','Quad2','Quad3','Quad4'};
            [ia,index]=ismember(app.GainDropDownQuads.Value,tabquad)
            if index ==1 
                indcol=1:4;
            else
                indcol=index-1;
            end
            %
            indlingainpixselected=find(cell2mat(app.UITableGain.Data(2:end,2:end)));
            for gg=1:size(indlingainpixselected,1)
             indexgainMaroctable=find(ismember(app.UITableMaroc.RowName,['GAIN' num2str(indlingainpixselected(gg)-1)]));
             %update Maroc table with new desired value
            app.UITableMaroc.Data(indexgainMaroctable,indcol)={newgain}; 
              %update current quabo
            quaboconfig=app.currentconfig;
            indexquabo=find(ismember(quaboconfig,['GAIN' num2str(indlingainpixselected(gg)-1)]));
            quaboconfig(indexquabo,indcol+1)={newgain};
            app.currentconfig=quaboconfig;

            end
            
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            [file,path] = uigetfile([app.configdir '\*.txt'],'Select your config file');
            if isfile([path file])
            app.requestedconfigfile=[path file];
               quaboconfig=importquaboconfig(app.requestedconfigfile)
            app.currentconfig= quaboconfig;
            %  app.currentconfigfile=app.requestedconfigfile;
            %  app.lastconfigfile=app.requestedconfigfile;
          refreshconfig(app) ; 
            end
        end

        % Value changed function: STIMSwitch
        function STIMSwitchValueChanged(app, event)
            value = app.STIMSwitch.Value;
            if strncmp(value,'On',2)
                val='1';
            else
                val='0';
            end
            quaboconfig=app.currentconfig;
            [ia,index]=ismember('STIMON ',quaboconfig);
            quaboconfig(index,2)=string(val);
             app.currentconfig=quaboconfig;

        end

        % Button pushed function: MeasbaselineButton
        function MeasbaselineButtonPushed(app, event)
            sendmeasbaseline(app)
        end

        % Button pushed function: ResetBoardButton
        function ResetBoardButtonPushed(app, event)
            sendreset(app)
        end

        % Value changed function: DropDownFreq
        function DropDownFreqValueChanged(app, event)
            value = app.DropDownFreq.Value;
          % tabfreq= ['190Hz','380Hz','760Hz','1520Hz','3,040Hz','6,080Hz','12,160Hz','24,320Hz'];
            quaboconfig=app.currentconfig;
            [ia,index]=ismember('STIM_RATE ',quaboconfig);
            quaboconfig(index,2)=string( find(ismember(app.DropDownFreq.Items,value))-1);
             app.currentconfig=quaboconfig;
          
        end

        % Button pushed function: applyMaskButton
        function applyMaskButtonPushed(app, event)
                    filename=saveconfile(app)
             fhand=py.open(filename);
             %system('python control_quabo_matlab7.py')
             cmd_payload=py.control_quabo_v04mask.send_trigger_mask(fhand);
              sendpacket(app,cmd_payload)

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Module1Tab = uitab(app.TabGroup);
            app.Module1Tab.Title = 'Module 1';
            app.Quabo1Panel = uipanel(app.Module1Tab);
            app.Quabo1Panel.Title = 'Quabo 1';
            app.HighVoltagesPanel = uipanel(app.Quabo1Panel);
            app.HighVoltagesPanel.Title = 'High-Voltages:';
            app.UITableHV = uitable(app.HighVoltagesPanel);
            app.UITableHV.ColumnName = {'HV0 (V)'; 'HV1 (V)'; 'HV2 (V)'; 'HV3 (V)'; 'AllColumns'};
            app.UITableHV.RowName = {};
            app.UITableHV.ColumnEditable = true;
            app.UITableHV.CellEditCallback = createCallbackFcn(app, @UITableHVCellEdit, true);
            app.MarocPanel = uipanel(app.Quabo1Panel);
            app.MarocPanel.Title = 'Maroc:';
            app.UITableMaroc = uitable(app.MarocPanel);
            app.UITableMaroc.ColumnName = {'Array1'; 'Array2'; 'Array3'; 'Array4'; 'AllCol'};
            app.UITableMaroc.RowName = {};
            app.UITableMaroc.ColumnEditable = true;
            app.UITableMaroc.CellEditCallback = createCallbackFcn(app, @UITableMarocCellEdit, true);
            app.ThresholdLabel = uilabel(app.MarocPanel);
            app.ThresholdLabel.Text = 'Threshold:';
            app.ThresholdSlider = uislider(app.MarocPanel);
            app.ThresholdSlider.Limits = [0 1023];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.ThreshEditField = uieditfield(app.MarocPanel, 'numeric');
            app.ThreshEditField.Limits = [0 1093];
            app.ThreshEditField.ValueChangedFcn = createCallbackFcn(app, @ThreshEditFieldValueChanged, true);
            app.GainSliderLabel = uilabel(app.MarocPanel);
            app.GainSliderLabel.Text = 'Gain:';
            app.GainSlider = uislider(app.MarocPanel);
            app.GainSlider.Limits = [0 255];
            app.GainSlider.ValueChangedFcn = createCallbackFcn(app, @GainSliderValueChanged, true);
            app.GainSlider.Value = 127;
            app.ThreshDropDownQuads = uidropdown(app.MarocPanel);
            app.ThreshDropDownQuads.Items = {'AllQuads', 'Quad1', 'Quad2', 'Quad3', 'Quad4'};
            app.ThreshDropDownQuads.Value = 'AllQuads';
            app.GainEditField = uieditfield(app.MarocPanel, 'numeric');
            app.GainEditField.Limits = [0 1093];
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.GainDropDownQuads = uidropdown(app.MarocPanel);
            app.GainDropDownQuads.Items = {'AllQuads', 'Quad1', 'Quad2', 'Quad3', 'Quad4'};
            app.GainDropDownQuads.Value = 'AllQuads';
            app.UITableGain = uitable(app.MarocPanel);
            app.UITableGain.ColumnName = {'All'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'};
            app.UITableGain.RowName = {'All'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'};
            app.UITableGain.ColumnEditable = true;
            app.UITableGain.CellEditCallback = createCallbackFcn(app, @UITableGainCellEdit, true);
            app.UITableMask = uitable(app.MarocPanel);
            app.UITableMask.ColumnName = {'All'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'};
            app.UITableMask.RowName = {'All'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'};
            app.UITableMask.ColumnEditable = true;
            app.UITableMask.CellEditCallback = createCallbackFcn(app, @UITableMaskCellEdit, true);
            app.MasksLabel = uilabel(app.MarocPanel);
            app.MasksLabel.Tooltip = {'Uncheck cell to mask pixel(s)'; ''};
            app.MasksLabel.Text = 'Masks:';
            app.applyMaskButton = uibutton(app.MarocPanel, 'push');
            app.applyMaskButton.ButtonPushedFcn = createCallbackFcn(app, @applyMaskButtonPushed, true);
            app.applyMaskButton.Text = 'apply Mask';
            app.ApplyMarocButton = uibutton(app.Quabo1Panel, 'push');
            app.ApplyMarocButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyMarocButtonPushed, true);
            app.ApplyMarocButton.Text = 'Apply Maroc';
            app.ApplyHVsButton = uibutton(app.Quabo1Panel, 'push');
            app.ApplyHVsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyHVsButtonPushed, true);
            app.ApplyHVsButton.Text = 'Apply HVs';
            app.AcquisitionsPanel = uipanel(app.Quabo1Panel);
            app.AcquisitionsPanel.Title = 'Acquisitions:';
            app.UITableAcq = uitable(app.AcquisitionsPanel);
            app.UITableAcq.ColumnName = {'ACQINT'; 'HOLD1'; 'HOLD2'; 'MONCHAN'};
            app.UITableAcq.RowName = {};
            app.UITableAcq.ColumnEditable = true;
            app.UITableAcq.CellEditCallback = createCallbackFcn(app, @UITableAcqCellEdit, true);
            app.ModeLabel = uilabel(app.AcquisitionsPanel);
            app.ModeLabel.Text = 'Mode:';
            app.ModeDropDown = uidropdown(app.AcquisitionsPanel);
            app.ModeDropDown.Items = {'PulseHeight', 'Imaging', 'PH (no base corr.)', 'Disable'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'PulseHeight';
            app.StimLabel = uilabel(app.AcquisitionsPanel);
            app.StimLabel.Text = 'Stim:';
            app.Lamp = uilamp(app.AcquisitionsPanel);
            app.STIMSwitch = uiswitch(app.AcquisitionsPanel, 'toggle');
            app.STIMSwitch.ValueChangedFcn = createCallbackFcn(app, @STIMSwitchValueChanged, true);
            app.AmplitpeSliderLabel = uilabel(app.AcquisitionsPanel);
            app.AmplitpeSliderLabel.Text = 'Amplit (pe):';
            app.AmplitpeSlider = uislider(app.AcquisitionsPanel);
            app.AmplitpeSlider.Limits = [0 23];
            app.AmplitpeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitpeSliderValueChanged, true);
            app.AmplitpeSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23];
            app.MeasbaselineButton = uibutton(app.AcquisitionsPanel, 'push');
            app.MeasbaselineButton.ButtonPushedFcn = createCallbackFcn(app, @MeasbaselineButtonPushed, true);
            app.MeasbaselineButton.Text = 'Meas. baseline';
            app.ResetBoardButton = uibutton(app.AcquisitionsPanel, 'push');
            app.ResetBoardButton.ButtonPushedFcn = createCallbackFcn(app, @ResetBoardButtonPushed, true);
            app.ResetBoardButton.Text = 'Reset Board';
            app.DropDownFreq = uidropdown(app.AcquisitionsPanel);
            app.DropDownFreq.Items = {'190Hz', '380Hz', '760Hz', '1520Hz', '3040Hz        ', '6080Hz', '12160Hz       ', '24320Hz'};
            app.DropDownFreq.ValueChangedFcn = createCallbackFcn(app, @DropDownFreqValueChanged, true);
            app.DropDownFreq.Value = '760Hz';
            app.ApplyButton = uibutton(app.Quabo1Panel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.Module2Tab = uitab(app.TabGroup);
            app.Module2Tab.Title = 'Module 2';
            app.Quabo1Panel_2 = uipanel(app.Module2Tab);
            app.Quabo1Panel_2.Title = 'Quabo 1';
            app.HighVoltagesPanel_2 = uipanel(app.Quabo1Panel_2);
            app.HighVoltagesPanel_2.Title = 'High-Voltages:';
            app.UITableHV_2 = uitable(app.HighVoltagesPanel_2);
            app.UITableHV_2.ColumnName = {'HV0 (V)'; 'HV1 (V)'; 'HV2 (V)'; 'HV3 (V)'; 'AllColumns'};
            app.UITableHV_2.RowName = {};
            app.UITableHV_2.ColumnEditable = true;
            app.MarocPanel_2 = uipanel(app.Quabo1Panel_2);
            app.MarocPanel_2.Title = 'Maroc:';
            app.UITableMaroc_2 = uitable(app.MarocPanel_2);
            app.UITableMaroc_2.ColumnName = {'Array1'; 'Array2'; 'Array3'; 'Array4'; 'AllCol'};
            app.UITableMaroc_2.RowName = {};
            app.UITableMaroc_2.ColumnEditable = true;
            app.ThresholdSlider_2Label = uilabel(app.MarocPanel_2);
            app.ThresholdSlider_2Label.Text = 'Threshold:';
            app.ThresholdSlider_2 = uislider(app.MarocPanel_2);
            app.ThresholdSlider_2.Limits = [0 1023];
            app.ThreshEditField_2 = uieditfield(app.MarocPanel_2, 'numeric');
            app.ThreshEditField_2.Limits = [0 1093];
            app.GainSlider_2Label = uilabel(app.MarocPanel_2);
            app.GainSlider_2Label.Text = 'Gain:';
            app.GainSlider_2 = uislider(app.MarocPanel_2);
            app.GainSlider_2.Limits = [0 255];
            app.GainSlider_2.Value = 127;
            app.ThreshDropDownQuads_2 = uidropdown(app.MarocPanel_2);
            app.ThreshDropDownQuads_2.Items = {'AllQuads', 'Quad1', 'Quad2', 'Quad3', 'Quad4'};
            app.ThreshDropDownQuads_2.Value = 'AllQuads';
            app.GainEditField_2 = uieditfield(app.MarocPanel_2, 'numeric');
            app.GainEditField_2.Limits = [0 1093];
            app.GainDropDownQuads_2 = uidropdown(app.MarocPanel_2);
            app.GainDropDownQuads_2.Items = {'AllQuads', 'Quad1', 'Quad2', 'Quad3', 'Quad4'};
            app.GainDropDownQuads_2.Value = 'AllQuads';
            app.UITableGain_2 = uitable(app.MarocPanel_2);
            app.UITableGain_2.ColumnName = {'All'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'};
            app.UITableGain_2.RowName = {'All'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'};
            app.UITableGain_2.ColumnEditable = true;
            app.UITableMask_2 = uitable(app.MarocPanel_2);
            app.UITableMask_2.ColumnName = {'All'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'};
            app.UITableMask_2.RowName = {'All'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'};
            app.UITableMask_2.ColumnEditable = true;
            app.MasksLabel_2 = uilabel(app.MarocPanel_2);
            app.MasksLabel_2.Tooltip = {'Uncheck cell to mask pixel(s)'; ''};
            app.MasksLabel_2.Text = 'Masks:';
            app.applyMaskButton_2 = uibutton(app.MarocPanel_2, 'push');
            app.applyMaskButton_2.Text = 'apply Mask';
            app.ApplyMarocButton_2 = uibutton(app.Quabo1Panel_2, 'push');
            app.ApplyMarocButton_2.Text = 'Apply Maroc';
            app.ApplyHVsButton_2 = uibutton(app.Quabo1Panel_2, 'push');
            app.ApplyHVsButton_2.Text = 'Apply HVs';
            app.AcquisitionsPanel_2 = uipanel(app.Quabo1Panel_2);
            app.AcquisitionsPanel_2.Title = 'Acquisitions:';
            app.UITableAcq_2 = uitable(app.AcquisitionsPanel_2);
            app.UITableAcq_2.ColumnName = {'ACQINT'; 'HOLD1'; 'HOLD2'; 'MONCHAN'};
            app.UITableAcq_2.RowName = {};
            app.UITableAcq_2.ColumnEditable = true;
            app.ModeDropDown_2Label = uilabel(app.AcquisitionsPanel_2);
            app.ModeDropDown_2Label.Text = 'Mode:';
            app.ModeDropDown_2 = uidropdown(app.AcquisitionsPanel_2);
            app.ModeDropDown_2.Items = {'PulseHeight', 'Imaging', 'PH (no base corr.)', 'Disable'};
            app.ModeDropDown_2.Value = 'PulseHeight';
            app.StimLabel_2 = uilabel(app.AcquisitionsPanel_2);
            app.StimLabel_2.Text = 'Stim:';
            app.Lamp_2 = uilamp(app.AcquisitionsPanel_2);
            app.STIMSwitch_2 = uiswitch(app.AcquisitionsPanel_2, 'toggle');
            app.AmplitpeSlider_2Label = uilabel(app.AcquisitionsPanel_2);
            app.AmplitpeSlider_2Label.Text = 'Amplit (pe):';
            app.AmplitpeSlider_2 = uislider(app.AcquisitionsPanel_2);
            app.AmplitpeSlider_2.Limits = [0 23];
            app.AmplitpeSlider_2.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23];
            app.MeasbaselineButton_2 = uibutton(app.AcquisitionsPanel_2, 'push');
            app.MeasbaselineButton_2.Text = 'Meas. baseline';
            app.ResetBoardButton_2 = uibutton(app.AcquisitionsPanel_2, 'push');
            app.ResetBoardButton_2.Text = 'Reset Board';
            app.DropDownFreq_2 = uidropdown(app.AcquisitionsPanel_2);
            app.DropDownFreq_2.Items = {'190Hz', '380Hz', '760Hz', '1520Hz', '3040Hz        ', '6080Hz', '12160Hz       ', '24320Hz'};
            app.DropDownFreq_2.Value = '760Hz';
            app.ApplyButton_2 = uibutton(app.Quabo1Panel_2, 'push');
            app.ApplyButton_2.Text = 'Apply';
            app.Module3Tab = uitab(app.TabGroup);
            app.Module3Tab.Title = 'Module 3';
            app.Module4Tab = uitab(app.TabGroup);
            app.Module4Tab.Title = 'Module 4';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'PANOlogo0.png';
            app.Button.Text = '';
            app.CommandsLabel = uilabel(app.UIFigure);
            app.CommandsLabel.Text = 'Commands';
            app.LoadDefaultConfigButton_3 = uibutton(app.UIFigure, 'push');
            app.LoadDefaultConfigButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadDefaultConfigButton_3Pushed, true);
            app.LoadDefaultConfigButton_3.Text = 'Load Default Config';
            app.LoadLastConfigButton = uibutton(app.UIFigure, 'push');
            app.LoadLastConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLastConfigButtonPushed, true);
            app.LoadLastConfigButton.Text = 'Load Last Config';
            app.SaveConfigButton = uibutton(app.UIFigure, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save Config';
            app.deployfig = uibutton(app.UIFigure, 'push');
            app.deployfig.ButtonPushedFcn = createCallbackFcn(app, @deployfigPushed, true);
            app.deployfig.Text = '<';
            app.LoadConfigButton = uibutton(app.UIFigure, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config...';
        end
    end

    methods (Access = public)

        % Construct app
        function app = panocommands4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end