classdef panocommandsb < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Module1Tab               matlab.ui.container.Tab
        Quabo1Panel              matlab.ui.container.Panel
        AcquisitionsPanel        matlab.ui.container.Panel
        UITableAcq               matlab.ui.control.Table
        STIMLevelSliderLabel     matlab.ui.control.Label
        STIMLevelSlider          matlab.ui.control.Slider
        ModeLabel                matlab.ui.control.Label
        ModeDropDown             matlab.ui.control.DropDown
        ApplyButton              matlab.ui.control.Button
        HighVoltagesPanel        matlab.ui.container.Panel
        ApplyHVsButton           matlab.ui.control.Button
        MarocPanel               matlab.ui.container.Panel
        UITableMaroc             matlab.ui.control.Table
        ApplyMarocButton         matlab.ui.control.Button
        UITableHV                matlab.ui.control.Table
        Module2Tab               matlab.ui.container.Tab
        Module3Tab               matlab.ui.container.Tab
        Module4Tab               matlab.ui.container.Tab
        Button                   matlab.ui.control.Button
        CommandsLabel            matlab.ui.control.Label
        LoadDefaultConfigButton  matlab.ui.control.Button
        LoadLastConfigButton     matlab.ui.control.Button
        SaveConfigButton         matlab.ui.control.Button
        deployfig                matlab.ui.control.Button
    end

    
    properties (Access = public)
        currentconfig; % Description
        lastconfigfile;
        defautconfigfile;
        configdir;
        sock;
    end
    
    methods (Access = private)
        
        function results = sentacqparams(app)
             quaboconfig=app.currentconfig;
            [ia,index]=ismember('ACQMODE ',quaboconfig);
            aqm=char(quaboconfig(index,2));
            indx=strfind(aqm,'x');
             ACQMODE=str2num(aqm(indx+1:indx+2)) ;
        %ACQMODE = 1 % *= 0x01 FOR PH, 0x02 FOR IM
        
      %  ACQINT = 255 	%*frame interval for IMage mode, x1.024us
         [ia,index]=ismember('ACQINT ',quaboconfig);
             ACQINT=str2num(quaboconfig(index,2)) ;

      %  HOLD1 = 0	%*time after trigger that hold1 is asserted, x5ns, 0 to 15
         [ia,index]=ismember('HOLD1 ',quaboconfig);
             HOLD1=str2num(quaboconfig(index,2)) ;

       % HOLD2 = 0	%*time after trigger that hold1 is asserted, x5ns, 0 to 15
         [ia,index]=ismember('HOLD2 ',quaboconfig);
             HOLD2=str2num(quaboconfig(index,2)) ;

      %  ADCCLKPH = 5	%*phase of PH ADC clock, in 10ns steps, 0 to 7
         [ia,index]=ismember('ADCCLKPH ',quaboconfig);
             ADCCLKPH=str2num(quaboconfig(index,2)) ;

       % MONCHAN = 70	%*Channel to stop the analog mux at for monitor points
         [ia,index]=ismember('MONCHAN ',quaboconfig);
            MONCHAN=str2num(quaboconfig(index,2)) ;

        %STIMON = 1
        [ia,index]=ismember('STIMON ',quaboconfig);
            STIMON=str2num(quaboconfig(index,2)) ;
       
       % STIM_LEVEL = 16%*0 to 255
        [ia,index]=ismember('STIM_LEVEL ',quaboconfig);
            STIM_LEVEL=str2num(quaboconfig(index,2)) ;
        
        packet=int8(zeros(64,1));
        packet(1)=int8(3);
        %hv1=20000;
        % hv1h=dec2hex(ACQMODE)
        % LB=hv1h(3:4)
        % MB=hv1h(1:2)
        packet(3)=int8(1);
        
        packet(5)=int8(ACQINT);
        packet(11)=int8(ADCCLKPH);
        packet(13)=int8(MONCHAN);
        packet(15)=int8(STIMON);
        packet(17)=int8(STIM_LEVEL);
        
        sendpacket(app,packet)
        
%              utcshift=7/24; 
%              filename=[app.configdir 'config_d0w0m0q0_' datestr(time+utcshift,'yyyymmdd_HHMMSS') '.txt']
%              app.lastconfigfile=filename;
%              savequaboconfig(filename, quaboconfig);
             saveconfile(app)
     
        end
        
        function results = sentHVs(app)
              quaboconfig=app.currentconfig;
              
              if size(app.sock,1)==0
             [ia,index]=ismember('HV_0 ',quaboconfig);
             HV_0=str2num(quaboconfig(index,2)) ;
             [ia,index]=ismember('HV_1 ',quaboconfig);
             HV_1=str2num(quaboconfig(index,2)) ;
             [ia,index]=ismember('HV_2 ',quaboconfig);
             HV_2=str2num(quaboconfig(index,2)) ;
             [ia,index]=ismember('HV_3 ',quaboconfig);
             HV_3=str2num(quaboconfig(index,2)) ;
               packet=int8(zeros(64,1));
             packet(1)=int8(2);
             
             Vref=1250.;
             %convert V in int16
                %HV_0=floor((1/(Vref*(10/(65536*158))))*HV_0v);
                 hv1h=dec2hex(HV_0);
              LB=hex2dec(hv1h(3:4));
              MB=hex2dec(hv1h(1:2));
            packet(3)=int8(LB);
            packet(4)=int8((MB));
            %HV_1=floor((1/(Vref*(10/(65536*158))))*HV_1v);
                     hv1h=dec2hex(HV_1);
              LB=hex2dec(hv1h(3:4));
              MB=hex2dec(hv1h(1:2));
            packet(5)=int8((LB));
            packet(6)=int8((MB));
            %HV_2=floor((1/(Vref*(10/(65536*158))))*HV_2v);
                          hv1h=dec2hex(HV_2);
              LB=hex2dec(hv1h(3:4));
              MB=hex2dec(hv1h(1:2));
            packet(7)=int8((LB));
            packet(8)=int8((MB));
            %HV_3=floor((1/(Vref*(10/(65536*158))))*HV_3v);
                          hv1h=dec2hex(HV_3);
              LB=hex2dec(hv1h(3:4));
              MB=hex2dec(hv1h(1:2));
            packet(9)=int8((LB));
            packet(10)=int8((MB));
              
               sendpacket(app,packet)
             
%                utcshift=7/24; 
%              filename=[app.configdir 'config_d0w0m0q0_' datestr(time+utcshift,'yyyymmdd_HHMMSS') '.txt']
%              app.lastconfigfile=filename;
%              savequaboconfig(filename, quaboconfig);
             saveconfile(app)
              else
                   filename=saveconfile(app)
             fhand=py.open(filename);
             %system('python control_quabo_matlab7.py')
             cmd_payload=py.control_quabo_matlab7ihv.send_HV_params(fhand);
              sendpacket(app,cmd_payload)

                  
              end
        end
        
        function results = sentMaroc(app)
%              quaboconfig=app.currentconfig;
%              %dome (0-7), wavelength(0=VIS, 1=IR), module (0-127), quadrant (0-3), maroc (0-3), pixel (0-63) 
%             utcshift=7/24; 
%              filename=[app.configdir 'config_d0w0m0q0_' datestr(time+utcshift,'yyyymmdd_HHMMSS') '.txt']
%              app.lastconfigfile=filename;
%              savequaboconfig(filename, quaboconfig);
             filename=saveconfile(app)
             fhand=py.open(filename);
             %system('python control_quabo_matlab7.py')
             cmd_payload=py.control_quabo_matlab7i.send_maroc_params(fhand);
              sendpacket(app,cmd_payload)


        end
        
        function filename = saveconfile(app)
             utcshift=7/24; 
               %dome (0-7), wavelength(0=VIS, 1=IR), module (0-127), quadrant (0-3), maroc (0-3), pixel (0-63) 
           
             filename=[app.configdir 'config_d0w0m0q0_' datestr(now+utcshift,'yyyymmdd_HHMMSS') '.txt']
             app.lastconfigfile=filename;
             quaboconfig=app.currentconfig;
             savequaboconfig(filename, quaboconfig);
            
        end
        
      
    end
    
    methods (Access = public)
        
        function results = sendpacket(app,packet)
            %packet identification
            sz=size(packet);
            szp=sz(1);
            
            %% read data
          %  if szp==64 %SCIENCE
                
                if size(app.sock,1)==0
                    port=60000
                    %judp('SEND',port,'192.168.1.10', packet);
                    aa=py.socket.AF_INET
                    ab=py.socket.SOCK_DGRAM
                    sock = py.socket.socket(aa, ab)
                    
                    sock.settimeout(5)
                    
                    
                    host=py.tuple({'', uint32(60000)})
                    sock.bind(host)
                end
                zzb=py.bytes(packet)
                
                dest3=py.tuple({'192.168.1.10', uint32(60000)})
                sock.sendto(zzb,dest3)
                % reply=sock.recvfrom(uint8(64))
                %      test=reply(0);
            end
            
            %test if return packet?
            %read packets
            % port=60000;
            % [packetans,SOURCEHOST]  = judp('RECEIVE',port,64, 10000);
            %convert values
            %  sz=size(packetans);
            % szp=sz(1);
            %read packets
            %  pack=py.tuple({ uint8(64)})


       % end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.UIFigure.Position = [255 40 440 946];
            app.deployfig.Text='>';
            app.configdir='C:\DATA\configs\';
            app.defautconfigfile='C:\DATA\configs\default\quabo_config.txt';
        
            %app.configdir='/Users/jeromemaire/Documents/SETI/PANOSETI/new/configs/';
          %app.defautconfigfile='C:\Users\jerome\Desktop\udp3\Control_quabo_v02\control_quabo_v02\config\quabo_config.txt';
          %app.defautconfigfile='/Users/jeromemaire/Documents/SETI/PANOSETI/new/testapp/quabo_config.txt'
          LoadDefaultConfigButtonPushed(app) ; 
          %Just to start the socket inPython:
          sentHVs(app)
          %sentacqparams(app)
          
        end

        % Cell edit callback: UITableHV
        function UITableHVCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if indices<5
                
                      Vref=1250.;
                tabnames={'HV_0 ','HV_1 ','HV_2 ',...
                    'HV_3 '};
                
                quaboconfig=app.currentconfig;
                datatab=app.UITableHV.Data;
                if cell2mat(datatab(5))==false
                    
                    [ia,index]=ismember(tabnames(indices(2)),quaboconfig);
                    oldvalue=quaboconfig(index,2);
                    if isnumeric(newData) && (newData>0) && (newData<70)
                        quaboconfig(index,2)=string(floor(    (65536/75)*newData     ));
                    else
                        %Data(index)=quaboconfig(index,2);
                        app.UITableHV.Data(indices)=quaboconfig(index,2);
                    end
                else
                     
                    %if checkbox slected apply change to all columns
                    Data=cell(1,5);
                    for ii=1:size(tabnames,2)
                        [ia,index]=ismember(tabnames(indices(2)),quaboconfig);
                         if isnumeric(newData) && (newData>0) && (newData<70)
                        quaboconfig(index,2)=string(floor(    (65536/75)*newData   ));
                        else
                        %Data(index)=quaboconfig(index,2);
                        app.UITableHV.Data(ii)=quaboconfig(index,2);
                    end
                        Data(ii)={newData};
                    end
                    
                    Data(5)=app.UITableHV.Data(5);
                    app.UITableHV.Data=Data;
                    
                end
                app.currentconfig=quaboconfig;
                
            end
        end

        % Value changed function: STIMLevelSlider
        function STIMLevelSliderValueChanged(app, event)
            value = app.STIMLevelSlider.Value;
            
               quaboconfig=app.currentconfig;
            [ia,index]=ismember('STIM_LEVEL ',quaboconfig);
            quaboconfig(index,2)=string(value);
             app.currentconfig=quaboconfig;
            
        end

        % Button pushed function: LoadDefaultConfigButton
        function LoadDefaultConfigButtonPushed(app, event)
        app.UITableMaroc.RowName=cellstr({'OTABG_ON',...
                    'DAC_ON',...
                    'SMALL_DAC',...
                    'DAC1',...
                    'DAC2',...
                    'ENB_OUT_ADC',...
                    'INV_START_GRAY',...
                    'RAMP8B',...
                    'RAMP10B',...
                    'CMD_CK_MUX',...
                    'D1_D2',...
                    'INV_DISCR_ADC',...
                    'POLAR_DISCRI',...
                    'ENB3ST',...
                    'VAL_DC_FSB2',...
                    'SW_FSB2_50F',...
                    'SW_FSB2_100F',...
                    'SW_FSB2_100K',...
                    'SW_FSB2_50K',...
                    'VALID_DC_FS',...
                    'CMD_FSB_FSU',...
                    'SW_FSB1_50F',...
                    'SW_FSB1_100F',...
                    'SW_FSB1_100K',...
                    'SW_FSB1_50k',...
                    'SW_FSU_100K',...
                    'SW_FSU_50K',...
                    'SW_FSU_25K',...
                    'SW_FSU_40F',...
                    'SW_FSU_20F',...
                    'H1H2_CHOICE',...
                    'EN_ADC',...
                    'SW_SS_1200F',...
                    'SW_SS_600F',...
                    'SW_SS_300F',...
                    'ON_OFF_SS',...
                    'SWB_BUF_2P',...
                    'SWB_BUF_1P',...
                    'SWB_BUF_500F',...
                    'SWB_BUF_250F',...
                    'CMD_FSB',...
                    'CMD_SS ',...
                    'CMD_FSU',...
                    'MASKOR1_63',...
                    'MASKOR1_62',...
                    'MASKOR1_61',...
                    'MASKOR1_60',...
                    'MASKOR1_59',...
                    'MASKOR1_58',...
                    'MASKOR1_57',...
                    'MASKOR1_56',...
                    'MASKOR1_55',...
                    'MASKOR1_54',...
                    'MASKOR1_53',...
                    'MASKOR1_52',...
                    'MASKOR1_51',...
                    'MASKOR1_50',...
                    'MASKOR1_49',...
                    'MASKOR1_48',...
                    'MASKOR1_47',...
                    'MASKOR1_46',...
                    'MASKOR1_45',...
                    'MASKOR1_44',...
                    'MASKOR1_43',...
                    'MASKOR1_42',...
                    'MASKOR1_41',...
                    'MASKOR1_40',...
                    'MASKOR1_39',...
                    'MASKOR1_38',...
                    'MASKOR1_37',...
                    'MASKOR1_36',...
                    'MASKOR1_35',...
                    'MASKOR1_34',...
                    'MASKOR1_33',...
                    'MASKOR1_32',...
                    'MASKOR1_31',...
                    'MASKOR1_30',...
                    'MASKOR1_29',...
                    'MASKOR1_28',...
                    'MASKOR1_27',...
                    'MASKOR1_26',...
                    'MASKOR1_25',...
                    'MASKOR1_24',...
                    'MASKOR1_23',...
                    'MASKOR1_22',...
                    'MASKOR1_21',...
                    'MASKOR1_20',...
                    'MASKOR1_19',...
                    'MASKOR1_18',...
                    'MASKOR1_17',...
                    'MASKOR1_16',...
                    'MASKOR1_15',...
                    'MASKOR1_14',...
                    'MASKOR1_13',...
                    'MASKOR1_12',...
                    'MASKOR1_11',...
                    'MASKOR1_10',...
                    'MASKOR1_9',...
                    'MASKOR1_8',...
                    'MASKOR1_7',...
                    'MASKOR1_6',...
                    'MASKOR1_5',...
                    'MASKOR1_4',...
                    'MASKOR1_3',...
                    'MASKOR1_2',...
                    'MASKOR1_1',...
                    'MASKOR1_0',...
                    'MASKOR2_63',...
                    'MASKOR2_62',...
                    'MASKOR2_61',...
                    'MASKOR2_60',...
                    'MASKOR2_59',...
                    'MASKOR2_58',...
                    'MASKOR2_57',...
                    'MASKOR2_56',...
                    'MASKOR2_55',...
                    'MASKOR2_54',...
                    'MASKOR2_53',...
                    'MASKOR2_52',...
                    'MASKOR2_51',...
                    'MASKOR2_50',...
                    'MASKOR2_49',...
                    'MASKOR2_48',...
                    'MASKOR2_47',...
                    'MASKOR2_46',...
                    'MASKOR2_45',...
                    'MASKOR2_44',...
                    'MASKOR2_43',...
                    'MASKOR2_42',...
                    'MASKOR2_41',...
                    'MASKOR2_40',...
                    'MASKOR2_39',...
                    'MASKOR2_38',...
                    'MASKOR2_37',...
                    'MASKOR2_36',...
                    'MASKOR2_35',...
                    'MASKOR2_34',...
                    'MASKOR2_33',...
                    'MASKOR2_32',...
                    'MASKOR2_31',...
                    'MASKOR2_30',...
                    'MASKOR2_29',...
                    'MASKOR2_28',...
                    'MASKOR2_27',...
                    'MASKOR2_26',...
                    'MASKOR2_25',...
                    'MASKOR2_24',...
                    'MASKOR2_23',...
                    'MASKOR2_22',...
                    'MASKOR2_21',...
                    'MASKOR2_20',...
                    'MASKOR2_19',...
                    'MASKOR2_18',...
                    'MASKOR2_17',...
                    'MASKOR2_16',...
                    'MASKOR2_15',...
                    'MASKOR2_14',...
                    'MASKOR2_13',...
                    'MASKOR2_12',...
                    'MASKOR2_11',...
                    'MASKOR2_10',...
                    'MASKOR2_9',...
                    'MASKOR2_8',...
                    'MASKOR2_7',...
                    'MASKOR2_6',...
                    'MASKOR2_5',...
                    'MASKOR2_4',...
                    'MASKOR2_3',...
                    'MASKOR2_2',...
                    'MASKOR2_1',...
                    'MASKOR2_0',...
                    'GAIN63',...
                    'GAIN62',...
                    'GAIN61',...
                    'GAIN60',...
                    'GAIN59',...
                    'GAIN58',...
                    'GAIN57',...
                    'GAIN56',...
                    'GAIN55',...
                    'GAIN54',...
                    'GAIN53',...
                    'GAIN52',...
                    'GAIN51',...
                    'GAIN50',...
                    'GAIN49',...
                    'GAIN48',...
                    'GAIN47',...
                    'GAIN46',...
                    'GAIN45',...
                    'GAIN44',...
                    'GAIN43',...
                    'GAIN42',...
                    'GAIN41',...
                    'GAIN40',...
                    'GAIN39',...
                    'GAIN38',...
                    'GAIN37',...
                    'GAIN36',...
                    'GAIN35',...
                    'GAIN34',...
                    'GAIN33',...
                    'GAIN32',...
                    'GAIN31',...
                    'GAIN30',...
                    'GAIN29',...
                    'GAIN28',...
                    'GAIN27',...
                    'GAIN26',...
                    'GAIN25',...
                    'GAIN24',...
                    'GAIN23',...
                    'GAIN22',...
                    'GAIN21',...
                    'GAIN20',...
                    'GAIN19',...
                    'GAIN18',...
                    'GAIN17',...
                    'GAIN16',...
                    'GAIN15',...
                    'GAIN14',...
                    'GAIN13',...
                    'GAIN12',...
                    'GAIN11',...
                    'GAIN10',...
                    'GAIN9',...
                    'GAIN8',...
                    'GAIN7',...
                    'GAIN6',...
                    'GAIN5',...
                    'GAIN4',...
                    'GAIN3',...
                    'GAIN2',...
                    'GAIN1',...
                    'GAIN0',...
                    'CTEST_63',...
                    'CTEST_62',...
                    'CTEST_61',...
                    'CTEST_60',...
                    'CTEST_59',...
                    'CTEST_58',...
                    'CTEST_57',...
                    'CTEST_56',...
                    'CTEST_55',...
                    'CTEST_54',...
                    'CTEST_53',...
                    'CTEST_52',...
                    'CTEST_51',...
                    'CTEST_50',...
                    'CTEST_49',...
                    'CTEST_48',...
                    'CTEST_47',...
                    'CTEST_46',...
                    'CTEST_45',...
                    'CTEST_44',...
                    'CTEST_43',...
                    'CTEST_42',...
                    'CTEST_41',...
                    'CTEST_40',...
                    'CTEST_39',...
                    'CTEST_38',...
                    'CTEST_37',...
                    'CTEST_36',...
                    'CTEST_35',...
                    'CTEST_34',...
                    'CTEST_33',...
                    'CTEST_32',...
                    'CTEST_31',...
                    'CTEST_30',...
                    'CTEST_29',...
                    'CTEST_28',...
                    'CTEST_27',...
                    'CTEST_26',...
                    'CTEST_25',...
                    'CTEST_24',...
                    'CTEST_23',...
                    'CTEST_22',...
                    'CTEST_21',...
                    'CTEST_20',...
                    'CTEST_19',...
                    'CTEST_18',...
                    'CTEST_17',...
                    'CTEST_16',...
                    'CTEST_15',...
                    'CTEST_14',...
                    'CTEST_13',...
                    'CTEST_12',...
                    'CTEST_11',...
                    'CTEST_10',...
                    'CTEST_9',...
                    'CTEST_8',...
                    'CTEST_7',...
                    'CTEST_6',...
                    'CTEST_5',...
                    'CTEST_4',...
                    'CTEST_3',...
                    'CTEST_2',...
                    'CTEST_1',...
                    'CTEST_0'}); %342-44+1 elems
            app.UITableMaroc.Data=cell(299,5);
            Data=cell(299,5);
           
            quaboconfig=importquaboconfig(app.defautconfigfile)
            app.currentconfig= quaboconfig;
             app.lastconfigfile=app.defautconfigfile;
            %Maroc
            sz=size(app.UITableMaroc.RowName,1);
            for ii =1:sz
             [ia, index]=ismember(app.UITableMaroc.RowName(ii),quaboconfig);
             Data(ii,1)={char(quaboconfig(index,2))} ;
             Data(ii,2)={char(quaboconfig(index,3))} ;
             Data(ii,3)={char(quaboconfig(index,4))} ;
             Data(ii,4)={char(quaboconfig(index,5))} ;
              Data(ii,5)={false} ;
            end
          app.UITableMaroc.Data=Data;
          %HV tab
          Data=cell(1,5);
          Vref=1250.;
          index=find(ismember(quaboconfig,'HV_0 '));
          HV_0=  (Vref*(10/(65536*158)))*str2num(char(quaboconfig(index,2)));
          Data(1)={HV_0};
          index=find(ismember(quaboconfig,'HV_1 '));
           HV_1=  (Vref*(10/(65536*158)))*str2num(char(quaboconfig(index,2)));
          Data(2)={HV_1};
          index=find(ismember(quaboconfig,'HV_2 '));
           HV_2=  (Vref*(10/(65536*158)))*str2num(char(quaboconfig(index,2)));
          Data(3)={HV_2};
          index=find(ismember(quaboconfig,'HV_3 '));
           HV_4=  (Vref*(10/(65536*158)))*str2num(char(quaboconfig(index,2)));
           Data(4)={HV_4};
          Data(5)={false} ;
          app.UITableHV.Data=Data;
          %ACQ PARAMS:
           Data=cell(1,6);
          index=find(ismember(quaboconfig,'ACQINT '));
          Data(1)={str2num(char(quaboconfig(index,2)))};
          index=find(ismember(quaboconfig,'HOLD1 '));
          Data(2)={str2num(char(quaboconfig(index,2)))};
          index=find(ismember(quaboconfig,'HOLD2 '));
          Data(3)={str2num(char(quaboconfig(index,2)))};
          index=find(ismember(quaboconfig,'ADCCLKPH '));
           Data(4)={str2num(char(quaboconfig(index,2)))};
            index=find(ismember(quaboconfig,'MONCHAN '));
           Data(5)={str2num(char(quaboconfig(index,2)))};
              index=find(ismember(quaboconfig,'STIMON '));
           Data(6)={str2num(char(quaboconfig(index,2)))};
       
          app.UITableAcq.Data=Data;
          %slider sti
             index=find(ismember(quaboconfig,'STIM_LEVEL '));
           app.STIMLevelSlider.Value=str2num(char(quaboconfig(index,2)));
          %acq mode
           index=find(ismember(quaboconfig,'ACQMODE '));
           hexval=(char(quaboconfig(index,2)));
           indx=findstr(hexval,'x');
           tabmode={'PulseHeight' 'Imaging'};
           app.ModeDropDown.Value=string(tabmode(str2num(hexval(indx+1:indx+3))));


        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            %SEND ACQ PARAMS
            sentacqparams(app)
           

        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            field = app.ModeDropDown.Value;
            isima=strncmp(field,'Ima',3);
            ispul=strncmp(field,'Pul',3);
            if numel(isima)==0 && numel(ispul)==0
                value=0;
            end
             if numel(isima)==1
                value=2
             end
              if numel(ispul)==1
                value=1
            end
               quaboconfig=app.currentconfig;
            [ia,index]=ismember('ACQMODE ',quaboconfig);
            quaboconfig(index,2)=string(['0x0' num2str(value)]);
             app.currentconfig=quaboconfig;
        end

        % Cell edit callback: UITableAcq
        function UITableAcqCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            tabnames={'ACQINT ','HOLD1 ','HOLD2 ',...
                'ADCCLKPH ','MONCHAN ','STIMON '};
            
             quaboconfig=app.currentconfig;
            [ia,index]=ismember(tabnames(indices(2)),quaboconfig);
            quaboconfig(index,2)=string(newData);
                 app.currentconfig=quaboconfig;
        end

        % Callback function
        function HighVoltagesPanelSizeChanged(app, event)
            position = app.HighVoltagesPanel.Position;
            
        end

        % Button pushed function: ApplyHVsButton
        function ApplyHVsButtonPushed(app, event)
            sentHVs(app)
        end

        % Button pushed function: ApplyMarocButton
        function ApplyMarocButtonPushed(app, event)
            sentMaroc(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if size(app.sock,1)~=0
            app.sock.close()
            end
            delete(app)
                
        end

        % Button pushed function: deployfig
        function deployfigPushed(app, event)
            if strncmp(app.deployfig.Text,'<',1)
            app.UIFigure.Position = [255 40 440 946];
            app.deployfig.Text='>';
            else
            app.UIFigure.Position = [255 40 1893 946];
            app.deployfig.Text='<';
            end
        end

        % Cell edit callback: UITableMaroc
        function UITableMarocCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
             quaboconfig=app.currentconfig;
              quaboconfig(indices(1),2)=string(newData);
                 app.currentconfig=quaboconfig;
          %   savequaboconfig(filename, quaboconfig);
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            
             saveconfile(app)
        end

        % Button pushed function: LoadLastConfigButton
        function LoadLastConfigButtonPushed(app, event)
            quaboconfig=importquaboconfig(app.lastconfigfile)
            app.currentconfig= quaboconfig;
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Module1Tab = uitab(app.TabGroup);
            app.Module1Tab.Title = 'Module 1';
            app.Quabo1Panel = uipanel(app.Module1Tab);
            app.Quabo1Panel.Title = 'Quabo 1';
            app.AcquisitionsPanel = uipanel(app.Quabo1Panel);
            app.AcquisitionsPanel.Title = 'Acquisitions:';
            app.UITableAcq = uitable(app.AcquisitionsPanel);
            app.UITableAcq.ColumnName = {'ACQINT'; 'HOLD1'; 'HOLD2'; 'ADCCLKPH'; 'MONCHAN'; 'STIMON'};
            app.UITableAcq.RowName = {};
            app.UITableAcq.ColumnEditable = true;
            app.UITableAcq.CellEditCallback = createCallbackFcn(app, @UITableAcqCellEdit, true);
            app.STIMLevelSliderLabel = uilabel(app.AcquisitionsPanel);
            app.STIMLevelSliderLabel.Text = 'STIM Level:';
            app.STIMLevelSlider = uislider(app.AcquisitionsPanel);
            app.STIMLevelSlider.ValueChangedFcn = createCallbackFcn(app, @STIMLevelSliderValueChanged, true);
            app.ModeLabel = uilabel(app.AcquisitionsPanel);
            app.ModeLabel.Text = 'Mode:';
            app.ModeDropDown = uidropdown(app.AcquisitionsPanel);
            app.ModeDropDown.Items = {'PulseHeight', 'Imaging', 'Disable'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'PulseHeight';
            app.ApplyButton = uibutton(app.AcquisitionsPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.HighVoltagesPanel = uipanel(app.Quabo1Panel);
            app.HighVoltagesPanel.Title = 'High-Voltages:';
            app.ApplyHVsButton = uibutton(app.HighVoltagesPanel, 'push');
            app.ApplyHVsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyHVsButtonPushed, true);
            app.ApplyHVsButton.Text = 'Apply HVs';
            app.MarocPanel = uipanel(app.Quabo1Panel);
            app.MarocPanel.Title = 'Maroc:';
            app.UITableMaroc = uitable(app.MarocPanel);
            app.UITableMaroc.ColumnName = {'Array1'; 'Array2'; 'Array3'; 'Array4'; 'AllCol'};
            app.UITableMaroc.RowName = {};
            app.UITableMaroc.ColumnEditable = true;
            app.UITableMaroc.CellEditCallback = createCallbackFcn(app, @UITableMarocCellEdit, true);
            app.ApplyMarocButton = uibutton(app.MarocPanel, 'push');
            app.ApplyMarocButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyMarocButtonPushed, true);
            app.ApplyMarocButton.Text = 'Apply Maroc';
            app.UITableHV = uitable(app.Quabo1Panel);
            app.UITableHV.ColumnName = {'HV0 (V)'; 'HV1 (V)'; 'HV2 (V)'; 'HV3 (V)'; 'AllColumns'};
            app.UITableHV.RowName = {};
            app.UITableHV.ColumnEditable = true;
            app.UITableHV.CellEditCallback = createCallbackFcn(app, @UITableHVCellEdit, true);
            app.Module2Tab = uitab(app.TabGroup);
            app.Module2Tab.Title = 'Module 2';
            app.Module3Tab = uitab(app.TabGroup);
            app.Module3Tab.Title = 'Module 3';
            app.Module4Tab = uitab(app.TabGroup);
            app.Module4Tab.Title = 'Module 4';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'PANOlogo0.png';
            app.Button.Text = '';
            app.CommandsLabel = uilabel(app.UIFigure);
            app.CommandsLabel.Text = 'Commands';
            app.LoadDefaultConfigButton = uibutton(app.UIFigure, 'push');
            app.LoadDefaultConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDefaultConfigButtonPushed, true);
            app.LoadDefaultConfigButton.Text = 'Load Default Config';
            app.LoadLastConfigButton = uibutton(app.UIFigure, 'push');
            app.LoadLastConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLastConfigButtonPushed, true);
            app.LoadLastConfigButton.Text = 'Load Last Config';
            app.SaveConfigButton = uibutton(app.UIFigure, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save Config';
            app.deployfig = uibutton(app.UIFigure, 'push');
            app.deployfig.ButtonPushedFcn = createCallbackFcn(app, @deployfigPushed, true);
            app.deployfig.Text = '<';
        end
    end

    methods (Access = public)

        % Construct app
        function app = panocommandsb

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end