classdef videoparams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SetVideoparametersLabel       matlab.ui.control.Label
        VideoDirectoryEditFieldLabel  matlab.ui.control.Label
        VideoDirectoryEditField       matlab.ui.control.EditField
        SelectDirButton               matlab.ui.control.Button
        FilenameEditFieldLabel        matlab.ui.control.Label
        FilenameEditField             matlab.ui.control.EditField
        NbofimagesEditFieldLabel      matlab.ui.control.Label
        NbofimagesEditField           matlab.ui.control.NumericEditField
        DisplaytextEditFieldLabel     matlab.ui.control.Label
        DisplaytextEditField          matlab.ui.control.EditField
        IfalreadyexistingvideoButtonGroup  matlab.ui.container.ButtonGroup
        AppendButton                  matlab.ui.control.RadioButton
        OverwriteButton               matlab.ui.control.RadioButton
        ApplyButton                   matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        AddtimeinvideonameCheckBox    matlab.ui.control.CheckBox
        gifLabel                      matlab.ui.control.Label
        xpospixEditFieldLabel         matlab.ui.control.Label
        xpospixEditField              matlab.ui.control.NumericEditField
        ypospixEditFieldLabel         matlab.ui.control.Label
        ypospixEditField              matlab.ui.control.NumericEditField
        FontSizeLabel                 matlab.ui.control.Label
        FontSizeEditField             matlab.ui.control.NumericEditField
        TextcolorEditFieldLabel       matlab.ui.control.Label
        TextcolorEditField            matlab.ui.control.EditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          if exist([getuserdir '\panoseti\panotv\' 'savedvideorep.mat'])  
              load([getuserdir '\panoseti\panotv\' 'savedvideorep.mat'])
             app.VideoDirectoryEditField.Value =  videoFolder;
            app.FilenameEditField.Value = videoname;
            app.AddtimeinvideonameCheckBox.Value= videoAddtimeinvideoname;
            app.NbofimagesEditField.Value=   videoNbofimagesEditField;
            app.DisplaytextEditField.Value =   videocomments;
            app.AppendButton.Value=  videoappend;
            app.xpospixEditField.Value=videotextxpos;
            app.ypospixEditField.Value=videotextypos;
            app.FontSizeEditField.Value=videotextfontsize;
             app.TextcolorEditField.Value=videotextcolor
          else 
               app.VideoDirectoryEditField.Value =  [getuserdir '\panoseti\panotv\'];
           
          end
        end

        % Button pushed function: SelectDirButton
        function SelectDirButtonPushed(app, event)
                % h=findobj('Tag','repdata');
                    videoFolder = uigetdir(app.VideoDirectoryEditField.Value);
                        if numel(videoFolder)>1
                            app.VideoDirectoryEditField.Value=videoFolder;
                            %h(1).String=dataFolder;
                           % save('savedvideorep.mat','videoFolder')
         
                       
                         end
  

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            %take all params and save them
            videoFolder=app.VideoDirectoryEditField.Value;
            videoname=app.FilenameEditField.Value;
            videoAddtimeinvideoname=app.AddtimeinvideonameCheckBox.Value;
            videoNbofimagesEditField=app.NbofimagesEditField.Value;
            videocomments=app.DisplaytextEditField.Value;
            videoappend=app.AppendButton.Value;
            videotextxpos=app.xpospixEditField.Value;
            videotextypos=app.ypospixEditField.Value;
            videotextfontsize=app.FontSizeEditField.Value;
            videotextcolor=app.TextcolorEditField.Value;
            save([getuserdir '\panoseti\panotv\' 'savedvideorep.mat'],'videoFolder','videoname',...
                'videoAddtimeinvideoname','videoNbofimagesEditField',...
                'videocomments','videoappend',...
                'videotextxpos','videotextypos','videotextfontsize','videotextcolor')
            app.delete
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete
        end

        % Value changed function: AddtimeinvideonameCheckBox
        function AddtimeinvideonameCheckBoxValueChanged(app, event)
            value = app.AddtimeinvideonameCheckBox.Value;
            if (value ==1) && ~strncmp(app.FilenameEditField.Value,'yyyymmdd')
                app.FilenameEditField.Value=[app.FilenameEditField.Value '_yyyymmdd_hhmmss'];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SetVideoparametersLabel = uilabel(app.UIFigure);
            app.SetVideoparametersLabel.Text = 'Set Video parameters:';
            app.VideoDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.VideoDirectoryEditFieldLabel.Text = 'Video Directory:';
            app.VideoDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.SelectDirButton = uibutton(app.UIFigure, 'push');
            app.SelectDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirButtonPushed, true);
            app.SelectDirButton.Text = 'Select Dir...';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename:';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Value = 'panogif';
            app.NbofimagesEditFieldLabel = uilabel(app.UIFigure);
            app.NbofimagesEditFieldLabel.Text = 'Nb of images:';
            app.NbofimagesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NbofimagesEditField.Value = 20;
            app.DisplaytextEditFieldLabel = uilabel(app.UIFigure);
            app.DisplaytextEditFieldLabel.Text = 'Display text:';
            app.DisplaytextEditField = uieditfield(app.UIFigure, 'text');
            app.IfalreadyexistingvideoButtonGroup = uibuttongroup(app.UIFigure);
            app.IfalreadyexistingvideoButtonGroup.Title = 'If already existing video:';
            app.AppendButton = uiradiobutton(app.IfalreadyexistingvideoButtonGroup);
            app.AppendButton.Text = 'Append';
            app.AppendButton.Value = true;
            app.OverwriteButton = uiradiobutton(app.IfalreadyexistingvideoButtonGroup);
            app.OverwriteButton.Text = 'Overwrite';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AddtimeinvideonameCheckBox = uicheckbox(app.UIFigure);
            app.AddtimeinvideonameCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddtimeinvideonameCheckBoxValueChanged, true);
            app.AddtimeinvideonameCheckBox.Text = 'Add time in video name';
            app.gifLabel = uilabel(app.UIFigure);
            app.gifLabel.Text = '.gif';
            app.xpospixEditFieldLabel = uilabel(app.UIFigure);
            app.xpospixEditFieldLabel.Text = 'xpos (pix:)';
            app.xpospixEditField = uieditfield(app.UIFigure, 'numeric');
            app.xpospixEditField.Value = 1;
            app.ypospixEditFieldLabel = uilabel(app.UIFigure);
            app.ypospixEditFieldLabel.Text = 'ypos (pix:)';
            app.ypospixEditField = uieditfield(app.UIFigure, 'numeric');
            app.ypospixEditField.Value = 18;
            app.FontSizeLabel = uilabel(app.UIFigure);
            app.FontSizeLabel.Text = 'Font Size:';
            app.FontSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FontSizeEditField.Value = 15;
            app.TextcolorEditFieldLabel = uilabel(app.UIFigure);
            app.TextcolorEditFieldLabel.Text = 'Text color:';
            app.TextcolorEditField = uieditfield(app.UIFigure, 'text');
            app.TextcolorEditField.Value = 'y';
        end
    end

    methods (Access = public)

        % Construct app
        function app = videoparams

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end